classdef DashBoard_converted < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        RunButton                       matlab.ui.control.Button
        CompletedLamp                   matlab.ui.control.Lamp
        CompletedLampLabel              matlab.ui.control.Label
        RunningLamp                     matlab.ui.control.Lamp
        RunningLampLabel                matlab.ui.control.Label
        ErrorField                      matlab.ui.control.EditField
        ErrorLamp                       matlab.ui.control.Lamp
        ErrorLampLabel                  matlab.ui.control.Label
        Tab0ParamteresTab1Label         matlab.ui.control.Label
        InformationLabel                matlab.ui.control.Label
        phase6HVCheckBox                matlab.ui.control.CheckBox
        phase6LVCheckBox                matlab.ui.control.CheckBox
        phase3CheckBox                  matlab.ui.control.CheckBox
        NumberofPhasesLabel_2           matlab.ui.control.Label
        CheckBox_8                      matlab.ui.control.CheckBox
        CheckBox_12                     matlab.ui.control.CheckBox
        CheckBox_4                      matlab.ui.control.CheckBox
        CheckBox_7                      matlab.ui.control.CheckBox
        CheckBox_11                     matlab.ui.control.CheckBox
        CheckBox_3                      matlab.ui.control.CheckBox
        CheckBox_6                      matlab.ui.control.CheckBox
        CheckBox_10                     matlab.ui.control.CheckBox
        CheckBox_2                      matlab.ui.control.CheckBox
        CheckBox_9                      matlab.ui.control.CheckBox
        CheckBox_5                      matlab.ui.control.CheckBox
        CheckBox_1                      matlab.ui.control.CheckBox
        NumberofcellsLabel              matlab.ui.control.Label
        SDFBCheckBox                    matlab.ui.control.CheckBox
        SSFBCheckBox                    matlab.ui.control.CheckBox
        SSHBCheckBox                    matlab.ui.control.CheckBox
        DSFBCheckBox                    matlab.ui.control.CheckBox
        DSHBCheckBox                    matlab.ui.control.CheckBox
        TopologiesLabel                 matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        ParametersTab                   matlab.ui.container.Tab
        LosscalculationlimitingfrequencyHzEditField  matlab.ui.control.NumericEditField
        LosscalculationlimitingfrequencyHzEditFieldLabel  matlab.ui.control.Label
        CapacitorunderratingfactorEditField  matlab.ui.control.NumericEditField
        CapacitorunderratingfactorEditFieldLabel  matlab.ui.control.Label
        ReoptimizeCheckBox              matlab.ui.control.CheckBox
        OptimizedpulsenumberandcapacitorenergyCheckBox  matlab.ui.control.CheckBox
        RelativelossreductionfactorEditField  matlab.ui.control.NumericEditField
        RelativelossreductionfactorEditFieldLabel  matlab.ui.control.Label
        ModuleSelectionButtonGroup      matlab.ui.container.ButtonGroup
        IGBTIXYSMIXA300PF1200TSFSiBuggedButton  matlab.ui.control.RadioButton
        MOSFETSEMITRANSSKM260MB170SCH17SiCButton  matlab.ui.control.RadioButton
        MOSFETWolfspeedCAB450M12XM3SiCButton  matlab.ui.control.RadioButton
        Tab0ParamteresTab1Label_2       matlab.ui.control.Label
        OptimizingminimumnumberofparallelMOSFETsCheckBox  matlab.ui.control.CheckBox
        BatteryLeadsinductance2levelHEditField  matlab.ui.control.NumericEditField
        LoadcapacitorEnergymatrixCheckBox  matlab.ui.control.CheckBox
        BatteryLeadsinductance2levelHEditFieldLabel  matlab.ui.control.Label
        TotalenergystoredinSMdclinkcapacitorsJEditField  matlab.ui.control.NumericEditField
        ThermalresistancecasetoambientKWEditField  matlab.ui.control.NumericEditField
        ThermalresistancecasetoambientKWEditFieldLabel  matlab.ui.control.Label
        TotalenergystoredinSMdclinkcapacitorsJEditFieldLabel  matlab.ui.control.Label
        SMDClinkcapacitorDFEditField    matlab.ui.control.NumericEditField
        DClinkcapacitorESLHEditField    matlab.ui.control.NumericEditField
        SMDClinkcapacitorDFEditFieldLabel  matlab.ui.control.Label
        DClinkcapacitorESLHEditFieldLabel  matlab.ui.control.Label
        MaximumpowerdessipationforasubmodulecapacitorWEditField  matlab.ui.control.NumericEditField
        DClinkcapacitorDF2levelEditField  matlab.ui.control.NumericEditField
        DClinkcapacitorDF2levelEditFieldLabel  matlab.ui.control.Label
        MaximumpowerdessipationforasubmodulecapacitorWEditFieldLabel  matlab.ui.control.Label
        MaximumenergyforasubmodulecapacitorJEditField  matlab.ui.control.NumericEditField
        DClinkcapacitorenergy2levelJEditField  matlab.ui.control.NumericEditField
        DClinkcapacitorenergy2levelJEditFieldLabel  matlab.ui.control.Label
        MaximumenergyforasubmodulecapacitorJEditFieldLabel  matlab.ui.control.Label
        Maximumenergyfor1seriescellspersubmoduleJEditField  matlab.ui.control.NumericEditField
        levelinverterpulsenumberEditField  matlab.ui.control.NumericEditField
        levelinverterpulsenumberEditFieldLabel  matlab.ui.control.Label
        Maximumenergyfor1seriescellspersubmoduleJEditFieldLabel  matlab.ui.control.Label
        PeakRMScurrentfor1seriescellspersubmoduleAEditField  matlab.ui.control.NumericEditField
        DClinkvoltageVEditField         matlab.ui.control.NumericEditField
        DClinkvoltageVEditFieldLabel    matlab.ui.control.Label
        PeakRMScurrentfor1seriescellspersubmoduleAEditFieldLabel  matlab.ui.control.Label
        CapacitormodelingLabel          matlab.ui.control.Label
        levelinverterLabel              matlab.ui.control.Label
        MinimumallowabledidtAusEditField  matlab.ui.control.NumericEditField
        DCimpedancefrequencyHzEditField  matlab.ui.control.NumericEditField
        DCimpedancefrequencyHzEditFieldLabel  matlab.ui.control.Label
        MinimumallowabledidtAusEditFieldLabel  matlab.ui.control.Label
        BatteryLeadsparaciticinductanceHEditField  matlab.ui.control.NumericEditField
        AlelionCellEISdataCheckBox      matlab.ui.control.CheckBox
        BatteryLeadsparaciticinductanceHEditFieldLabel  matlab.ui.control.Label
        MaximumnumberofparallelMOSFETsEditField  matlab.ui.control.NumericEditField
        MaximumnumberofparallelMOSFETsEditFieldLabel  matlab.ui.control.Label
        BatterymodelingLabel            matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        MinimumnumberofparallelMOSFETsEditField  matlab.ui.control.NumericEditField
        MinimumnumberofparallelMOSFETsEditFieldLabel  matlab.ui.control.Label
        DCchargingpowerWEditField       matlab.ui.control.NumericEditField
        DCchargingpowerWEditFieldLabel  matlab.ui.control.Label
        MotorNominalspeedrpmEditField   matlab.ui.control.NumericEditField
        MotorNominalspeedrpmEditFieldLabel  matlab.ui.control.Label
        DampingratioEditField           matlab.ui.control.NumericEditField
        DampingratioEditFieldLabel      matlab.ui.control.Label
        DCchargingvoltageVEditField     matlab.ui.control.NumericEditField
        DCchargingvoltageVEditFieldLabel  matlab.ui.control.Label
        NumberofpolesofmotorEditField   matlab.ui.control.NumericEditField
        NumberofpolesofmotorEditFieldLabel  matlab.ui.control.Label
        PCBparaciticinductanceHEditField  matlab.ui.control.NumericEditField
        PCBparaciticinductanceHEditFieldLabel  matlab.ui.control.Label
        LossclaculationlimitsPanel      matlab.ui.container.Panel
        LosslimitEnableCheckBox         matlab.ui.control.CheckBox
        DCchargingenableCheckBox        matlab.ui.control.CheckBox
        DCchargingLabel                 matlab.ui.control.Label
        NominalcellcapacityAhEditField  matlab.ui.control.NumericEditField
        NominalcellcapacityAhEditFieldLabel  matlab.ui.control.Label
        MotoranddrivecycleparametersLabel_2  matlab.ui.control.Label
        SubmoduleDCvoltagetolerenceEditField  matlab.ui.control.NumericEditField
        NotinUseLabel_3                 matlab.ui.control.Label
        MOSFETcostEditField             matlab.ui.control.EditField
        SubmoduleDCvoltagetolerenceLabel  matlab.ui.control.Label
        MOSFETcostEditFieldLabel        matlab.ui.control.Label
        Heattransferaream2EditField     matlab.ui.control.NumericEditField
        Heattransferaream2EditFieldLabel  matlab.ui.control.Label
        DepthofdischargeEditField       matlab.ui.control.NumericEditField
        DepthofdischargeEditFieldLabel  matlab.ui.control.Label
        SubmodulepulsenumberEditField   matlab.ui.control.NumericEditField
        SubmodulepulsenumberEditFieldLabel  matlab.ui.control.Label
        FalltimedidtAusEditField        matlab.ui.control.NumericEditField
        ThermalresistancejunctiontocaseKWEditField  matlab.ui.control.EditField
        NotinUseLabel_2                 matlab.ui.control.Label
        FalltimedidtAusEditFieldLabel   matlab.ui.control.Label
        ThermalresistancejunctiontocaseKWEditFieldLabel  matlab.ui.control.Label
        MOSFETaream2EditField           matlab.ui.control.NumericEditField
        MOSFETaream2EditFieldLabel      matlab.ui.control.Label
        EnergyofbatterypackWhEditField  matlab.ui.control.NumericEditField
        EnergyofbatterypackWhEditFieldLabel  matlab.ui.control.Label
        overcurrentfactorEditField      matlab.ui.control.NumericEditField
        overcurrentfactorEditFieldLabel  matlab.ui.control.Label
        FalltimedvdtVusEditField        matlab.ui.control.NumericEditField
        NotinUseLabel                   matlab.ui.control.Label
        OnstateresistanceOhmEditField   matlab.ui.control.EditField
        FalltimedvdtVusEditFieldLabel   matlab.ui.control.Label
        OnstateresistanceOhmEditFieldLabel  matlab.ui.control.Label
        CasetoambientheattransfercoefficienctWm2KEditField  matlab.ui.control.NumericEditField
        CasetoambientheattransfercoefficienctWm2KEditFieldLabel  matlab.ui.control.Label
        MaximumcellvoltageVEditField    matlab.ui.control.NumericEditField
        MaximumcellvoltageVEditFieldLabel  matlab.ui.control.Label
        loadpowerfactorEditField        matlab.ui.control.NumericEditField
        loadpowerfactorEditFieldLabel   matlab.ui.control.Label
        MOSFETparametersvectorLabel     matlab.ui.control.Label
        maximummodulationindexEditField  matlab.ui.control.NumericEditField
        maximummodulationindexEditFieldLabel  matlab.ui.control.Label
        RisetimedidtAusEditField        matlab.ui.control.NumericEditField
        BatterycostperkWhkWhEditField   matlab.ui.control.NumericEditField
        RisetimedidtAusEditFieldLabel   matlab.ui.control.Label
        BatterycostperkWhkWhEditFieldLabel  matlab.ui.control.Label
        MaximumCasetemperaturedegCEditField  matlab.ui.control.NumericEditField
        MaximumCasetemperaturedegCEditFieldLabel  matlab.ui.control.Label
        NominalcellvoltageVEditField    matlab.ui.control.NumericEditField
        NominalcellvoltageVEditFieldLabel  matlab.ui.control.Label
        RatedoutputvoltageVEditField    matlab.ui.control.NumericEditField
        RatedoutputvoltageVEditFieldLabel  matlab.ui.control.Label
        RisetimedvdtVusEditField        matlab.ui.control.NumericEditField
        CapacitorcostperunitEditField   matlab.ui.control.NumericEditField
        CostpersubmoduleSMEditField     matlab.ui.control.NumericEditField
        CapacitorcostperunitEditFieldLabel  matlab.ui.control.Label
        RisetimedvdtVusEditFieldLabel   matlab.ui.control.Label
        CostpersubmoduleSMEditFieldLabel  matlab.ui.control.Label
        MaximumAmbienttemperaturedegCEditField  matlab.ui.control.NumericEditField
        MaximumAmbienttemperaturedegCEditFieldLabel  matlab.ui.control.Label
        MinimumbatterycellvoltageVEditField  matlab.ui.control.NumericEditField
        MinimumbatterycellvoltageVEditFieldLabel  matlab.ui.control.Label
        AverageoutputpowerWEditField    matlab.ui.control.NumericEditField
        AverageoutputpowerWEditFieldLabel  matlab.ui.control.Label
        MOSFETswitchingparametersLabel  matlab.ui.control.Label
        CostConstantsLabel              matlab.ui.control.Label
        ThermalParametersLabel          matlab.ui.control.Label
        BatteryParametersLabel          matlab.ui.control.Label
        ConverterParametersLabel        matlab.ui.control.Label
        SMsandCapacityTab               matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        BatteryCapacityperSubmoduleLabel  matlab.ui.control.Label
        TotalNumberofSubmodulesLabel_2  matlab.ui.control.Label
        TotalNumberofSubmodulesLabel    matlab.ui.control.Label
        Bcap_sm_plot_3phase             matlab.ui.control.UIAxes
        Bcap_sm_plot_6phase_LV          matlab.ui.control.UIAxes
        Nsm_plot_3phase                 matlab.ui.control.UIAxes
        Bcap_sm_plot_6phase_HV          matlab.ui.control.UIAxes
        Nsm_plot_6phase_LV              matlab.ui.control.UIAxes
        Nsm_plot_6phase_HV              matlab.ui.control.UIAxes
        ParallelCellsSCsTab             matlab.ui.container.Tab
        NumberofparallelMOSFETsperswitchLabel  matlab.ui.control.Label
        NumberofparallelcellspersubmoduleLabel  matlab.ui.control.Label
        Npmos_plot_6phase_HV            matlab.ui.control.UIAxes
        Npcells_plot_6phase_LV          matlab.ui.control.UIAxes
        Npcells_plot_6phase_HV          matlab.ui.control.UIAxes
        Npcells_plot_3phase             matlab.ui.control.UIAxes
        Npmos_sm_plot_6phase_LV         matlab.ui.control.UIAxes
        Npmos_sm_plot_3phase            matlab.ui.control.UIAxes
        SMCurrentsTab                   matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        SubmoduleDCsidecurrentsLabel_22  matlab.ui.control.Label
        SubmoduleDCsidecurrentsLabel_21  matlab.ui.control.Label
        Ibatt_plot_6phase_HV            matlab.ui.control.UIAxes
        Rthata_ca_6phase_HV             matlab.ui.control.UIAxes
        Ibatt_plot_3phase               matlab.ui.control.UIAxes
        Ibatt_plot_6phase_LV            matlab.ui.control.UIAxes
        Rthata_ca_6phase_LV             matlab.ui.control.UIAxes
        Rthata_ca_3phase                matlab.ui.control.UIAxes
        SMCapacitorTab                  matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        SubmoduleDCsidecurrentsLabel_4  matlab.ui.control.Label
        SubmoduleDCsidecurrentsLabel_3  matlab.ui.control.Label
        Cap_plot_6phase_HV              matlab.ui.control.UIAxes
        Cap_plot_3phase                 matlab.ui.control.UIAxes
        ESR_plot_3phase                 matlab.ui.control.UIAxes
        Cap_plot_6phase_LV              matlab.ui.control.UIAxes
        ESR_plot_6phase_HV              matlab.ui.control.UIAxes
        ESR_plot_6phase_LV              matlab.ui.control.UIAxes
        ZetaanddidtTab                  matlab.ui.container.Tab
        GridLayout3_2                   matlab.ui.container.GridLayout
        SubmoduleDCsidecurrentsLabel_24  matlab.ui.control.Label
        SubmoduleDCsidecurrentsLabel_23  matlab.ui.control.Label
        didt_plot_6phase_LV             matlab.ui.control.UIAxes
        didt_plot_6phase_HV             matlab.ui.control.UIAxes
        didt_plot_3phase                matlab.ui.control.UIAxes
        Zeta_plot_3phase                matlab.ui.control.UIAxes
        Zeta_plot_6phase_HV             matlab.ui.control.UIAxes
        Zeta_plot_6phase_LV             matlab.ui.control.UIAxes
        BatterylossTab                  matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        SubmoduleDCsidecurrentsLabel_6  matlab.ui.control.Label
        SubmoduleDCsidecurrentsLabel_5  matlab.ui.control.Label
        Pbatt_plot_3phase               matlab.ui.control.UIAxes
        Pbatt_plot_6phase_LV            matlab.ui.control.UIAxes
        Pbatt_tot_plot_6phase_HV        matlab.ui.control.UIAxes
        Pbatt_plot_6phase_HV            matlab.ui.control.UIAxes
        Pbatt_tot_plot_6phase_LV        matlab.ui.control.UIAxes
        Pbatt_tot_plot_3phase           matlab.ui.control.UIAxes
        CapacitorlossTab                matlab.ui.container.Tab
        GridLayout4_2                   matlab.ui.container.GridLayout
        SubmoduleDCsidecurrentsLabel_14  matlab.ui.control.Label
        SubmoduleDCsidecurrentsLabel_13  matlab.ui.control.Label
        Pcap_plot_6phase_HV             matlab.ui.control.UIAxes
        Pcap_tot_plot_6phase_LV         matlab.ui.control.UIAxes
        Pcap_tot_plot_6phase_HV         matlab.ui.control.UIAxes
        Pcap_plot_6phase_LV             matlab.ui.control.UIAxes
        Pcap_plot_3phase                matlab.ui.control.UIAxes
        Pcap_tot_plot_3phase            matlab.ui.control.UIAxes
        TemperatureTab                  matlab.ui.container.Tab
        GridLayout4_3                   matlab.ui.container.GridLayout
        SubmoduleDCsidecurrentsLabel_16  matlab.ui.control.Label
        SubmoduleDCsidecurrentsLabel_15  matlab.ui.control.Label
        Tc_plot_3phase                  matlab.ui.control.UIAxes
        Tc_plot_6phase_HV               matlab.ui.control.UIAxes
        Tj_plot_6phase_HV               matlab.ui.control.UIAxes
        Tc_plot_6phase_LV               matlab.ui.control.UIAxes
        Tj_plot_3phase                  matlab.ui.control.UIAxes
        Tj_plot_6phase_LV               matlab.ui.control.UIAxes
        SClossTab                       matlab.ui.container.Tab
        SubmoduleDCsidecurrentsLabel_8  matlab.ui.control.Label
        GridLayout6                     matlab.ui.container.GridLayout
        SubmoduleDCsidecurrentsLabel_7  matlab.ui.control.Label
        Psm_plot_3phase                 matlab.ui.control.UIAxes
        Psc_tot_plot_6phase_LV          matlab.ui.control.UIAxes
        Psc_tot_plot_6phase_HV          matlab.ui.control.UIAxes
        Psc_tot_plot_3phase             matlab.ui.control.UIAxes
        Psm_plot_6phase_HV              matlab.ui.control.UIAxes
        Psm_plot_6phase_LV              matlab.ui.control.UIAxes
        TotallossTab                    matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        SubmoduleDCsidecurrentsLabel_10  matlab.ui.control.Label
        SubmoduleDCsidecurrentsLabel_9  matlab.ui.control.Label
        Ploss_avg_plot_3phase           matlab.ui.control.UIAxes
        Ploss_avg_plot_6phase_LV        matlab.ui.control.UIAxes
        Ploss_max_plot_3phase           matlab.ui.control.UIAxes
        Ploss_max_plot_6phase_LV        matlab.ui.control.UIAxes
        Ploss_max_plot_6phase_HV        matlab.ui.control.UIAxes
        Ploss_avg_plot_6phase_HV        matlab.ui.control.UIAxes
        EfficiencyTab                   matlab.ui.container.Tab
        GridLayout6_2                   matlab.ui.container.GridLayout
        SubmoduleDCsidecurrentsLabel_12  matlab.ui.control.Label
        SubmoduleDCsidecurrentsLabel_11  matlab.ui.control.Label
        eff_avg_plot_6phase_HV          matlab.ui.control.UIAxes
        eff_max_plot_3phase             matlab.ui.control.UIAxes
        eff_max_plot_6phase_HV          matlab.ui.control.UIAxes
        eff_avg_plot_6phase_LV          matlab.ui.control.UIAxes
        eff_avg_plot_3phase             matlab.ui.control.UIAxes
        eff_max_plot_6phase_LV          matlab.ui.control.UIAxes
        ConverterCostsTab               matlab.ui.container.Tab
        GridLayout6_3                   matlab.ui.container.GridLayout
        SubmoduleDCsidecurrentsLabel_18  matlab.ui.control.Label
        SubmoduleDCsidecurrentsLabel_17  matlab.ui.control.Label
        lambda_normalized_plot_6phase_LV  matlab.ui.control.UIAxes
        lambda_plot_3phase              matlab.ui.control.UIAxes
        lambda_normalized_plot_6phase_HV  matlab.ui.control.UIAxes
        lambda_normalized_plot_3phase   matlab.ui.control.UIAxes
        lambda_plot_6phase_HV           matlab.ui.control.UIAxes
        lambda_plot_6phase_LV           matlab.ui.control.UIAxes
        TotalCostsTab                   matlab.ui.container.Tab
        GridLayout6_4                   matlab.ui.container.GridLayout
        SubmoduleDCsidecurrentsLabel_20  matlab.ui.control.Label
        SubmoduleDCsidecurrentsLabel_19  matlab.ui.control.Label
        lambda_tot_normalized_plot_6phase_LV  matlab.ui.control.UIAxes
        lambda_tot_plot_3phase          matlab.ui.control.UIAxes
        lambda_tot_normalized_plot_6phase_HV  matlab.ui.control.UIAxes
        lambda_tot_normalized_plot_3phase  matlab.ui.control.UIAxes
        lambda_tot_plot_6phase_HV       matlab.ui.control.UIAxes
        lambda_tot_plot_6phase_LV       matlab.ui.control.UIAxes
        TotalcharinglossesTab           matlab.ui.container.Tab
        SubmoduleDCsidecurrentsLabel_26  matlab.ui.control.Label
        SubmoduleDCsidecurrentsLabel_25  matlab.ui.control.Label
        Tc_DC_chrg_plot_6phase_HV       matlab.ui.control.UIAxes
        Tc_DC_chrg_plot_3phase          matlab.ui.control.UIAxes
        Tc_DC_chrg_plot_6phase_LV       matlab.ui.control.UIAxes
        Ploss_DC_chrg_max_plot_3phase   matlab.ui.control.UIAxes
        Ploss_DC_chrg_max_plot_6phase_LV  matlab.ui.control.UIAxes
        Ploss_DC_chrg_max_plot_6phase_HV  matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
        
        function Params = Inputs(app)
            % external data
            StaterFiles_Vector_Params2;
            Ext_Data = readmatrix('Vector_params.txt');
            
            
            % converter parameters
            Params.Pout = app.AverageoutputpowerWEditField.Value; % average output power
            Params.Upp = app.RatedoutputvoltageVEditField.Value; % rated output line-to-line voltage
            Params.Mmax = app.maximummodulationindexEditField.Value; % maximum modulation index
            Params.cosphi = app.loadpowerfactorEditField.Value; % load power factor
            Params.ocf = app.overcurrentfactorEditField.Value; % over current factor
            Params.p = app.SubmodulepulsenumberEditField.Value; % modulation index at average power
            
            %             % calculations
            %             Params.Iphavg = Params.Pout/(sqrt(3)*Params.Upp*Params.cosphi); % output phase current average
            %             Params.Iphmax = Params.Pout*Params.ocf/(sqrt(3)*Params.Upp*Params.cosphi); % output phase current maximum
            
            % Cell parameters
            Params.Uc_min = app.MinimumbatterycellvoltageVEditField.Value; % minimum cell voltage
            Params.Uc_n = app.NominalcellvoltageVEditField.Value; % Nominal cell voltage
            Params.Uc_max = app.MaximumcellvoltageVEditField.Value; % maximum cell voltage
            Params.Ebatt = app.EnergyofbatterypackWhEditField.Value; % energy of the battery pack
            Params.DoD = app.DepthofdischargeEditField.Value; % depth of discharge
            Params.Bcap = app.NominalcellcapacityAhEditField.Value; % nominal cell capacity
            
            % Thermal parametrers
            Params.Rca = app.CasetoambientheattransfercoefficienctWm2KEditField.Value; % Thermal resistanc eform case to ambient
            Params.Tc = app.MaximumCasetemperaturedegCEditField.Value; % Maximum case temeprature
            Params.Ta= app.MaximumAmbienttemperaturedegCEditField.Value; % Maximum ambient temperature
            
            % Motor and Drive cycle data
            Params.wnom = app.MotorNominalspeedrpmEditField.Value; % Nominal motor speed
            Params.np = app.NumberofpolesofmotorEditField.Value; % Number of poles of motor
            
            % MOSFET switching parameters
            Params.r_dvdt = app.RisetimedvdtVusEditField.Value*1e6; % Rise time dvdt
            Params.r_didt = app.RisetimedidtAusEditField.Value*1e6; % Rise time didt
            Params.f_dvdt = app.FalltimedvdtVusEditField.Value*1e6; % Fall time dvdt
            Params.f_didt = app.FalltimedidtAusEditField.Value*1e6; % Fall time didt
            Params.dUds = app.SubmoduleDCvoltagetolerenceEditField.Value; % Dc side voltage tolerence
            Params.Lpcb = app.PCBparaciticinductanceHEditField.Value; % PCB parasitic inductace
            Params.Zeta = app.DampingratioEditField.Value; % Damping ratio Zeta
            Params.NPmosMin = app.MinimumnumberofparallelMOSFETsEditField.Value; % Minimum number of parallel MOSFETs
            Params.NPmosMax = app.MaximumnumberofparallelMOSFETsEditField.Value; %  Maximum number of parallel MOSFETs
            Params.f_didt_min = 1e6*app.MinimumallowabledidtAusEditField.Value; % mimimum allowable didt for MOSFET
            
            % loss limits
            Params.LossLim = app.LosslimitEnableCheckBox.Value; % limit loss calculations label
            Params.f_limit = app.LosscalculationlimitingfrequencyHzEditField.Value; % loss calculation limiting frequency
            
            % 2-level inverter Module selection
            Params.InvModule.MOSFET3p = app.MOSFETWolfspeedCAB450M12XM3SiCButton.Value; % 2-level inverter MOSFET module
            Params.InvModule.IGBT = app.IGBTIXYSMIXA300PF1200TSFSiBuggedButton.Value; % 2-level inverter IGBT module'
            Params.InvModule.MOSFETnp = app.MOSFETSEMITRANSSKM260MB170SCH17SiCButton.Value; % 2-level inverter MOSFET module
            Params.Udc = app.DClinkvoltageVEditField.Value; % DC-link voltage for 2-level inverter
            Params.p2l = app.levelinverterpulsenumberEditField.Value; % 2-level inverter pulse number
            Params.Cap_2l_constant = app.DClinkcapacitorenergy2levelJEditField.Value; % Dc-link capacitor value
            Params.ESR_2l = app.DClinkcapacitorDF2levelEditField.Value; % DC-link voltage ripple due to ESR
            Params.ESL_2l = app.DClinkcapacitorESLHEditField.Value; % DC-link capacitor ESL
            %             Params.Npcap_2l = app.NumberofparallelDClinkcapacitorsEditField.Value; % number of DC-link capacitors in parallel
            Params.Ta2l = app.ThermalresistancecasetoambientKWEditField.Value; % ambient temperature for the 2-level inverter
            Params.Lbatt_leads_2l = app.BatteryLeadsinductance2levelHEditField.Value; % battery leads connections to the converter
            
            Params.Asw = app.Heattransferaream2EditField.Value; % heat transfer area [m^2]
            Params.Amos = app.MOSFETaream2EditField.Value; % MOSFET area [m^2]
            Params.Lbatt_leads = app.BatteryLeadsparaciticinductanceHEditField.Value; % battery leads connections to the converter
            
            % Cost constans
            Params.ksm = app.CostpersubmoduleSMEditField.Value; % cost per submodule [€/SM]
            Params.Kcap = app.CapacitorcostperunitEditField.Value; % caapcitor cost per J [€/J]
            Params.Kbatt = app.BatterycostperkWhkWhEditField.Value; % battery cost per Kwh [€/kWh]
            
            % MOSFET parameters
            %             Params.Rdson = str2double(strsplit(app.OnstateresistanceOhmEditField.Value)); % on state resistnace vector [\Omega]
            Params.Rdson = Ext_Data(6,:); % on state resistnace vector [\Ohm]
            %             Params.Rthjc = str2double(strsplit(app.ThermalresistancejunctiontocaseKWEditField.Value)); % thermal resistance from jucntion to case [K/W]
            Params.Rthjc = Ext_Data(7,:); % thermal resistance from jucntion to case [K/W]
            %             Params.Ksw = str2double(strsplit(app.MOSFETcostEditField.Value)); % MOSFET cost [€]
            Params.Ksw = Ext_Data(8,:); % MOSFET cost [€]
            Params.Rth_pad = Ext_Data(9,:); % MOSFET pad thermal via resistances [K/W]
            
            % 2-levle inverter specific parameters
            Params.Tc_2l = Params.Tc;
            Params.Rca_2l = Params.Rca;
            
            % Submodule parameters
            Params.Ecap_SM_tot = app.TotalenergystoredinSMdclinkcapacitorsJEditField.Value; % total energy stored in submodule capacitors [J]
            Params.Cap_SM_DF = app.SMDClinkcapacitorDFEditField.Value; % SM dC-link capcitor ESR [Ohm]
            
            % ESR calculation
            Params.EnESRCal = app.LoadcapacitorEnergymatrixCheckBox.Value; % Zeta based Capacitor ESR calculation enable/disable
            Params.Ecap_single_cell = app.Maximumenergyfor1seriescellspersubmoduleJEditField.Value; % maximum energy stored in the capcitor considering 1 cell per submodule
            Params.Ecap_max_cell = app.MaximumenergyforasubmodulecapacitorJEditField.Value; % maximum energy stored in a submodule capctior [J]
            Params.P_cap_cell = app.MaximumpowerdessipationforasubmodulecapacitorWEditField.Value; % maximum power dessipation for a single capacitor model
            Params.Icap_pk_single_cell = app.PeakRMScurrentfor1seriescellspersubmoduleAEditField.Value; % peak current for a single cell per submodule [A]
            Params.log_rate = Ext_Data(1,:); % rate of change of delta (desipation factor wrt Nscells)
            Params.log_rate = Ext_Data(1,:); % rate of change of delta (desipation factor wrt Nscells)
            Params.Ccap_unit = Ext_Data(2,:); % Capacitor max unit capacitance
            Params.ESR_unit = Ext_Data(3,:); % Capacitor max unit capacitance
            Params.Icap_unit = Ext_Data(4,:); % Capacitor max unit capacitance
            Params.Acap_unit = Ext_Data(5,:); % Capacitor max unit capacitance
            
            % EIS cell data
            Params.AlelionEnable = app.AlelionCellEISdataCheckBox.Value; % Enable calculation based on Alelion cell data
            Params.DC_imp_freq = app.DCimpedancefrequencyHzEditField.Value; % select the frequency used to calculate the DC-resistance
            
            % Optimization
            Params.OptimEnable = app.OptimizedpulsenumberandcapacitorenergyCheckBox.Value;
            save Parameters_dashboard.mat; % saving the paratemres mat file
            Params.Reoptimize = app.ReoptimizeCheckBox.Value; % re-optimization variable
            Params.OptimEnable_NPmos = app.OptimizingminimumnumberofparallelMOSFETsCheckBox.Value;
            Params.RLRF = 1-app.RelativelossreductionfactorEditField.Value/100; % relative load reduction factor
            Params.ccf_lowlim = 1-app.CapacitorunderratingfactorEditField.Value/100; 
            
            % DC Charging
            Params.DC_chrg_en = app.DCchargingenableCheckBox.Value; % DC charging calculations enable
            Params.Udc_chrg = app.DCchargingvoltageVEditField.Value; % DC charging voltage
            Params.Pdc_chrg = app.DCchargingpowerWEditField.Value; % charging power
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            %% This is the main code
            
            % program start-up indicator
            app.CompletedLamp.Enable = 'off'; % disable completed lamp
            app.RunningLamp.Enable = 'on'; % disable running lamp
            app.ErrorLamp.Enable = 'off'; % dsiable error lamp
            drawnow();  % Update the screen
            
            % User input topology selection
            Topologies_check = [app.DSHBCheckBox.Value app.DSFBCheckBox.Value ...
                app.SSHBCheckBox.Value app.SSFBCheckBox.Value ...
                app.SDFBCheckBox.Value]; % variable to check the CheckBoxes
            Topologies_NameList = ["DSHB" "DSFB" "SSHB" "SSFB" "SDFB"]; % topology names
            Topologies = find(Topologies_check); % dynamic vector whoose length is equal to the number topologies selected
            Topologies_Name = Topologies_NameList(find(Topologies_check)); % selected topology names
            
            % User input for number of cells per submodule
            NoofCells_check = [app.CheckBox_1.Value app.CheckBox_2.Value ...
                app.CheckBox_3.Value app.CheckBox_4.Value app.CheckBox_5.Value...
                app.CheckBox_6.Value app.CheckBox_7.Value app.CheckBox_8.Value ...
                app.CheckBox_9.Value app.CheckBox_10.Value...
                app.CheckBox_11.Value app.CheckBox_12.Value]; % variable to check the CheckBoxes
            NoofCells_NameList= ["1" "2" "3" "4" "5" "6" "7" "8" "9" ...
                "10" "11" "12"]; % topology names
            NoofCells= find(NoofCells_check); % dynamic vector whoose length is equal to the number of cells selected
            NoofCells_Name= NoofCells_NameList(find(NoofCells_check)); % selected number of cells
            
            % User input for number of phases
            NoofPhases_check = [app.phase3CheckBox.Value app.phase6LVCheckBox.Value ....
                app.phase6HVCheckBox.Value]; % variable to check the CheckBoxes
            NoofPhases_NamesList = ["3p" "6l" "6h"]; % number of phases names
            NoofPhases= find(NoofPhases_check); % dynamic vector whoose length is equal to the number of cells selected
            NoofPhases_Names = NoofPhases_NamesList(find(NoofPhases_check)); % selected number of phases
            
            % Take the paramters imput
            Params = Inputs(app);
            
            if length(Topologies) < 2 % check if topology is selected
                app.ErrorField.Value = "Select 2 Topologies!";
                app.ErrorLamp.Enable = 'on'; % enable error lamp
                app.CompletedLamp.Enable = 'off'; % disable completed lamp
                app.RunningLamp.Enable = 'off'; % disable running lamp
                return
            end
            if length(NoofCells) < 2 % check if number of cells selected
                app.ErrorField.Value = "Select 2 No. of Cells!";
                app.ErrorLamp.Enable = 'on'; % enable error lamp
                app.CompletedLamp.Enable = 'off'; % disable completed lamp
                app.RunningLamp.Enable = 'off'; % disable running lamp
                return
            end
            if length(NoofPhases) < 3 % check if number of phases are selected
                app.ErrorField.Value = "Select All Phases!";
                app.ErrorLamp.Enable = 'on'; % enable error lamp
                app.CompletedLamp.Enable = 'off'; % disable completed lamp
                app.RunningLamp.Enable = 'off'; % disable running lamp
                return
            end
            app.ErrorField.Value = "Working...";
            drawnow();  % Update the screen
            
            % nominal frequency
            f = Params.wnom.*Params.np./120; % converter electrical frequency
            
            % Instantanious calculations
            t = linspace(0,1/f,2000-1); % 1 fundamental cycle
            [~,~,~,t_mtx] = ndgrid(Topologies,NoofCells,NoofPhases,t); % time instant vector
            [~,t_NoofCells_mtx] = ndgrid(NoofCells,t); % time instant vector
            
            f_mtx = zeros(size(t_mtx)) + f; % frequency vector
            
            Topologies_t_mtx = ndgrid(Topologies,NoofCells,NoofPhases,t); % Topologies time vectors
            [~,~,NoofPhases_t_mtx,~] = ndgrid(Topologies,NoofCells,NoofPhases,t); % number of phases time vectors
            
            % topology dependent variables
            Noofarms_per_phase= ones(size(Topologies)); % number of arms per phase
            U_s = ones(size(Topologies))*Params.Uc_min; % DC-side SM voltage
            U_sm = ones(size(Topologies)); % RMS SM output voltage
            
            
            % carrier and reference wave functions
            %             sawtooth_carrier_mtx = sawtooth(2*pi*f_mtx.*Params.p.*t_mtx,0.5); % carrier wave
            %             for i=1:length(Topologies)
            %                 switch Topologies(i)
            %                     case 1 % DSHB
            %                         p(i,:) = 1.5*Params.p*sqrt(NoofCells); % p set at LC resonance
            %                     otherwise % other topologies
            %                         p(i,:) = Params.p*sqrt(NoofCells); % p set at LC resonance
            %                 end
            %             end
            
            % setting-up phase voltages
            U_ph = ones(size(Topologies))*Params.Upp/sqrt(3); % phase voltage
            [Uph_mtx,~,~] = ndgrid(U_ph,NoofCells,NoofPhases); % output phase voltage matrix
            
            
            % Phase currents determination
            for i=1:length(NoofPhases)
                switch NoofPhases(i)
                    case 1 % 3 phase system
                        Iphavg(i) = Params.Pout/(sqrt(3)*Params.Upp*Params.cosphi); % output phase current average
                        Iphmax(i) = Params.Pout*Params.ocf/(sqrt(3)*Params.Upp*Params.cosphi); % output phase current maximum
                        NoofPhases_Value(i) = 3; % number of phases
                    case 2 % 6 phase LV system
                        Iphavg(i) = Params.Pout/(6*Params.Upp/sqrt(3)*Params.cosphi); % output phase current average
                        Iphmax(i) = Params.Pout*Params.ocf/(6*Params.Upp/sqrt(3)*Params.cosphi); % output phase current maximum
                        NoofPhases_Value(i) = 6; % number of phases
                    case 3 % 6 phase HV system
                        Iphavg(i) = Params.Pout/(6*Params.Upp*Params.cosphi); % output phase current average
                        Iphmax(i) = Params.Pout*Params.ocf/(6*Params.Upp*Params.cosphi); % output phase current maximum
                        NoofPhases_Value(i) = 6; % number of phases
                        Uph_mtx(:,:,i) = Params.Upp; % output phase voltage
                end
            end
            
            [Topologies_mtx,NoofCells_mtx,NoofPhases_mtx] = ndgrid(Topologies,NoofCells,NoofPhases_Value); % Topology, No of cells and No of phases vector
            
            % number of switches per submodule and delta topology
            Noofswitches_per_sm = [2 4 2 4 4];
            Delta   = [0 0 0 0 1];
            Noofarms_per_phase = [2 2 1 1 1];
            Noofswitches_per_sm = Noofswitches_per_sm(find(Topologies_check));
            Delta = Delta(find(Topologies_check));
            Noofarms_per_phase = Noofarms_per_phase(find(Topologies_check));
            
            % Global variable definitions
            global f_didt_min Rth_pad_mtx
            f_didt_min = Params.f_didt_min; % [A/s] minimum allowable didt
            [~,Rth_pad_mtx] = ndgrid(Topologies,Params.Rth_pad(NoofCells),NoofPhases);
            
            % pulse number modification
            if Params.OptimEnable % Enable optimized values
                %%%%%.........................................................
                %% Topology optimization
                %%%%%.........................................................
                
                %% Loading and Running the optimization matlab file
                if Params.Reoptimize
                    Optim_alg_Cap_Energy_All_Cells_from_dashboard;
                    load('OptimVars_new.mat', 'Optimal_p_vector')
                    p_mtx = Optimal_p_vector;
                else
                    load('OptimVars.mat', 'Optimal_p_vector')
                    p_mtx = Optimal_p_vector(Topologies,NoofCells,NoofPhases); % p-vector from the optimized results
                end
                
                
            else
                p = 2*round(Params.p*sqrt(NoofCells)/2)-1; % p set at LC resonance
                [~,p_mtx,~] = ndgrid(Topologies,p,NoofPhases); % number of phases time vectors
                
                p_mtx(Topologies_mtx == 1 | Topologies_mtx == 3) = 3*p_mtx(Topologies_mtx == 1 | Topologies_mtx == 3) - 1; % changing the pulse number of HB topologies
            end
            
            sawtooth_carrier_mtx = sawtooth(2*pi*f_mtx.*p_mtx.*t_mtx,0.5); % carrier wave
            sineRef_mtx = Params.Mmax*cos(2*pi*f_mtx.*t_mtx); % reference wave
            sineRef_inv_mtx = -Params.Mmax*cos(2*pi*f_mtx.*t_mtx); % reference wave
            s_mtx = double(sineRef_mtx > sawtooth_carrier_mtx); % switching function for HB - 1
            s_inv_mtx = double(sineRef_inv_mtx > sawtooth_carrier_mtx); % switching function for HB - 2


            [~,~,Iphavg_mtx] = ndgrid(Topologies,NoofCells,Iphavg); % Average phase current vector
            [~,~,Iphmax_mtx] = ndgrid(Topologies,NoofCells,Iphmax); % Maximum phase current vector
            [~,~,Iphavg_pk_t_mtx] = ndgrid(Topologies,NoofCells,Iphavg*sqrt(2),t); % Average phase current time vector
            [~,~,Iphmax_pk_t_mtx] = ndgrid(Topologies,NoofCells,Iphmax*sqrt(2),t); % Maximum phase current time vector
            [~,~,Iphavg_pk_F_mtx] = ndgrid(Topologies,NoofCells,Iphavg*sqrt(2),0:length(t)/2); % Average phase current frequency vector
            [~,~,Iphmax_pk_F_mtx] = ndgrid(Topologies,NoofCells,Iphmax*sqrt(2),0:length(t)/2); % Maximum phase current frequency vector
            
            [~,~,NoofPhases_Value_mtx] = ndgrid(Topologies,NoofCells,NoofPhases_Names); % number of phases value vector
            
            % Switch currents
            is1_avg_t_mtx = Iphavg_pk_t_mtx.*s_mtx.*cos(2*pi*f_mtx.*t_mtx - acos(Params.cosphi)); % Switch S1 Average currents
            is3_avg_t_mtx = -Iphavg_pk_t_mtx.*s_inv_mtx.*cos(2*pi*f_mtx.*t_mtx - acos(Params.cosphi)); % Switch S3 Average currents
            is1_max_t_mtx = Iphmax_pk_t_mtx.*s_mtx.*cos(2*pi*f_mtx.*t_mtx - acos(Params.cosphi)); % Switch S1 maximum currents
            is3_max_t_mtx = -Iphmax_pk_t_mtx.*s_inv_mtx.*cos(2*pi*f_mtx.*t_mtx - acos(Params.cosphi)); % Switch S1 maximum currents
            
            %% Switch statement to generate topology depended variables
            for i = 1:length(Topologies)
                switch Topologies(i)
                    case 1 % DSHB
                        Noofarms_per_phase(i) = 2; % number of arms per phase
                        U_sm(i)    = U_s(i)/2*Params.Mmax; % RMS SM output voltage
                        
                        Noofswitches_per_sm(i) = 2; % number of switches per submodule
                        
                        % Peak currents in SM frequency domain
                        Iphavg_pk_F_mtx(i,:,:,:) = Iphavg_pk_F_mtx(i,:,:,:)/Noofarms_per_phase(i); % Average
                        Iphmax_pk_F_mtx(i,:,:,:) = Iphmax_pk_F_mtx(i,:,:,:)/Noofarms_per_phase(i); % Maximum
                        
                        % DC side currents
                        idc_avg_t_mtx(i,:,:,:) = is1_avg_t_mtx(i,:,:,:)/Noofarms_per_phase(i); % Average
                        idc_max_t_mtx(i,:,:,:) = is1_max_t_mtx(i,:,:,:)/Noofarms_per_phase(i); % Maximum
                        
                    case 2 % DSFB
                        Noofarms_per_phase(i) = 2; % number of arms per phase
                        U_sm(i)    = U_s(i)*Params.Mmax; % RMS SM output voltage
                        
                        Noofswitches_per_sm(i) = 4; % number of switches per submodule
                        
                        % Peak currents in SM frequency domain
                        Iphavg_pk_F_mtx(i,:,:,:) = Iphavg_pk_F_mtx(i,:,:,:)/Noofarms_per_phase(i); % Average
                        Iphmax_pk_F_mtx(i,:,:,:) = Iphmax_pk_F_mtx(i,:,:,:)/Noofarms_per_phase(i); % Maximum
                        
                        % DC side currents
                        idc_avg_t_mtx(i,:,:,:) = (is1_avg_t_mtx(i,:,:,:) + is3_avg_t_mtx(i,:,:,:))/Noofarms_per_phase(i); % Average
                        idc_max_t_mtx(i,:,:,:) = (is1_max_t_mtx(i,:,:,:) + is3_max_t_mtx(i,:,:,:))/Noofarms_per_phase(i); % Maximum
                        
                    case 3 % SSHB
                        Noofarms_per_phase(i) = 1; % number of arms per phase
                        U_sm(i)    = U_s(i)/2*Params.Mmax; % RMS SM output voltage
                        
                        Noofswitches_per_sm(i) = 2; % number of switches per submodule
                        
                        % Peak currents in SM frequency domain
                        Iphavg_pk_F_mtx(i,:,:,:) = Iphavg_pk_F_mtx(i,:,:,:)/Noofarms_per_phase(i); % Average
                        Iphmax_pk_F_mtx(i,:,:,:) = Iphmax_pk_F_mtx(i,:,:,:)/Noofarms_per_phase(i); % Maximum
                        
                        % DC side currents
                        idc_avg_t_mtx(i,:,:,:) = is1_avg_t_mtx(i,:,:,:)/Noofarms_per_phase(i); % Average
                        idc_max_t_mtx(i,:,:,:) = is1_max_t_mtx(i,:,:,:)/Noofarms_per_phase(i); % Maximum
                        
                    case 4 % SSFB
                        Noofarms_per_phase(i) = 1; % number of arms per phase
                        U_sm(i)    = U_s(i)*Params.Mmax; % RMS SM output voltage
                        
                        Noofswitches_per_sm(i) = 4; % number of switches per submodule
                        
                        % Peak currents in SM frequency domain
                        Iphavg_pk_F_mtx(i,:,:,:) = Iphavg_pk_F_mtx(i,:,:,:)/Noofarms_per_phase(i); % Average
                        Iphmax_pk_F_mtx(i,:,:,:) = Iphmax_pk_F_mtx(i,:,:,:)/Noofarms_per_phase(i); % Maximum
                        
                        % DC side currents
                        idc_avg_t_mtx(i,:,:,:) = (is1_avg_t_mtx(i,:,:,:) + is3_avg_t_mtx(i,:,:,:))/Noofarms_per_phase(i); % Average
                        idc_max_t_mtx(i,:,:,:) = (is1_max_t_mtx(i,:,:,:) + is3_max_t_mtx(i,:,:,:))/Noofarms_per_phase(i); % Maximum
                        
                    case 5 % SDFB
                        Noofarms_per_phase(i) = 1; % number of arms per phase
                        U_sm(i)    = U_s(i)*Params.Mmax; % RMS SM output voltage
                        
                        Noofswitches_per_sm(i) = 4; % number of switches per submodule
                        
                        if NoofPhases_check(1)
                            %                             Iphavg_mtx(i,:,1) = Iphavg(1)/sqrt(3); % 3-phase delta current reduced by sqrt(3)
                            %                             Iphmax_mtx(i,:,1) = Iphmax(1)/sqrt(3); % 3-phase delta current rediced by sqrt(3)
                            
                            % Peak currents in SM
                            Iphavg_pk_t_mtx(i,:,1,:) = Iphavg(1)/sqrt(3)*sqrt(2); % 3-phase delta current reduced by sqrt(3)
                            Iphmax_pk_t_mtx(i,:,1,:) = Iphmax(1)/sqrt(3)*sqrt(2); % 3-phase delta current rediced by sqrt(3)
                            Iphavg_pk_F_mtx(i,:,1,:) = Iphavg(1)/sqrt(3)*sqrt(2)/Noofarms_per_phase(i); % 3-phase delta current reduced by sqrt(3)
                            Iphmax_pk_F_mtx(i,:,1,:) = Iphmax(1)/sqrt(3)*sqrt(2)/Noofarms_per_phase(i); % 3-phase delta current rediced by sqrt(3)
                            
                            is1_avg_t_mtx(i,:,1,:) = is1_avg_t_mtx(i,:,1,:)/sqrt(3); % 3-phase delta current reduced by sqrt(3)
                            is3_avg_t_mtx(i,:,1,:) = is3_avg_t_mtx(i,:,1,:)/sqrt(3); % 3-phase delta current reduced by sqrt(3)
                            is1_max_t_mtx(i,:,1,:) = is1_max_t_mtx(i,:,1,:)/sqrt(3); % 3-phase delta current reduced by sqrt(3)
                            is3_max_t_mtx(i,:,1,:) = is3_max_t_mtx(i,:,1,:)/sqrt(3); % 3-phase delta current reduced by sqrt(3)
                            
                            Uph_mtx(i,:,1) = Params.Upp; % Delta output phase voltage and line voltages are the same
                        end
                        
                        % DC side currents
                        idc_avg_t_mtx(i,:,:,:) = (is1_avg_t_mtx(i,:,:,:) + is3_avg_t_mtx(i,:,:,:))/Noofarms_per_phase(i); % Average
                        idc_max_t_mtx(i,:,:,:) = (is1_max_t_mtx(i,:,:,:) + is3_max_t_mtx(i,:,:,:))/Noofarms_per_phase(i); % Maximum
                        
                    otherwise
                        app.ErrorLamp.Enable = 'on'; % error indicator
                        app.ErrorField.Value = "Topology Not selected!";
                        error_flag = 1; % set error flag
                end
            end
            
            %% MOSFET selecting based on the number of series cells per submodule
            for i = 1:length(NoofCells)
                switch NoofCells(i)
                    case 1 % LiU MOSFET
                        Vdss(i) = 5; % Max drain-source voltage
                        Rdson(i) = Params.Rdson(i); % on-state resistance
                        RdsonMax(i) = Params.Rdson(i).*1.5; % on-state resistance maximum
                        RTheta_Jc(i) = Params.Rthjc(i); % thermal resistance (junction to case)
                        TjMax(i) = 150; % maximum junction temperature
                        k_sc(i) = Params.Ksw(i); % cost of the MOSFET [€/MOSFET] Estimated
                        log_rate(i) = Params.log_rate(i); % delta change wrt number of submodules
                    case 2 % SiR178DP https://www.vishay.com/docs/77598/sir178dp.pdf
                        Vdss(i) = 20; % Max drain-source voltage
                        Rdson(i) = Params.Rdson(i); % on-state resistance
                        RdsonMax(i) = Params.Rdson(i).*1.5; % on-state resistance maximum
                        RTheta_Jc(i) = Params.Rthjc(i); % thermal resistance (junction to case)
                        TjMax(i) = 150; % maximum junction temperature
                        k_sc(i) = Params.Ksw(i); % cost of the MOSFET [€/MOSFET] Estimated
                        log_rate(i) = Params.log_rate(i); % delta change wrt number of submodules
                    case 3 % SiR178DP https://www.vishay.com/docs/77598/sir178dp.pdf
                        Vdss(i) = 20; % Max drain-source voltage
                        Rdson(i) = Params.Rdson(i); % on-state resistance
                        RdsonMax(i) = Params.Rdson(i).*1.5; % on-state resistance maximum2
                        RTheta_Jc(i) = Params.Rthjc(i); % thermal resistance (junction to case)
                        TjMax(i) = 150; % maximum junction temperature
                        k_sc(i) = Params.Ksw(i); % cost of the MOSFET [€/MOSFET] Estimated
                        log_rate(i) = Params.log_rate(i); % delta change wrt number of submodules
                    case 4 % NTMTS0D4N04CL https://www.mouser.se/datasheet/2/308/NTMTS0D4N04CL_D-1664468.pdf
                        Vdss(i) = 40; % Max drain-source voltage
                        Rdson(i) = Params.Rdson(i); % on-state resistance
                        RdsonMax(i) = Params.Rdson(i).*1.5; % on-state resistance maximum
                        RTheta_Jc(i) = Params.Rthjc(i); % thermal resistance (junction to case)
                        TjMax(i) = 150; % maximum junction temperature
                        k_sc(i) = Params.Ksw(i); % cost of the MOSFET [€/MOSFET] Estimated
                        log_rate(i) = Params.log_rate(i); % delta change wrt number of submodules
                    case 5 % NTMTS0D4N04CL https://www.mouser.se/datasheet/2/308/NTMTS0D4N04CL_D-1664468.pdf
                        Vdss(i) = 40; % Max drain-source voltage
                        Rdson(i) = Params.Rdson(i); % on-state resistance
                        RdsonMax(i) = Params.Rdson(i).*1.5; % on-state resistance maximum
                        RTheta_Jc(i) = Params.Rthjc(i); % thermal resistance (junction to case)
                        TjMax(i) = 150; % maximum junction temperature
                        k_sc(i) = Params.Ksw(i); % cost of the MOSFET [€/MOSFET] Estimated
                        log_rate(i) = Params.log_rate(i); % delta change wrt number of submodules
                    case 6 % NTMTS0D4N04CL https://www.mouser.se/datasheet/2/308/NTMTS0D4N04CL_D-1664468.pdf
                        Vdss(i) = 40; % Max drain-source voltage
                        Rdson(i) = Params.Rdson(i); % on-state resistance
                        RdsonMax(i) = Params.Rdson(i).*1.5; % on-state resistance maximum
                        RTheta_Jc(i) = Params.Rthjc(i); % thermal resistance (junction to case)
                        TjMax(i) = 150; % maximum junction temperature
                        k_sc(i) = Params.Ksw(i); % cost of the MOSFET [€/MOSFET] Estimated
                        log_rate(i) = Params.log_rate(i); % delta change wrt number of submodules
                    case 7 % NTMTS0D7N06CL https://www.mouser.se/datasheet/2/308/NTMTS0D7N06CL_D-1664507.pdf
                        Vdss(i) = 60; % Max drain-source voltage
                        Rdson(i) = Params.Rdson(i); % on-state resistance
                        RdsonMax(i) = Params.Rdson(i).*1.5; % on-state resistance maximum
                        RTheta_Jc(i) = Params.Rthjc(i); % thermal resistance (junction to case)
                        TjMax(i) = 150; % maximum junction temperature
                        k_sc(i) = Params.Ksw(i); % cost of the MOSFET [€/MOSFET] Estimated
                        log_rate(i) = Params.log_rate(i); % delta change wrt number of submodules
                    case 8 % NTMTS0D7N06CL https://www.mouser.se/datasheet/2/308/NTMTS0D7N06CL_D-1664507.pdf
                        Vdss(i) = 60; % Max drain-source voltage
                        Rdson(i) = Params.Rdson(i); % on-state resistance
                        RdsonMax(i) = Params.Rdson(i).*1.5; % on-state resistance maximum
                        RTheta_Jc(i) = Params.Rthjc(i); % thermal resistance (junction to case)
                        TjMax(i) = 150; % maximum junction temperature
                        k_sc(i) = Params.Ksw(i); % cost of the MOSFET [€/MOSFET] Estimated
                        log_rate(i) = Params.log_rate(i); % delta change wrt number of submodules
                    case 9 % NTMTS0D7N06CL https://www.mouser.se/datasheet/2/308/NTMTS0D7N06CL_D-1664507.pdf
                        Vdss(i) = 60; % Max drain-source voltage
                        Rdson(i) = Params.Rdson(i); % on-state resistance
                        RdsonMax(i) = Params.Rdson(i).*1.5; % on-state resistance maximum
                        RTheta_Jc(i) = Params.Rthjc(i); % thermal resistance (junction to case)
                        TjMax(i) = 150; % maximum junction temperature
                        k_sc(i) = Params.Ksw(i); % cost of the MOSFET [€/MOSFET] Estimated
                        log_rate(i) = Params.log_rate(i); % delta change wrt number of submodules
                    case 10 % NVBLS1D1N08H https://www.mouser.se/datasheet/2/308/NVBLS1D1N08H_D-1814524.pdf
                        Vdss(i) = 80; % Max drain-source voltage
                        Rdson(i) = Params.Rdson(i); % on-state resistance
                        RdsonMax(i) = Params.Rdson(i).*1.5; % on-state resistance maximum
                        RTheta_Jc(i) = Params.Rthjc(i); % thermal resistance (junction to case)
                        TjMax(i) = 150; % maximum junction temperature
                        k_sc(i) = Params.Ksw(i); % cost of the MOSFET [€/MOSFET] Estimated
                        log_rate(i) = Params.log_rate(i); % delta change wrt number of submodules
                    case 11 % NVBLS1D1N08H https://www.mouser.se/datasheet/2/308/NVBLS1D1N08H_D-1814524.pdf
                        Vdss(i) = 80; % Max drain-source voltage
                        Rdson(i) = Params.Rdson(i); % on-state resistance
                        RdsonMax(i) = Params.Rdson(i).*1.5; % on-state resistance maximum
                        RTheta_Jc(i) = Params.Rthjc(i); % thermal resistance (junction to case)
                        TjMax(i) = 150; % maximum junction temperature
                        k_sc(i) = Params.Ksw(i); % cost of the MOSFET [€/MOSFET] Estimated
                        log_rate(i) = Params.log_rate(i); % delta change wrt number of submodules
                    case 12 % NVBLS1D1N08H https://www.mouser.se/datasheet/2/308/NVBLS1D1N08H_D-1814524.pdf
                        Vdss(i) = 80; % Max drain-source voltage
                        Rdson(i) = Params.Rdson(i); % on-state resistance
                        RdsonMax(i) = Params.Rdson(i).*1.5; % on-state resistance maximum
                        RTheta_Jc(i) = Params.Rthjc(i); % thermal resistance (junction to case)
                        TjMax(i) = 150; % maximum junction temperature
                        k_sc(i) = Params.Ksw(i); % cost of the MOSFET [€/MOSFET] Estimated
                        log_rate(i) = Params.log_rate(i); % delta change wrt number of submodules
                    otherwise
                        return; % exit the function
                end
            end
            RdsonMax=Rdson*1.5;
            
            U_sm_mtx = ndgrid(U_sm,NoofCells,NoofPhases); % RMS SM output voltage vector
            Noofarms_per_phase_mtx = ndgrid(Noofarms_per_phase,NoofCells,NoofPhases); % number of arms per phase vector
            U_s_mtx = ndgrid(U_s,NoofCells,NoofPhases); % DC-side SM voltage
            
            Noofswitches_per_sm_mtx = ndgrid(Noofswitches_per_sm,NoofCells,NoofPhases); % number of switches per submodule
            
            U_sm_mtx = U_sm_mtx.*NoofCells_mtx; % RMS SM output voltage vector considering number of series connected cells
            U_s_sw_mtx = U_s_mtx.*Params.Uc_n./Params.Uc_min.*NoofCells_mtx; % DC side submodule voltage vector considering number of series connected cells for nominal cell voltage (U_s_mtx considered minimum cell voltage)
            
            I_arm_avg_mtx = Iphavg_mtx./Noofarms_per_phase_mtx; % Average Arm current vector
            I_arm_max_mtx = Iphmax_mtx./Noofarms_per_phase_mtx; % Maximum Arm current vector
            
            % change of arm current for the SDFB topologies
            I_arm_avg_mtx(Topologies_mtx == 5 & NoofPhases_mtx == 3) = I_arm_avg_mtx(Topologies_mtx == 5 & NoofPhases_mtx == 3)/sqrt(3);
            I_arm_max_mtx(Topologies_mtx == 5 & NoofPhases_mtx == 3) = I_arm_max_mtx(Topologies_mtx == 5 & NoofPhases_mtx == 3)/sqrt(3);
            
            [~,Vdss_mtx] = ndgrid(Topologies,Vdss,NoofPhases); % Average on-state resistance matrix
            Vds_margin_mtx=(Vdss_mtx-U_s_sw_mtx*(1+Params.dUds/100))./Vdss_mtx;
            
            [~,Rdson_mtx] = ndgrid(Topologies,Rdson,NoofPhases); % Average on-state resistance matrix
            [~,RdsonMax_mtx] = ndgrid(Topologies,RdsonMax,NoofPhases); % Average on-state resistance matrix
            [~,RTheta_Jc_mtx] = ndgrid(Topologies,RTheta_Jc,NoofPhases); % Average on-state resistance matrix
            [~,TjMax_mtx] = ndgrid(Topologies,TjMax,NoofPhases); % Average on-state resistance matrix
            
            % Switch Currents
            I_sw_avg_sw_mtx = I_arm_avg_mtx/sqrt(2);% RMS Average switch current vector
            I_sw_max_sw_mtx = I_arm_max_mtx/sqrt(2);% RMS Maximum switch current vector
            I_sw_av_avg_sw_mtx = 2*sqrt(2)*I_arm_avg_mtx/pi;% RMS Average switch current vector
            I_sw_av_max_sw_mtx = 2*sqrt(2)*I_arm_max_mtx/pi;% RMS Maximum switch current vector
            
            %             t_fi_max_mtx = I_sw_av_max_sw_mtx./Params.f_didt*1e-6; % current fall time
            %             t_ri_max_mtx = I_sw_av_max_sw_mtx./Params.r_didt*1e-6; % current rise time
            
            %             t_fv_avg_mtx = U_s_sw_mtx./Params.f_dvdt*1e-6; % voltage fall time average
            %             t_rv_avg_mtx = U_s_sw_mtx./Params.r_dvdt*1e-6; % voltage rise time average
            %             t_fi_avg_mtx = I_sw_av_avg_sw_mtx./Params.f_didt*1e-6; % current fall time average
            %             t_ri_avg_mtx = I_sw_av_avg_sw_mtx./Params.r_didt*1e-6; % current rise time average
            
            % Cost determination
            [~,k_sc_mtx,~] = ndgrid(Topologies,k_sc,NoofPhases); % cost of MOSFET vector
            
            % delta fucntion with respect to the number of cells per
            % submodule
            [~,log_rate_mtx,~]=ndgrid(Topologies,log_rate,NoofPhases);
            
            %%%%---------------------------------------------------------
            %% Evalulation section
            %%%%---------------------------------------------------------
            
            save DataBuffer.mat
            
            %%%%...........................................................
            %% Two-level inverter reference case
            %%%%...........................................................
            
            refa_2l = Params.Mmax*cos(2*pi*f*t); % referene signal phase A
            refb_2l = Params.Mmax*cos(2*pi*f*t + 2*pi/3); % referene signal phase B
            refc_2l = Params.Mmax*cos(2*pi*f*t - 2*pi/3); % referene signal phase C
            
            carr_2l = sawtooth(2*pi*f*Params.p2l.*t,0.5); % carrier signal
            
            sa_2l = double(refa_2l>carr_2l); % Phase A switching function
            sb_2l = double(refb_2l>carr_2l); % Phase B switching function
            sc_2l = double(refc_2l>carr_2l); % Phase C switching function
            
            io_pk_avg = Params.Pout/(Params.Upp*sqrt(3)*Params.cosphi)*sqrt(2); % average output peak current
            io_pk_max = Params.Pout*Params.ocf/(Params.Upp*sqrt(3)*Params.cosphi)*sqrt(2); % maximum output peak current
            
            Io_avg = io_pk_avg/sqrt(2); % RMS average output current
            Io_max = io_pk_max/sqrt(2); % RMS maximum output current
            
            io_avg = io_pk_avg*cos(2*pi*f*t - acos(Params.cosphi)); % contonuous time average output current
            io_max = io_pk_max*cos(2*pi*f*t - acos(Params.cosphi)); % contonuous time maximum output current
            
            I_2l_rms_avg = Io_avg/sqrt(2); % RMS current through MOSFET
            I_2l_rms_max = Io_max/sqrt(2); % RMS current through MOSFET
            I_2l_abs_avg = Io_avg*2*sqrt(2)/pi; % Absalure average current
            I_2l_abs_avg_max = Io_max*2*sqrt(2)/pi; % Absalure average current
            
            % 2L-inverter losses
            if Params.InvModule.MOSFET3p % MOSFET selected
                % CAB450M12XM3 https://www.wolfspeed.com/downloads/dl/file/id/1493/product/482/cab450m12xm3.pdf
                % MOSFET ordered by Scania
                
                CAB450M12XM3_loss_calculation; % file to calculate the MOSFET losses
                
            elseif Params.InvModule.MOSFETnp  % MOSFET selected
                % MOSFET parameters SKM260MB170SCH17 https://www.semikron.com/products/product-classes/sic/full-sic.html?gclid=Cj0KCQjw1PSDBhDbARIsAPeTqrfZFFtmi-OvJA3cEEd_GSzI6kiJcClEeWGpl3p7jE_XqF-Hw37RNVIaArYNEALw_wcB#view/table/items/20/order/id-in-a+desc/
                
                SKM260MB170SCH17_loss_calculation; % file to calculate the MOSFET losses
                
            elseif Params.InvModule.IGBT % IGBT selected (SOMETHING WRONG WITH THIS CODE -- WILL LOOK INTO IT LATER)
                % IGBT parameters https://www.mouser.se/datasheet/2/240/Viewer-1549534.pdf
                Uomax_igbt = 1.1; % [V] Forward voltage drop
                Rdson_igbt = 4.6e-3; % On-state resistance [mOhm]
                % Diode parameters
                Uomax_diode = 4.6; % [V] Forward voltage drop
                Rdson_diode = 8.5e-3; % On-state resistance [mOhm]
                
                Tj_max_2l = 150; % maximum junction temperature of IGBT module
                
                E_on_datasheet_avg = 7e-3; % turn-on energy for 2-level inverter maximum
                E_rec_datasheet_avg = 10e-3; % reverse recovery for IGBT body diode energy for 2-level inverter maximum
                E_off_datasheet_avg = 25e-3; % turn-on energy for 2-level inverter maximum
                
                E_on_datasheet_max = 63e-3; % turn-on energy for 2-level inverter maximum
                E_rec_datasheet_max = 20e-3; % reverse recovery for IGBT body diode energy for 2-level inverter maximum
                E_off_datasheet_max = 72e-3; % turn-on energy for 2-level inverter maximum
                
                k_sc = 70; % cost per module [€/Module] https://www.mouser.se/ProductDetail/IXYS/MIXA300PF1200TSF?qs=%2Fha2pyFadujx79Cd6GMAKHTV48LQFGp%2FvqzqVTxeOsigRYJnNdo81A%3D%3D
                k_GateDrive = 2*35; % Gate drive cost per module [2x€/Module] Guess value
                k_controller = 100; % Controller, sensor, saftey costs [€/converter] Arbitrary
                
                P_sw_loss_avg = (E_on_datasheet_avg + E_rec_datasheet_avg + E_off_datasheet_avg)*Params.p2l*f; % average converter switching losses per IGBT
                P_sw_loss_max = (E_on_datasheet_max + E_rec_datasheet_max + E_off_datasheet_max)*Params.p2l*f; % maximum converter switching losses per IGBT
                
                Rthata_jc_MOS = 0.085; % junction to case thermal resistance [K/W] Transistor
                Rthata_ca_MOS = 0.04; % case to ambient thermal resistance [K/W] Transistor
                Rthata_jc_diode = 0.145; % junction to case thermal resistance [K/W] diode
                Rthata_ca_diode = 0.05; % case to ambient thermal resistance [K/W] diode
                
                io_avg_pos = io_avg;
                io_avg_pos(io_avg < 0) = 0; % positive current average
                io_avg_neg = -io_avg;
                io_avg_neg(io_avg > 0) = 0; % negative current average
                
                io_max_pos = io_max;
                io_max_pos(io_max < 0) = 0; % positive current maximum
                io_max_neg = -io_max;
                io_max_neg(io_max > 0) = 0; % negative current maximum
                
                %                 P_con_loss_pos_max = mean(io_max_pos.^2*Rdson_igbt + abs(io_max_pos)*Uomax_igbt); % positive direction conduction losses maximum
                %                 P_con_loss_neg_max = mean(io_max_neg.^2*Rdson_diode + abs(io_max_neg)*Uomax_diode); % negative direction conduction losses maximum
                
                Pcon_Rth_loss_perIGBT_IR_max = mean(io_max_pos.^2*Rdson_igbt)*(Rthata_jc_MOS+Rthata_jc_MOS) + mean(io_max_neg.^2*Rdson_diode)*(Rthata_jc_MOS + Rthata_ca_MOS); % maximium I^2R losses and Rth product per IGBT
                Pcon_Rth_loss_perIGBT_VI_max = mean(abs(io_max_pos).*Uomax_igbt)*(Rthata_jc_MOS+Rthata_jc_MOS) + mean(abs(io_max_neg).*Uomax_diode)*(Rthata_jc_MOS+Rthata_jc_MOS); % maximium VI losses and Rth product per IGBT
                Psw_Rth_loss_max = ((E_on_datasheet_max + E_off_datasheet_max)*(Rthata_jc_MOS+Rthata_jc_MOS) + E_rec_datasheet_max*(Rthata_jc_MOS+Rthata_jc_MOS))*Params.p2l*f; % maximium switching losses and Rth product per IGBT
                
                dTja = Tj_max_2l - Params.Ta2l; % case to ambient temperature rise
                
                Npmos_2l = Pcon_Rth_loss_perIGBT_IR_max./(dTja - (Psw_Rth_loss_max + Pcon_Rth_loss_perIGBT_VI_max)); % number of parallel MOSFETs
                
                Npmos_2l_active = max(1,Npmos_2l); % minimum number of parallel MOSFETs is 1
                
                P_con_loss_pos_avg = mean(io_avg_pos.^2*Rdson_igbt/Npmos_2l_active + abs(io_avg_pos)*Uomax_igbt); % positive direction conduction losses average
                P_con_loss_pos_max = mean(io_max_pos.^2*Rdson_igbt/Npmos_2l_active + abs(io_max_pos)*Uomax_igbt); % positive direction conduction losses maximum
                P_con_loss_neg_avg = mean(io_avg_neg.^2*Rdson_diode/Npmos_2l_active + abs(io_avg_neg)*Uomax_diode); % negative direction conduction losses average
                P_con_loss_neg_max = mean(io_max_neg.^2*Rdson_diode/Npmos_2l_active + abs(io_max_neg)*Uomax_diode); % negative direction conduction losses maximum
                
                P_2l_loss_avg = 3*2*(P_con_loss_pos_avg + P_con_loss_neg_avg + P_sw_loss_avg); % converter losses (2-level inverter) average
                P_2l_loss_max = 3*2*(P_con_loss_pos_max + P_con_loss_neg_max + P_sw_loss_max); % converter losses (2-level inverter) maximum
                
                %                 P_con_loss_pos_avg = mean(io_avg_pos.^2*Rdson_igbt + abs(io_avg_pos)*Uomax_igbt); % positive direction conduction losses average
                %                 P_con_loss_neg_avg = mean(io_avg_neg.^2*Rdson_diode + abs(io_avg_neg)*Uomax_diode); % negative direction conduction losses average
                
                Tc_avg = Rthata_ca_MOS*2.*(P_con_loss_pos_avg + (E_on_datasheet_avg + E_off_datasheet_avg)*Params.p2l*f)+ Rthata_ca_diode*2.*(P_con_loss_neg_avg + Params.p2l*f*E_rec_datasheet_avg)+ Params.Ta; % Rth*Total losses + Ta
                Tj_2l_avg = Rthata_jc_MOS.*(P_con_loss_pos_avg + (E_on_datasheet_avg + E_off_datasheet_avg)*Params.p2l*f) + Rthata_jc_diode*2.*(P_con_loss_neg_avg + Params.p2l*f*E_rec_datasheet_avg) + Tc_avg; % Rth*losses per MOSFET + Tc
                
                Tc_max = Rthata_ca_MOS*2.*(P_con_loss_pos_max + (E_on_datasheet_max + E_off_datasheet_max)*Params.p2l*f)+ Rthata_ca_diode*2.*(P_con_loss_neg_max + Params.p2l*f*E_rec_datasheet_max) + Params.Ta; % Rth*Total losses + Ta
                Tj_2l_max = Rthata_jc_MOS.*(P_con_loss_pos_max + (E_on_datasheet_max + E_off_datasheet_max)*Params.p2l*f) + Rthata_jc_diode*2.*(P_con_loss_neg_max + Params.p2l*f*E_rec_datasheet_max) +Tc_max; % Rth*losses per MOSFET + Tc
                
                lambda_sc_2l = k_sc.*Npmos_2l_active.*3; % cost of semiconductors
                lambda_sm_2l = k_GateDrive.*3.*2 + k_controller ; % sigle gate ddriver for all parallel Modules
                
                lambda_conv_2l = lambda_sc_2l + lambda_sm_2l; % total converter cost [€]
            end
            
            Htfr_2l_mtx = Htfr_2l.*ones(size(NoofCells_mtx)); % Heat transfer coefficienct 2-level inverter vector
            p_2l_mtx = Params.p2l.*ones(size(NoofCells_mtx)); % Pulse no 2-level inverter vector
            
            lambda_semiconductor_2l_mtx = lambda_conv_2l.*ones(size(NoofCells_mtx)); % total cost of the 2-level inverter [€]
            
            P_2l_loss_avg_mtx = P_2l_loss_avg*ones(size(NoofCells_mtx)); % converter losses (2-level inverter) average vector
            P_2l_loss_max_mtx = P_2l_loss_max*ones(size(NoofCells_mtx)); % converter losses (2-level inverter) maximum vector
            
            Npmos_2l_mtx = Npmos_2l*ones(size(NoofCells_mtx)); % number of parallel Modules vector
            
            Tj_2l_mtx_avg = Tj_2l_avg.*ones(size(NoofCells_mtx)); % average junction temperature vector
            Tj_2l_mtx_max = Tj_2l_max.*ones(size(NoofCells_mtx)); % maximum junction temperature vector
            
            Tc_2l_mtx_avg = Tc_2l_avg.*ones(size(NoofCells_mtx)); % average junction temperature vector
            Tc_2l_mtx_max = Tc_2l_max.*ones(size(NoofCells_mtx)); % maximum case temperature vector
            
            % Battery and capacitor losses
            
            io_a_avg = io_avg; % phase A average current
            io_b_avg = io_pk_avg*cos(2*pi*f*t - acos(Params.cosphi) + 2*pi/3); % phase B average current
            io_c_avg = io_pk_avg*cos(2*pi*f*t - acos(Params.cosphi) - 2*pi/3); % phase C average current
            
            io_a_max = io_max; % phase A average current
            io_b_max = io_pk_max*cos(2*pi*f*t - acos(Params.cosphi) + 2*pi/3); % phase B average current
            io_c_max = io_pk_max*cos(2*pi*f*t - acos(Params.cosphi) - 2*pi/3); % phase C average current
            
            i_dc_avg = io_a_avg.*sa_2l + io_b_avg.*sb_2l + io_c_avg.*sc_2l; % average DC-side currents
            i_dc_max = io_a_max.*sa_2l + io_b_max.*sb_2l + io_c_max.*sc_2l; % matrix DC-side currents
            
            I_dc_avg = FFT_fn1d(i_dc_avg,t); % average DC-side currents frequency response
            [I_dc_max,F_2l] = FFT_fn1d(i_dc_max,t); % average DC-side currents frequency response
            
            Noofcells_series_2l = Params.Udc/Params.Uc_n; % number of series cells 2-level inverter
            Noofcells_parallel_2l = Params.Ebatt/(Params.Udc*Params.DoD*Params.Bcap); % number of parallel cells 2-level inverter
            
            Cap_2l = Params.Cap_2l_constant/(0.5*Params.Udc^2);% Cap defined by energy rating;
            
            % Capacitor Datasheet https://www.mouser.se/datasheet/2/40/ffve_ffvi-776591.pdf
            % New::: https://product.tdk.com/system/files/dam/doc/product/capacitor/film/power/data_sheet/20/50/ds/b25690.pdf
            ESR_cap_2l = Params.ESR_2l/100/(2*pi*1000*Cap_2l); % DC-link capacitor equivalent Series Reistance 2-level inverter
            %             Cap_2l =   Params.Cap_2l; % DC-link capacitance 2-level inverter
            ESL_cap_2l = Params.ESL_2l; % DC-link capacitor equivalent series inducatce
            
            %             NoofCaps_parallel = Params.Npcap_2l; % number of parallel capacitors
            
            s_2l = 2j*pi*F_2l; % lapace ceofficient
            
            %             ESL_cap_2l = 2e-9; % DC-link capacitor equivalent series inducatce
            
            %             ESR_cap_2l = 1./(io_pk_max)*(Params.Udc_ESRripple_2l*Params.Udc - ...
            %                 ESL_cap_2l*I_2l_abs_avg_max/t_sw_2l_max); % DC-link capacitor ESR vector
            
            %             I_dc = Params.Pout.*Params.ocf/(Params.Udc); % Dc-side current average
            
            Zcap_2l = (ESR_cap_2l + 1./s_2l/Cap_2l + s_2l.*ESL_cap_2l); % capacitor impedance
            
            dUs = (ESR_cap_2l*io_pk_max + 1/Cap_2l*0.5*io_pk_max*t_sw_2l_max + ESL_cap_2l*I_2l_abs_avg_max/t_sw_2l_max); % delta Uds on MOSFET during switching transient
            dUs_ver_2l_percent = dUs/Params.Udc*100; % percentage overshoot
            dUs_ver_2l_percent_mtx = dUs_ver_2l_percent.*ones(size(NoofCells_mtx)); % percentage overshoot vector
            
            Ecap_2l = 0.5.*Cap_2l.*Params.Udc^2; % Total energy stored in the capacitor
            Ecap_2l_mtx = Ecap_2l*ones(size(NoofCells_mtx)); % Total energy stored in the capacitor vector
            
            lambda_cap_2l_mtx = Params.Kcap.*Ecap_2l_mtx; % total cost of the capacitors for 2-level inverter vector
            
            lambda_conv_2l_mtx = lambda_semiconductor_2l_mtx + lambda_cap_2l_mtx; % total converter losses for 2-llevel inverter vector


            % loading the paramters file from Scania battery EIS extraction
            % The first three files seem to have some errors
            %             load SS1_25C_1_param.mat % normal file
            %             load SS1_25C_1_param2.mat % load file with lower series inductance resistance.
            %             load SS1_25C_1_param_kopia2.mat % load file with lower parallel resistance.
            
            if Params.AlelionEnable % Enable -- using Alelion cell data
                % Load Samsung 24Ah cell EIS
                name='PHEV2_24A_New_25m';
                
                eval(['load ',name,'.txt']);
                eval(['lokal=',name]);
                
                [NumberOfMeasurementpoints,~]=size(lokal);
                
                StartSelection=1;
                EndSelection=NumberOfMeasurementpoints;
                
                f_vect=lokal(StartSelection:EndSelection,3);
                z_real=lokal(StartSelection:EndSelection,4);
                z_imag=lokal(StartSelection:EndSelection,5);
                z_vect=z_real+sqrt(-1)*z_imag;
                
                Z_cell_real_2l=interp1(f_vect,z_real,F_2l,'linear','extrap');
                Z_cell_imag_2l=interp1(f_vect,z_imag,F_2l,'linear','extrap');
                Zcell_2l=Z_cell_real_2l+1j*Z_cell_imag_2l;
                
                Z_cell_real_2l_dc = interp1(f_vect,z_real,Params.DC_imp_freq,'linear','extrap'); % DC impedance
                Z_cell_imag_2l_dc = interp1(f_vect,z_imag,Params.DC_imp_freq,'linear','extrap'); % DC impedance
                Zcell_2l(1) = Z_cell_real_2l_dc + 1j*Z_cell_imag_2l_dc;
                
                x_param(9) = 50e-9;
                
            else
                % loading the paramters file from Scania battery EIS extraction
                load SS1_25C_1_param_nominal.mat; % loading nominal EIS model
                %                 load SS1_25C_1_param_0_1puRs.mat; % loading model with reduced Rs, 0.1 Rs_nominal
                %                 load SS1_25C_1_param_0_05puRs.mat; % loading model with reduced Rs, 0.05 Rs_nominal
                %                 load SS1_25C_1_param_10puRs.mat; % loading model with reduced Rs, 10 Rs_nominal
                
                % Battery cell impedance function with RL
                Z_cell_fn_2l = @(x)(x(1) ... % R0
                    + x(2)./(s_2l*x(2)*x(3)+1) + ... % R1||C1
                    x(4)./(s_2l*x(4)*x(5)+1) + x(6)./(s_2l*x(6)*x(7)+1) + ... % R2||C2 + R3||C3
                    s_2l*x(8)*x(9)./(x(8) + s_2l*x(9))); ... % Rs||Ls
                    
                % Battery cell impedance function with L only
                Z_cell_fn_L = @(x)(x(1) ... % R0
                    + x(2)./(s*x(2)*x(3)+1) + ... % R1||C1
                    x(4)./(s*x(4)*x(5)+1) + x(6)./(s*x(6)*x(7)+1) + ... % R2||C2 + R3||C3
                    s*x(8)); ... % |Ls
                    
                Zcell_2l = Z_cell_fn_2l(x_param); % impedance of the cell
            end
            
            
            Zbatt_2l = Zcell_2l.*Noofcells_series_2l./Noofcells_parallel_2l + s_2l*Params.Lbatt_leads_2l; % total battery pack
            
            Zeta_2l = 0.5*ESR_cap_2l.*sqrt(Cap_2l./(ESL_cap_2l + Params.Lbatt_leads_2l + x_param(9).*Noofcells_series_2l./Noofcells_parallel_2l));
            Zeta_2l_mtx = Zeta_2l.*ones(size(NoofCells_mtx)); % zeta 2-level inverter vector
            
            I_cap_avg = I_dc_avg.*Zbatt_2l./(Zbatt_2l + Zcap_2l); % Capacitor current average
            I_batt_avg = I_dc_avg.*Zcap_2l./(Zbatt_2l + Zcap_2l); % Battery current average
            I_batt_avg(:,1) = I_dc_avg(:,1); % DC component
            I_cap_avg(:,1) = 0; % DC component
            
            I_cap_max = I_dc_max.*Zbatt_2l./(Zbatt_2l + Zcap_2l); % Capacitor current maximum
            I_batt_max = I_dc_max.*Zcap_2l./(Zbatt_2l + Zcap_2l); % Battery current maximum
            I_batt_max(:,1) = I_dc_max(:,1); % DC component
            I_cap_max(:,1) = 0; % DC component
            
            if Params.LossLim % Limit the loss calculations upto 10 kHz
                I_batt_avg(:,find(F_2l>Params.f_limit)) = 0; % equate the battery currents to zero
                I_cap_avg(:,find(F_2l>Params.f_limit)) = 0; % equate the capacitor currents to zero
                I_batt_max(:,find(F_2l>Params.f_limit)) = 0; % equate the battery currents to zero
                I_cap_max(:,find(F_2l>Params.f_limit)) = 0; % equate the capacitor currents to zero
            end
            
            %%% Power losses in batter and capcitor
            Pbatt_avg_f_2l = I_batt_avg.*conj(I_batt_avg).*real(Zbatt_2l); % battery power losses average
            Pcap_avg_f_2l = I_cap_avg.*conj(I_cap_avg).*real(Zcap_2l); % capacitor power losses average
            Pcap_avg_f_2l(:,1) = 0; % capacitor losses at DC
            
            Pbatt_max_f_2l = I_batt_max.*conj(I_batt_max).*real(Zbatt_2l); % battery power losses maximum
            Pcap_max_f_2l = I_cap_max.*conj(I_cap_max).*real(Zcap_2l); % capacitor power losses maximum
            Pcap_max_f_2l(:,1) = 0; % capacitor losses at DC
            
            Pbatt_2l_avg = sum(Pbatt_avg_f_2l); % average battery losses
            Pcap_2l_avg = sum(Pcap_avg_f_2l); % average capacitor losses
            
            Pbatt_2l_max = sum(Pbatt_max_f_2l); % maximum battery losses
            Pcap_2l_max = sum(Pcap_max_f_2l); % maximum capacitor losses
            
            Pbatt_2l_avg_mtx = Pbatt_2l_avg*ones(size(NoofCells_mtx)); % average battery losses 2-level vector
            Pcap_2l_avg_mtx = Pcap_2l_avg*ones(size(NoofCells_mtx)); % average battery losses 2-level vector
            
            Pbatt_2l_max_mtx = Pbatt_2l_max*ones(size(NoofCells_mtx)); % maximum battery losses 2-level vector
            Pcap_2l_max_mtx = Pcap_2l_max*ones(size(NoofCells_mtx)); % maximum battery losses 2-level vector
            
            Ploss_tot_2l_avg = Pbatt_2l_avg_mtx + Pcap_2l_avg_mtx + P_2l_loss_avg_mtx; % total average 2-level inverter losses
            Ploss_tot_2l_max = Pbatt_2l_max_mtx + Pcap_2l_max_mtx + P_2l_loss_max_mtx; % total maximum 2-level inverter losses
            
            eff_tot_2l_avg = Params.Pout./(Params.Pout + Ploss_tot_2l_avg); % Average effeciency 2-level inverter
            eff_tot_2l_max = Params.Pout*Params.ocf./(Params.Pout*Params.ocf + Ploss_tot_2l_max); % Maximum effeciency 2-level inverter
            
            %%%%.....
            %% MMC topology evalulation
            %%%%.....
            
            %%%%.....
            %% Number of Submodules
            %%%%.....
            
            [~,NoofSM_total_mtx] = DB_number_of_submodules_Calc(Uph_mtx,U_sm_mtx,Noofarms_per_phase_mtx,NoofPhases_mtx); % Matlab function to calculate the number of submodules
            
            %%%%......
            %% Battery Capacity and number of parallel cells per submodule
            %%%%......
            
            [BatteryCapacity_per_submodule,NoofParallel_cells] = DB_battery_capcity_No_of_parallel_cells(Params.Ebatt,Params.Uc_n,Params.DoD,NoofCells_mtx,NoofSM_total_mtx,Params.Bcap);
            
            %%%%...........................................................
            %% DC charging
            %%%%...........................................................
            
            U_dc_nom_mtx = NoofSM_total_mtx./NoofPhases_mtx.*NoofCells_mtx*Params.Uc_n; % nominal DC voltage
            U_dc_min_mtx = NoofSM_total_mtx./NoofPhases_mtx.*NoofCells_mtx*Params.Uc_min; % Minimum DC voltage
            U_dc_max_mtx = NoofSM_total_mtx./NoofPhases_mtx.*NoofCells_mtx*Params.Uc_max; % Maximum DC voltage
            
            % maximum charging current 
            Iarm_chrg_max = Params.Pdc_chrg./Params.Udc_chrg./NoofPhases_mtx; % DC arm charging current

            % Removing the SS and SD topologies from the DC-side charging analysis 
            U_dc_nom_mtx(Topologies_mtx == 3 | Topologies_mtx == 4 | Topologies_mtx == 5) = 0;
            U_dc_min_mtx(Topologies_mtx == 3 | Topologies_mtx == 4 | Topologies_mtx == 5) = 0;
            U_dc_max_mtx(Topologies_mtx == 3 | Topologies_mtx == 4 | Topologies_mtx == 5) = 0;
            Iarm_chrg_max(Topologies_mtx == 3 | Topologies_mtx == 4 | Topologies_mtx == 5) = 0;
            
            %%..... 
            %% Capacitor calculations
            %%.....
            
            [Cap_C_mtx,Cap_ESR_mtx,Cap_Ipk_mtx,Cap_Pmax_unit,NcapC_mtx,Acap_mtx,Zeta_mmc_mtx] = DB_capacitor_calc(Params,NoofSM_total_mtx,U_s_sw_mtx,x_param,NoofCells_mtx,NoofParallel_cells,Topologies,NoofCells,NoofPhases);
            
            
            %%.....
            %% Battery and DC-side currents calculation
            %%.....
            
            [Z_batt_mtx,Z_cap_mtx,E_cap_tot_mtx,Ibatt_avg_mtx,Ibatt_max_mtx,Icap_avg_mtx,Icap_max_mtx] = DB_dc_side_and_battery_currents_calculation(Params,t,idc_avg_t_mtx,idc_max_t_mtx,Topologies,NoofCells,NoofPhases,NoofParallel_cells,Cap_ESR_mtx,Cap_C_mtx,U_s_sw_mtx,NoofSM_total_mtx);
            
            %%.....
            %% Battery and capacitor loss calculation
            %%.....
            
            [Pbatt_avg_tot_mtx,Pbatt_max_mtx,Pcap_avg_tot_mtx,Pcap_max_mtx,NcapI_mtx,Cap_Ipk_rating_fact,NcapP_mtx,Cap_Ppk_rating_fact,Pbatt_max_tot_mtx,Pcap_max_tot_mtx] = DB_battery_and_capacitor_losses(Params,Ibatt_avg_mtx,Ibatt_max_mtx,Icap_avg_mtx,Icap_max_mtx,Z_batt_mtx,Z_cap_mtx,NcapC_mtx,Cap_Ipk_mtx,NoofSM_total_mtx);
            
            %%.....
            %% Optimization of Number of parallel MOSFETs
            %%.....
            global init_npmos_opti 
            Psc_tot_max_mtx_prv = inf + zeros(size(NoofSM_total_mtx)); % initialization
            init_npmos_opti = 1;
            Minimum_NoofParallel_mosfets_mtx = ones(size(NoofSM_total_mtx))*Params.NPmosMin; % Minimum numner of parallel MOSFETs vector 
            
            % Check if user requested for Npmos optimization
            if Params.OptimEnable_NPmos
                check_mtx = ones(size(NoofSM_total_mtx)) > 0;
            else
                check_mtx = 0;
            end
            
            
            while 1
                %%.....
                %% Number of parallel MOSFETs
                %%.....
                
                [f_didt_calc,t_sw_max_mtx,dUds_mmc_percent_mtx,NoofParallel_mosfets_mtx,NoofParallel_actiVe_mosfets_mtx,Htfr_mtx,Rtheta_ca_mtx,return_var,Minimum_NoofParallel_mosfets_mtx] = DB_number_of_parallel_MOSFETs(Params,f,p_mtx,Cap_ESR_mtx,Cap_C_mtx,I_arm_max_mtx,I_sw_max_sw_mtx,I_sw_av_max_sw_mtx,RdsonMax_mtx,U_s_sw_mtx,Noofswitches_per_sm_mtx,check_mtx,Minimum_NoofParallel_mosfets_mtx);
                
                if Params.DC_chrg_en
                    %% Considering the DC charging case where we consider each submodule is considered as a DC-DC converter with a switching pulse number of 'p_chrg_mtx'. Although all submodule will not be used as DC-DC converters 
                    %% some or one submodules will operate as DC-DC converters (similar to Bo's soultion at SEM). Even though only one or very few submodules will operate as DC-DC converters, theses submodules will have far greater 
                    %% losses then when comptared to the other converters that are operating in dis-continuous (descrete) mode. Therefore, this submodule becomes the limiting factor for the thermal design considerations. 
                    %% The total losses per submodule is defines the case temperature and is independednt of dity cycle. For example:
                    %% Pc_s1 = D*Id*Rdson, & Pc_s2 = (1-D)*Id*Rdson. Therefore, Pc_sm = Id*Rdson; % Same as the case with descrete mode. However, the duty cycle defines how even the losses are destributed between the cells. 
                
                    p_mtx_DC_chrg = p_mtx;
                    p_mtx_DC_chrg(Topologies_mtx == 2) = p_mtx(Topologies_mtx == 2)*2; % swtiching frequncy for FB-SM increased by 2 to maintatin the same harmonic performance.
                
                    [f_didt_calc_DC_chrg,t_sw_max_DC_chrg_mtx,dUds_mmc_percent_DC_chrg_mtx,NoofParallel_mosfets_DC_chrg_mtx,NoofParallel_actiVe_mosfets_DC_chrg_mtx,Htfr_DC_chrg_mtx,Rtheta_ca_DC_chrg_mtx,return_var,Minimum_NoofParallel_DC_chrg_mosfets_mtx] = DB_number_of_parallel_MOSFETs(Params,f,p_mtx_DC_chrg,Cap_ESR_mtx,Cap_C_mtx,Iarm_chrg_max/sqrt(2),Iarm_chrg_max/2,Iarm_chrg_max/sqrt(2),RdsonMax_mtx,U_s_sw_mtx,Noofswitches_per_sm_mtx,check_mtx,Minimum_NoofParallel_mosfets_mtx);
                    
                    NoofParallel_actiVe_mosfets_mtx = max(NoofParallel_actiVe_mosfets_DC_chrg_mtx,NoofParallel_actiVe_mosfets_mtx); % maximum of the two calculations
                    f_didt_calc = max(f_didt_calc,f_didt_calc); % maximum of the two
                    
                    %% Loss calculations for the DC charging case
                    [~,Pmos_max_DC_chrg_mtx,~,Psm_max_DC_chrg_mtx,~,Psc_tot_max_DC_chrg_mtx] = DB_MOSFET_loss_calculation_DC_Charging(Params,U_s_sw_mtx,Iarm_chrg_max,Iarm_chrg_max,f_didt_calc,Iarm_chrg_max/sqrt(2),Iarm_chrg_max/sqrt(2),Rdson_mtx,RdsonMax_mtx,NoofParallel_actiVe_mosfets_mtx,Noofswitches_per_sm_mtx,t_sw_max_mtx,f,p_mtx_DC_chrg,NoofSM_total_mtx,U_dc_min_mtx);
                    [~,Tc_DC_chrg_max_mtx,~,Tj_DC_Chrg_max_mtx] = DB_MOSFET_temperature_calculation(Params,Rtheta_ca_mtx,RTheta_Jc_mtx,Psm_max_DC_chrg_mtx,Psm_max_DC_chrg_mtx,Pmos_max_DC_chrg_mtx,Pmos_max_DC_chrg_mtx);
                end
                
                % Given the upper limit of the number of parallel MOSFETs, theNoofswitches_per_sm_mtx
                % thermal resistance required to ensure that the
                    
                % Mosfet currents
                I_mos_avg_mtx = I_sw_avg_sw_mtx./NoofParallel_actiVe_mosfets_mtx;
                I_mos_max_mtx = I_sw_max_sw_mtx./NoofParallel_actiVe_mosfets_mtx;
                
                %%.....
                %% MOSFET loss calculations
                %%..... 
                
                [Pmos_avg_mtx,Pmos_max_mtx,Psm_avg_mtx,Psm_max_mtx,Psc_tot_avg_mtx,Psc_tot_max_mtx] = DB_MOSFET_loss_calculation(Params,U_s_sw_mtx,I_sw_av_avg_sw_mtx,I_sw_av_max_sw_mtx,f_didt_calc,I_sw_avg_sw_mtx,I_sw_max_sw_mtx,Rdson_mtx,RdsonMax_mtx,NoofParallel_actiVe_mosfets_mtx,Noofswitches_per_sm_mtx,t_sw_max_mtx,f,p_mtx,NoofSM_total_mtx);
                
                check_mtx = Psc_tot_max_mtx < Params.RLRF*Psc_tot_max_mtx_prv | ceil(NoofParallel_actiVe_mosfets_mtx) ~= Minimum_NoofParallel_mosfets_mtx; % check
                Psc_tot_max_mtx_prv = Psc_tot_max_mtx; % update previous results
                %                 check_sum  = sum(check_mtx,'all'); % check sum
                
                if return_var
                    break;
                end
            end
            
            %%.....
            %% Temperature rise
            %%.....
            
            [Tc_avg_mtx,Tc_max_mtx,Tj_avg_mtx,Tj_max_mtx] = DB_MOSFET_temperature_calculation(Params,Rtheta_ca_mtx,RTheta_Jc_mtx,Psm_avg_mtx,Psm_max_mtx,Pmos_avg_mtx,Pmos_max_mtx);
            
            %%.....
            %% Efficiency calculations
            %%.....
            
            %             eff_battcap_avg_mtx = Params.Pout./(Params.Pout + Pbatt_avg_tot_mtx + Pcap_avg_tot_mtx); % Total semiconductor efficiency average
            %             eff_sc_avg_mtx = Params.Pout./(Params.Pout + Psc_tot_avg_mtx); % Total semiconductor efficiency average
            %             eff_tot_avg_mtx = Params.Pout./(Params.Pout + Psc_tot_avg_mtx + Pbatt_avg_tot_mtx + Pcap_avg_tot_mtx); % Total system efficiency average
            %
            %             eff_battcap_max_mtx = Params.Pout*Params.ocf./(Params.Pout*Params.ocf + Pbatt_max_tot_mtx + Pcap_max_tot_mtx); % Total semiconductor efficiency average
            %             eff_sc_max_mtx = Params.Pout*Params.ocf./(Params.Pout*Params.ocf + Psc_tot_max_mtx); % Total semiconductor efficiency average
            %             eff_tot_max_mtx = Params.Pout*Params.ocf./(Params.Pout*Params.ocf + Psc_tot_max_mtx + Pbatt_max_tot_mtx + Pcap_max_tot_mtx); % Total system efficiency average
            %
            %             Ploss_tot_avg_mtx = Psc_tot_avg_mtx + Pbatt_avg_tot_mtx + Pcap_avg_tot_mtx; % total losses as aveage power
            %             Ploss_tot_max_mtx = Psc_tot_max_mtx + Pbatt_max_tot_mtx + Pcap_max_tot_mtx; % total losses as maximum power
            
            [Ploss_tot_avg_mtx,Ploss_tot_max_mtx,eff_tot_avg_mtx,eff_tot_max_mtx] = DB_totallosses_efficiency_calculation(Params,Pbatt_avg_tot_mtx,Pbatt_max_tot_mtx,Pcap_avg_tot_mtx,Pcap_max_tot_mtx,Psc_tot_avg_mtx,Psc_tot_max_mtx);
            
            %%%%...........................................................
            %% Cost evalulation
            %%%%...........................................................
            %             lambda_MOSFET_mtx = k_sc_mtx.*NoofParallel_actiVe_mosfets_mtx.*Noofswitches_per_sm_mtx.*NoofSM_total_mtx; % total cost of MOSFETs for MMC vewctor
            %             lambda_SubmoduleCost_mtx = Params.ksm.*NoofSM_total_mtx; % total fized cost per submodule vector
            %             lambda_converter_mtx = cat(4,lambda_MOSFET_mtx,lambda_SubmoduleCost_mtx); % total cost of the converter
            %
            %             lambda_CapCost_mtx = Params.Kcap.*E_cap_tot_mtx; % total cost of the energy storage elements vector
            %
            %             lambda_BatteryCost_mtx = Params.Kbatt.*Params.Ebatt.*ones(size(NoofCells_mtx)); % total cost of the battery vector
            %
            %             lambda_TotalConverterCost_mtx = cat(4,lambda_converter_mtx,lambda_CapCost_mtx); % total cost vector
            %             lambda_converter_normalized = lambda_TotalConverterCost_mtx./lambda_conv_2l_mtx; % normalized coneverter costs
            %
            %             lambda_TotalCost_mtx = cat(4,lambda_BatteryCost_mtx,lambda_converter_mtx,lambda_CapCost_mtx); % total cost vector
            %
            %             lambda_TotalCost_2l_mtx =  lambda_BatteryCost_mtx + lambda_conv_2l_mtx; % total cost of 2-level inverter vecotr
            %
            %             lambda_TotalCost_normalized = lambda_TotalCost_mtx./lambda_TotalCost_2l_mtx; % normalized coneverter costs
            
            %             [lambda_MOSFET_mtx,lambda_SubmoduleCost_mtx,lambda_TotalConverterCost_mtx,lambda_CapCost_mtx,lambda_BatteryCost_mtx,lambda_converter_normalized,lambda_TotalCost_mtx,lambda_TotalCost_2l_mtx,lambda_TotalCost_normalized] = DB_totalcosts_calculation(Params,k_sc_mtx,NoofParallel_actiVe_mosfets_mtx,Noofswitches_per_sm_mtx,NoofSM_total_mtx,E_cap_tot_mtx,NoofCells_mtx,lambda_conv_2l_mtx);
            [lambda_MOSFET_mtx,lambda_SubmoduleCost_mtx,lambda_TotalConverterCost_mtx,lambda_CapCost_mtx,lambda_BatteryCost_mtx,lambda_converter_normalized,lambda_TotalCost_mtx,lambda_TotalCost_2l_mtx,lambda_TotalCost_normalized] = DB_totalcosts_calculation(Params,k_sc_mtx,NoofParallel_actiVe_mosfets_mtx,Noofswitches_per_sm_mtx,NoofSM_total_mtx,NcapC_mtx,NoofCells_mtx,lambda_conv_2l_mtx);
            
            
            % Plots Number of submodules
            % 3 phase system
            surf(app.Nsm_plot_3phase,squeeze(NoofSM_total_mtx(:,:,find(NoofPhases == 1))))
            app.Nsm_plot_3phase.YTick = 1:length(Topologies);
            app.Nsm_plot_3phase.XTick = 1:length(NoofCells);
            app.Nsm_plot_3phase.YTickLabel = Topologies_Name;
            app.Nsm_plot_3phase.XTickLabel = NoofCells_Name;
            app.Nsm_plot_3phase.XLabel.String = "Number of cells per SM";
            app.Nsm_plot_3phase.YLabel.String = "Topologies";
            app.Nsm_plot_3phase.ZLabel.String = "N_{sm}";
            
            % 6 phase LV system
            surf(app.Nsm_plot_6phase_LV,squeeze(NoofSM_total_mtx(:,:,find(NoofPhases == 2))))
            app.Nsm_plot_6phase_LV.YTick = 1:length(Topologies);
            app.Nsm_plot_6phase_LV.XTick = 1:length(NoofCells);
            app.Nsm_plot_6phase_LV.YTickLabel = Topologies_Name;
            app.Nsm_plot_6phase_LV.XTickLabel = NoofCells_Name;
            app.Nsm_plot_6phase_LV.XLabel.String = "Number of cells per SM";
            app.Nsm_plot_6phase_LV.YLabel.String = "Topologies";
            app.Nsm_plot_6phase_LV.ZLabel.String = "N_{sm}";
            
            % 6 phase HV system
            surf(app.Nsm_plot_6phase_HV,squeeze(NoofSM_total_mtx(:,:,find(NoofPhases == 3))))
            app.Nsm_plot_6phase_HV.YTick = 1:length(Topologies);
            app.Nsm_plot_6phase_HV.XTick = 1:length(NoofCells);
            app.Nsm_plot_6phase_HV.YTickLabel = Topologies_Name;
            app.Nsm_plot_6phase_HV.XTickLabel = NoofCells_Name;
            app.Nsm_plot_6phase_HV.XLabel.String = "Number of cells per SM";
            app.Nsm_plot_6phase_HV.YLabel.String = "Topologies";
            app.Nsm_plot_6phase_HV.ZLabel.String = "N_{sm}";
            
            
            % Battery current plots
            % 3-phase
            surf(app.Ibatt_plot_3phase,squeeze(Cap_C_mtx(:,:,find(NoofPhases == 1)))/1e-3)
            app.Ibatt_plot_3phase.YTick = 1:length(Topologies);
            app.Ibatt_plot_3phase.XTick = 1:length(NoofCells);
            app.Ibatt_plot_3phase.YTickLabel = Topologies_Name;
            app.Ibatt_plot_3phase.XTickLabel = NoofCells_Name;
            app.Ibatt_plot_3phase.XLabel.String = "Number of cells per SM";
            app.Ibatt_plot_3phase.YLabel.String = "Topologies";
            app.Ibatt_plot_3phase.ZLabel.String = "C_{cap(sm)} [mF]";
            
            % 6-phase LV
            surf(app.Ibatt_plot_6phase_LV,squeeze(Cap_C_mtx(:,:,find(NoofPhases == 2)))/1e-3)
            app.Ibatt_plot_6phase_LV.YTick = 1:length(Topologies);
            app.Ibatt_plot_6phase_LV.XTick = 1:length(NoofCells);
            app.Ibatt_plot_6phase_LV.YTickLabel = Topologies_Name;
            app.Ibatt_plot_6phase_LV.XTickLabel = NoofCells_Name;
            app.Ibatt_plot_6phase_LV.XLabel.String = "Number of cells per SM";
            app.Ibatt_plot_6phase_LV.YLabel.String = "Topologies";
            app.Ibatt_plot_6phase_LV.ZLabel.String = "C_{cap(sm)} [mF]";
            
            % 6-phase HV Ibatt_plot_6phase_HV
            surf(app.Ibatt_plot_6phase_HV,squeeze(Cap_C_mtx(:,:,find(NoofPhases == 3)))/1e-3)
            app.Ibatt_plot_6phase_HV.YTick = 1:length(Topologies);
            app.Ibatt_plot_6phase_HV.XTick = 1:length(NoofCells);
            app.Ibatt_plot_6phase_HV.YTickLabel = Topologies_Name;
            app.Ibatt_plot_6phase_HV.XTickLabel = NoofCells_Name;
            app.Ibatt_plot_6phase_HV.XLabel.String = "Number of cells per SM";
            app.Ibatt_plot_6phase_HV.YLabel.String = "Topologies";
            app.Ibatt_plot_6phase_HV.ZLabel.String = "C_{cap(sm)} [mF]";
            
            
            % Capacitor Capacitance plot
            % 3-phase plots
            cla(app.Cap_plot_3phase);
            surf(app.Cap_plot_3phase,squeeze(E_cap_tot_mtx(:,:,find(NoofPhases == 1))))
            hold(app.Cap_plot_3phase,'on');
            mesh(app.Cap_plot_3phase,squeeze(Ecap_2l_mtx(:,:,find(NoofPhases == 1))),'FaceAlpha','0.3','EdgeColor','k')
            app.Cap_plot_3phase.YTick = 1:length(Topologies);
            app.Cap_plot_3phase.XTick = 1:length(NoofCells);
            app.Cap_plot_3phase.YTickLabel = Topologies_Name;
            app.Cap_plot_3phase.XTickLabel = NoofCells_Name;
            app.Cap_plot_3phase.XLabel.String = "Number of cells per SM";
            app.Cap_plot_3phase.YLabel.String = "Topologies";
            app.Cap_plot_3phase.ZLabel.String = "E_{cap} [J]";
            
            % 6-phase LV plots
            cla(app.Cap_plot_6phase_LV);
            surf(app.Cap_plot_6phase_LV,squeeze(E_cap_tot_mtx(:,:,find(NoofPhases == 2))))
            hold(app.Cap_plot_6phase_LV,'on');
            mesh(app.Cap_plot_6phase_LV,squeeze(Ecap_2l_mtx(:,:,find(NoofPhases == 2))),'FaceAlpha','0.3','EdgeColor','k')
            app.Cap_plot_6phase_LV.YTick = 1:length(Topologies);
            app.Cap_plot_6phase_LV.XTick = 1:length(NoofCells);
            app.Cap_plot_6phase_LV.YTickLabel = Topologies_Name;
            app.Cap_plot_6phase_LV.XTickLabel = NoofCells_Name;
            app.Cap_plot_6phase_LV.XLabel.String = "Number of cells per SM";
            app.Cap_plot_6phase_LV.YLabel.String = "Topologies";
            app.Cap_plot_6phase_LV.ZLabel.String = "E_{cap} [J]";
            

            % 6-phase HV plots
            cla(app.Cap_plot_6phase_HV);
            surf(app.Cap_plot_6phase_HV,squeeze(E_cap_tot_mtx(:,:,find(NoofPhases == 3))))
            hold(app.Cap_plot_6phase_HV,'on');
            mesh(app.Cap_plot_6phase_HV,squeeze(Ecap_2l_mtx(:,:,find(NoofPhases == 3))),'FaceAlpha','0.3','EdgeColor','k')
            app.Cap_plot_6phase_HV.YTick = 1:length(Topologies);
            app.Cap_plot_6phase_HV.XTick = 1:length(NoofCells);
            app.Cap_plot_6phase_HV.YTickLabel = Topologies_Name;
            app.Cap_plot_6phase_HV.XTickLabel = NoofCells_Name;
            app.Cap_plot_6phase_HV.XLabel.String = "Number of cells per SM";
            app.Cap_plot_6phase_HV.YLabel.String = "Topologies";
            app.Cap_plot_6phase_HV.ZLabel.String = "E_{cap} [J]";
            
            
            % Plots, capacity per submodule
            % 3 phase system
            surf(app.Bcap_sm_plot_3phase,squeeze(BatteryCapacity_per_submodule(:,:,find(NoofPhases == 1))))
            app.Bcap_sm_plot_3phase.YTick = 1:length(Topologies);
            app.Bcap_sm_plot_3phase.XTick = 1:length(NoofCells);
            app.Bcap_sm_plot_3phase.YTickLabel = Topologies_Name;
            app.Bcap_sm_plot_3phase.XTickLabel = NoofCells_Name;
            app.Bcap_sm_plot_3phase.XLabel.String = "Number of cells per SM";
            app.Bcap_sm_plot_3phase.YLabel.String = "Topologies";
            app.Bcap_sm_plot_3phase.ZLabel.String = "B_{cap(sm)} [Ah]";
            
            % 6 phase LV system
            surf(app.Bcap_sm_plot_6phase_LV,squeeze(BatteryCapacity_per_submodule(:,:,find(NoofPhases == 2))))
            app.Bcap_sm_plot_6phase_LV.YTick = 1:length(Topologies);
            app.Bcap_sm_plot_6phase_LV.XTick = 1:length(NoofCells);
            app.Bcap_sm_plot_6phase_LV.YTickLabel = Topologies_Name;
            app.Bcap_sm_plot_6phase_LV.XTickLabel = NoofCells_Name;
            app.Bcap_sm_plot_6phase_LV.XLabel.String = "Number of cells per SM";
            app.Bcap_sm_plot_6phase_LV.YLabel.String = "Topologies";
            app.Bcap_sm_plot_6phase_LV.ZLabel.String = "B_{cap(sm)} [Ah]";
            
            % 6 phase HV system
            surf(app.Bcap_sm_plot_6phase_HV,squeeze(BatteryCapacity_per_submodule(:,:,find(NoofPhases == 3))))
            app.Bcap_sm_plot_6phase_HV.YTick = 1:length(Topologies);
            app.Bcap_sm_plot_6phase_HV.XTick = 1:length(NoofCells);
            app.Bcap_sm_plot_6phase_HV.YTickLabel = Topologies_Name;
            app.Bcap_sm_plot_6phase_HV.XTickLabel = NoofCells_Name;
            app.Bcap_sm_plot_6phase_HV.XLabel.String = "Number of cells per SM";
            app.Bcap_sm_plot_6phase_HV.YLabel.String = "Topologies";
            app.Bcap_sm_plot_6phase_HV.ZLabel.String = "B_{cap(sm)} [Ah]";
            
            % Number of parellel cells plots
            % 3-phase plots
            surf(app.Npcells_plot_3phase,squeeze(NoofParallel_cells(:,:,find(NoofPhases == 1))))
            app.Npcells_plot_3phase.YTick = 1:length(Topologies);
            app.Npcells_plot_3phase.XTick = 1:length(NoofCells);
            app.Npcells_plot_3phase.YTickLabel = Topologies_Name;
            app.Npcells_plot_3phase.XTickLabel = NoofCells_Name;
            app.Npcells_plot_3phase.XLabel.String = "Number of cells per SM";
            app.Npcells_plot_3phase.YLabel.String = "Topologies";
            app.Npcells_plot_3phase.ZLabel.String = "N_{p,cells}";
            
            % 6-phase LV plots
            surf(app.Npcells_plot_6phase_LV,squeeze(NoofParallel_cells(:,:,find(NoofPhases == 2))))
            app.Npcells_plot_6phase_LV.YTick = 1:length(Topologies);
            app.Npcells_plot_6phase_LV.XTick = 1:length(NoofCells);
            app.Npcells_plot_6phase_LV.YTickLabel = Topologies_Name;
            app.Npcells_plot_6phase_LV.XTickLabel = NoofCells_Name;
            app.Npcells_plot_6phase_LV.XLabel.String = "Number of cells per SM";
            app.Npcells_plot_6phase_LV.YLabel.String = "Topologies";
            app.Npcells_plot_6phase_LV.ZLabel.String = "N_{p,cells}";
            
            % 6-phase HV plots
            surf(app.Npcells_plot_6phase_HV,squeeze(NoofParallel_cells(:,:,find(NoofPhases == 3))))
            app.Npcells_plot_6phase_HV.YTick = 1:length(Topologies);
            app.Npcells_plot_6phase_HV.XTick = 1:length(NoofCells);
            app.Npcells_plot_6phase_HV.YTickLabel = Topologies_Name;
            app.Npcells_plot_6phase_HV.XTickLabel = NoofCells_Name;
            app.Npcells_plot_6phase_HV.XLabel.String = "Number of cells per SM";
            app.Npcells_plot_6phase_HV.YLabel.String = "Topologies";
            app.Npcells_plot_6phase_HV.ZLabel.String = "N_{p,cells}";
            
            % Battery losses plot
            % 3-phase plots
            surf(app.Pbatt_plot_3phase,squeeze(Pbatt_max_mtx(:,:,find(NoofPhases == 1))))
            app.Pbatt_plot_3phase.YTick = 1:length(Topologies);
            app.Pbatt_plot_3phase.XTick = 1:length(NoofCells);
            app.Pbatt_plot_3phase.YTickLabel = Topologies_Name;
            app.Pbatt_plot_3phase.XTickLabel = NoofCells_Name;
            app.Pbatt_plot_3phase.XLabel.String = "Number of cells per SM";
            app.Pbatt_plot_3phase.YLabel.String = "Topologies";
            app.Pbatt_plot_3phase.ZLabel.String = "P_{batt(sm)} [W]";
            
            % 6-phase LV plots
            surf(app.Pbatt_plot_6phase_LV,squeeze(Pbatt_max_mtx(:,:,find(NoofPhases == 2))))
            app.Pbatt_plot_6phase_LV.YTick = 1:length(Topologies);
            app.Pbatt_plot_6phase_LV.XTick = 1:length(NoofCells);
            app.Pbatt_plot_6phase_LV.YTickLabel = Topologies_Name;
            app.Pbatt_plot_6phase_LV.XTickLabel = NoofCells_Name;
            app.Pbatt_plot_6phase_LV.XLabel.String = "Number of cells per SM";
            app.Pbatt_plot_6phase_LV.YLabel.String = "Topologies";
            app.Pbatt_plot_6phase_LV.ZLabel.String = "P_{batt(sm)} [W]";
            
            % 6-phase HV plots
            surf(app.Pbatt_plot_6phase_HV,squeeze(Pbatt_max_mtx(:,:,find(NoofPhases == 3))))
            app.Pbatt_plot_6phase_HV.YTick = 1:length(Topologies);
            app.Pbatt_plot_6phase_HV.XTick = 1:length(NoofCells);
            app.Pbatt_plot_6phase_HV.YTickLabel = Topologies_Name;
            app.Pbatt_plot_6phase_HV.XTickLabel = NoofCells_Name;
            app.Pbatt_plot_6phase_HV.XLabel.String = "Number of cells per SM";
            app.Pbatt_plot_6phase_HV.YLabel.String = "Topologies";
            app.Pbatt_plot_6phase_HV.ZLabel.String = "P_{batt(sm)} [W]";
            
            % Total Battery losses plot
            % 3-phase plots
            cla(app.Pbatt_tot_plot_3phase)
            surf(app.Pbatt_tot_plot_3phase,squeeze(Pbatt_max_tot_mtx(:,:,find(NoofPhases == 1)))/1e3)
            hold(app.Pbatt_tot_plot_3phase,'on')
            mesh(app.Pbatt_tot_plot_3phase,squeeze(Pbatt_2l_max_mtx(:,:,find(NoofPhases == 1)))/1e3,'FaceAlpha','0.3','EdgeColor','k')
            app.Pbatt_tot_plot_3phase.YTick = 1:length(Topologies);
            app.Pbatt_tot_plot_3phase.XTick = 1:length(NoofCells);
            app.Pbatt_tot_plot_3phase.YTickLabel = Topologies_Name;
            app.Pbatt_tot_plot_3phase.XTickLabel = NoofCells_Name;
            app.Pbatt_tot_plot_3phase.XLabel.String = "Number of cells per SM";
            app.Pbatt_tot_plot_3phase.YLabel.String = "Topologies";
            app.Pbatt_tot_plot_3phase.ZLabel.String = "P_{batt(tot)} [kW]";
            
            % 6-phase LV plots
            cla(app.Pbatt_tot_plot_6phase_LV)
            surf(app.Pbatt_tot_plot_6phase_LV,squeeze(Pbatt_max_tot_mtx(:,:,find(NoofPhases == 2)))/1e3)
            hold(app.Pbatt_tot_plot_6phase_LV,'on')
            mesh(app.Pbatt_tot_plot_6phase_LV,squeeze(Pbatt_2l_max_mtx(:,:,find(NoofPhases == 2)))/1e3,'FaceAlpha','0.3','EdgeColor','k')
            app.Pbatt_tot_plot_6phase_LV.YTick = 1:length(Topologies);
            app.Pbatt_tot_plot_6phase_LV.XTick = 1:length(NoofCells);
            app.Pbatt_tot_plot_6phase_LV.YTickLabel = Topologies_Name;
            app.Pbatt_tot_plot_6phase_LV.XTickLabel = NoofCells_Name;
            app.Pbatt_tot_plot_6phase_LV.XLabel.String = "Number of cells per SM";
            app.Pbatt_tot_plot_6phase_LV.YLabel.String = "Topologies";
            app.Pbatt_tot_plot_6phase_LV.ZLabel.String = "P_{batt(tot)} [kW]";
            
            % 6-phase HV plots
            cla(app.Pbatt_tot_plot_6phase_HV)
            surf(app.Pbatt_tot_plot_6phase_HV,squeeze(Pbatt_max_tot_mtx(:,:,find(NoofPhases == 3)))/1e3)
            hold(app.Pbatt_tot_plot_6phase_HV,'on')
            mesh(app.Pbatt_tot_plot_6phase_HV,squeeze(Pbatt_2l_max_mtx(:,:,find(NoofPhases == 3)))/1e3,'FaceAlpha','0.3','EdgeColor','k')
            app.Pbatt_tot_plot_6phase_HV.YTick = 1:length(Topologies);
            app.Pbatt_tot_plot_6phase_HV.XTick = 1:length(NoofCells);
            app.Pbatt_tot_plot_6phase_HV.YTickLabel = Topologies_Name;
            app.Pbatt_tot_plot_6phase_HV.XTickLabel = NoofCells_Name;
            app.Pbatt_tot_plot_6phase_HV.XLabel.String = "Number of cells per SM";
            app.Pbatt_tot_plot_6phase_HV.YLabel.String = "Topologies";
            app.Pbatt_tot_plot_6phase_HV.ZLabel.String = "P_{batt(tot)} [kW]";
            
            % Capacitor losses plot
            % 3-phase plots
            surf(app.Pcap_plot_3phase,squeeze(Pcap_max_mtx(:,:,find(NoofPhases == 1))))
            app.Pcap_plot_3phase.YTick = 1:length(Topologies);
            app.Pcap_plot_3phase.XTick = 1:length(NoofCells);
            app.Pcap_plot_3phase.YTickLabel = Topologies_Name;
            app.Pcap_plot_3phase.XTickLabel = NoofCells_Name;
            app.Pcap_plot_3phase.XLabel.String = "Number of cells per SM";
            app.Pcap_plot_3phase.YLabel.String = "Topologies";
            app.Pcap_plot_3phase.ZLabel.String = "P_{cap(sm)} [W]";
            
            % 6-phase LV plots
            surf(app.Pcap_plot_6phase_LV,squeeze(Pcap_max_mtx(:,:,find(NoofPhases == 2))))
            app.Pcap_plot_6phase_LV.YTick = 1:length(Topologies);
            app.Pcap_plot_6phase_LV.XTick = 1:length(NoofCells);
            app.Pcap_plot_6phase_LV.YTickLabel = Topologies_Name;
            app.Pcap_plot_6phase_LV.XTickLabel = NoofCells_Name;
            app.Pcap_plot_6phase_LV.XLabel.String = "Number of cells per SM";
            app.Pcap_plot_6phase_LV.YLabel.String = "Topologies";
            app.Pcap_plot_6phase_LV.ZLabel.String = "P_{cap(sm)} [W]";
            
            % 6-phase HV plots
            surf(app.Pcap_plot_6phase_HV,squeeze(Pcap_max_mtx(:,:,find(NoofPhases == 3))))
            app.Pcap_plot_6phase_HV.YTick = 1:length(Topologies);
            app.Pcap_plot_6phase_HV.XTick = 1:length(NoofCells);
            app.Pcap_plot_6phase_HV.YTickLabel = Topologies_Name;
            app.Pcap_plot_6phase_HV.XTickLabel = NoofCells_Name;
            app.Pcap_plot_6phase_HV.XLabel.String = "Number of cells per SM";
            app.Pcap_plot_6phase_HV.YLabel.String = "Topologies";
            app.Pcap_plot_6phase_HV.ZLabel.String = "P_{cap(sm)} [W]";
            
            % Total Capacitor losses plot
            % 3-phase plots
            cla(app.Pcap_tot_plot_3phase)
            surf(app.Pcap_tot_plot_3phase,squeeze(Pcap_max_tot_mtx(:,:,find(NoofPhases == 1)))/1e3)
            hold(app.Pcap_tot_plot_3phase,'on')
            mesh(app.Pcap_tot_plot_3phase,squeeze(Pcap_2l_max_mtx(:,:,find(NoofPhases == 1)))/1e3,'FaceAlpha','0.3','EdgeColor','k')
            app.Pcap_tot_plot_3phase.YTick = 1:length(Topologies);
            app.Pcap_tot_plot_3phase.XTick = 1:length(NoofCells);
            app.Pcap_tot_plot_3phase.YTickLabel = Topologies_Name;
            app.Pcap_tot_plot_3phase.XTickLabel = NoofCells_Name;
            app.Pcap_tot_plot_3phase.XLabel.String = "Number of cells per SM";
            app.Pcap_tot_plot_3phase.YLabel.String = "Topologies";
            app.Pcap_tot_plot_3phase.ZLabel.String = "P_{cap(tot)} [kW]";
            
            % 6-phase LV plots
            cla(app.Pcap_tot_plot_6phase_LV)
            surf(app.Pcap_tot_plot_6phase_LV,squeeze(Pcap_max_tot_mtx(:,:,find(NoofPhases == 2)))/1e3)
            hold(app.Pcap_tot_plot_6phase_LV,'on')
            mesh(app.Pcap_tot_plot_6phase_LV,squeeze(Pcap_2l_max_mtx(:,:,find(NoofPhases == 2)))/1e3,'FaceAlpha','0.3','EdgeColor','k')
            app.Pcap_tot_plot_6phase_LV.YTick = 1:length(Topologies);
            app.Pcap_tot_plot_6phase_LV.XTick = 1:length(NoofCells);
            app.Pcap_tot_plot_6phase_LV.YTickLabel = Topologies_Name;
            app.Pcap_tot_plot_6phase_LV.XTickLabel = NoofCells_Name;
            app.Pcap_tot_plot_6phase_LV.XLabel.String = "Number of cells per SM";
            app.Pcap_tot_plot_6phase_LV.YLabel.String = "Topologies";
            app.Pcap_tot_plot_6phase_LV.ZLabel.String = "P_{cap(tot)} [kW]";
            
            % 6-phase HV plots
            cla(app.Pcap_tot_plot_6phase_HV)
            surf(app.Pcap_tot_plot_6phase_HV,squeeze(Pcap_max_tot_mtx(:,:,find(NoofPhases == 3)))/1e3)
            hold(app.Pcap_tot_plot_6phase_HV,'on')
            mesh(app.Pcap_tot_plot_6phase_HV,squeeze(Pcap_2l_max_mtx(:,:,find(NoofPhases == 3)))/1e3,'FaceAlpha','0.3','EdgeColor','k')
            app.Pcap_tot_plot_6phase_HV.YTick = 1:length(Topologies);
            app.Pcap_tot_plot_6phase_HV.XTick = 1:length(NoofCells);
            app.Pcap_tot_plot_6phase_HV.YTickLabel = Topologies_Name;
            app.Pcap_tot_plot_6phase_HV.XTickLabel = NoofCells_Name;
            app.Pcap_tot_plot_6phase_HV.XLabel.String = "Number of cells per SM";
            app.Pcap_tot_plot_6phase_HV.YLabel.String = "Topologies";
            app.Pcap_tot_plot_6phase_HV.ZLabel.String = "P_{cap(tot)} [kW]";
            
            
            % 3-phase plots
            cla(app.Npmos_sm_plot_3phase);
            surf(app.Npmos_sm_plot_3phase,squeeze(NoofParallel_actiVe_mosfets_mtx(:,:,find(NoofPhases == 1))));
            hold(app.Npmos_sm_plot_3phase,'on');
            mesh(app.Npmos_sm_plot_3phase,squeeze(Npmos_2l_mtx(:,:,find(NoofPhases==1))),'FaceAlpha','0.3','EdgeColor','k')
            app.Npmos_sm_plot_3phase.YTick = 1:length(Topologies);
            app.Npmos_sm_plot_3phase.XTick = 1:length(NoofCells);
            app.Npmos_sm_plot_3phase.YTickLabel = Topologies_Name;
            app.Npmos_sm_plot_3phase.XTickLabel = NoofCells_Name;
            app.Npmos_sm_plot_3phase.XLabel.String = "Number of cells per SM";
            app.Npmos_sm_plot_3phase.YLabel.String = "Topologies";
            app.Npmos_sm_plot_3phase.ZLabel.String = "N_{p,mos}";
            
            % 6-phase LV plots
            cla(app.Npmos_sm_plot_6phase_LV);
            surf(app.Npmos_sm_plot_6phase_LV,squeeze(NoofParallel_actiVe_mosfets_mtx(:,:,find(NoofPhases == 2))))
            hold(app.Npmos_sm_plot_6phase_LV,'on');
            mesh(app.Npmos_sm_plot_6phase_LV,squeeze(Npmos_2l_mtx(:,:,find(NoofPhases==2))),'FaceAlpha','0.3','EdgeColor','k')
            app.Npmos_sm_plot_6phase_LV.YTick = 1:length(Topologies);
            app.Npmos_sm_plot_6phase_LV.XTick = 1:length(NoofCells);
            app.Npmos_sm_plot_6phase_LV.YTickLabel = Topologies_Name;
            app.Npmos_sm_plot_6phase_LV.XTickLabel = NoofCells_Name;
            app.Npmos_sm_plot_6phase_LV.XLabel.String = "Number of cells per SM";
            app.Npmos_sm_plot_6phase_LV.YLabel.String = "Topologies";
            app.Npmos_sm_plot_6phase_LV.ZLabel.String = "N_{p,mos}";
            
            % 6-phase HV plots
            cla(app.Npmos_plot_6phase_HV);
            surf(app.Npmos_plot_6phase_HV,squeeze(NoofParallel_actiVe_mosfets_mtx(:,:,find(NoofPhases == 3))))
            hold(app.Npmos_plot_6phase_HV,'on');
            mesh(app.Npmos_plot_6phase_HV,squeeze(Npmos_2l_mtx(:,:,find(NoofPhases==3))),'FaceAlpha','0.3','EdgeColor','k')
            app.Npmos_plot_6phase_HV.YTick = 1:length(Topologies);
            app.Npmos_plot_6phase_HV.XTick = 1:length(NoofCells);
            app.Npmos_plot_6phase_HV.YTickLabel = Topologies_Name;
            app.Npmos_plot_6phase_HV.XTickLabel = NoofCells_Name;
            app.Npmos_plot_6phase_HV.XLabel.String = "Number of cells per SM";
            app.Npmos_plot_6phase_HV.YLabel.String = "Topologies";
            app.Npmos_plot_6phase_HV.ZLabel.String = "N_{p,mos}";
            
            % Thermal resistance between case to ambient
            % 3-phase
            cla(app.Rthata_ca_3phase);
            surf(app.Rthata_ca_3phase,squeeze(p_mtx(:,:,find(NoofPhases == 1))))
            hold(app.Rthata_ca_3phase,'on');
            mesh(app.Rthata_ca_3phase,squeeze(p_2l_mtx(:,:,find(NoofPhases==1))),'FaceAlpha','0.3','EdgeColor','k')
            app.Rthata_ca_3phase.YTick = 1:length(Topologies);
            app.Rthata_ca_3phase.XTick = 1:length(NoofCells);
            app.Rthata_ca_3phase.YTickLabel = Topologies_Name;
            app.Rthata_ca_3phase.XTickLabel = NoofCells_Name;
            app.Rthata_ca_3phase.XLabel.String = "Number of cells per SM";
            app.Rthata_ca_3phase.YLabel.String = "Topologies";
            app.Rthata_ca_3phase.ZLabel.String = "p [-]";
            
            % 6-phase LV
            cla(app.Rthata_ca_6phase_LV);
            surf(app.Rthata_ca_6phase_LV,squeeze(p_mtx(:,:,find(NoofPhases == 2))))
            hold(app.Rthata_ca_6phase_LV,'on');
            mesh(app.Rthata_ca_6phase_LV,squeeze(p_2l_mtx(:,:,find(NoofPhases==2))),'FaceAlpha','0.3','EdgeColor','k')
            app.Rthata_ca_6phase_LV.YTick = 1:length(Topologies);
            app.Rthata_ca_6phase_LV.XTick = 1:length(NoofCells);
            app.Rthata_ca_6phase_LV.YTickLabel = Topologies_Name;
            app.Rthata_ca_6phase_LV.XTickLabel = NoofCells_Name;
            app.Rthata_ca_6phase_LV.XLabel.String = "Number of cells per SM";
            app.Rthata_ca_6phase_LV.YLabel.String = "Topologies";
            app.Rthata_ca_6phase_LV.ZLabel.String = "p [-]";
            
            % 6-phase HV
            cla(app.Rthata_ca_6phase_HV);
            surf(app.Rthata_ca_6phase_HV,squeeze(p_mtx(:,:,find(NoofPhases == 3))))
            hold(app.Rthata_ca_6phase_HV,'on');
            mesh(app.Rthata_ca_6phase_HV,squeeze(p_2l_mtx(:,:,find(NoofPhases==3))),'FaceAlpha','0.3','EdgeColor','k')
            app.Rthata_ca_6phase_HV.YTick = 1:length(Topologies);
            app.Rthata_ca_6phase_HV.XTick = 1:length(NoofCells);
            app.Rthata_ca_6phase_HV.YTickLabel = Topologies_Name;
            app.Rthata_ca_6phase_HV.XTickLabel = NoofCells_Name;
            app.Rthata_ca_6phase_HV.XLabel.String = "Number of cells per SM";
            app.Rthata_ca_6phase_HV.YLabel.String = "Topologies";
            app.Rthata_ca_6phase_HV.ZLabel.String = "p [-]";
            
            % dUds_mmc_percent_mtx
            % 3-phase plots
            cla(app.ESR_plot_3phase)
            surf(app.ESR_plot_3phase,squeeze(dUds_mmc_percent_mtx(:,:,find(NoofPhases == 1))))
            hold(app.ESR_plot_3phase,'on');
            mesh(app.ESR_plot_3phase,squeeze(dUs_ver_2l_percent_mtx(:,:,find(NoofPhases == 1))),'FaceAlpha','0.3','EdgeColor','k')
            app.ESR_plot_3phase.YTick = 1:length(Topologies);
            app.ESR_plot_3phase.XTick = 1:length(NoofCells);
            app.ESR_plot_3phase.YTickLabel = Topologies_Name;
            app.ESR_plot_3phase.XTickLabel = NoofCells_Name;
            app.ESR_plot_3phase.XLabel.String = "Number of cells per SM";
            app.ESR_plot_3phase.YLabel.String = "Topologies";
            app.ESR_plot_3phase.ZLabel.String = "\Delta U_{ds} [%]";
            
            % 6-phase LV plots
            cla(app.ESR_plot_6phase_LV);
            surf(app.ESR_plot_6phase_LV,squeeze(dUds_mmc_percent_mtx(:,:,find(NoofPhases == 2))))
            hold(app.ESR_plot_6phase_LV,'on');
            mesh(app.ESR_plot_6phase_LV,squeeze(dUs_ver_2l_percent_mtx(:,:,find(NoofPhases == 2))),'FaceAlpha','0.3','EdgeColor','k')
            app.ESR_plot_6phase_LV.YTick = 1:length(Topologies);
            app.ESR_plot_6phase_LV.XTick = 1:length(NoofCells);
            app.ESR_plot_6phase_LV.YTickLabel = Topologies_Name;
            app.ESR_plot_6phase_LV.XTickLabel = NoofCells_Name;
            app.ESR_plot_6phase_LV.XLabel.String = "Number of cells per SM";
            app.ESR_plot_6phase_LV.YLabel.String = "Topologies";
            app.ESR_plot_6phase_LV.ZLabel.String = "\Delta U_{ds} [%]";
            
            % 6-phase HV plots
            cla(app.ESR_plot_6phase_HV);
            surf(app.ESR_plot_6phase_HV,squeeze(dUds_mmc_percent_mtx(:,:,find(NoofPhases == 3))))
            hold(app.ESR_plot_6phase_HV,'on');
            mesh(app.ESR_plot_6phase_HV,squeeze(dUs_ver_2l_percent_mtx(:,:,find(NoofPhases == 3))),'FaceAlpha','0.3','EdgeColor','k')
            app.ESR_plot_6phase_HV.YTick = 1:length(Topologies);
            app.ESR_plot_6phase_HV.XTick = 1:length(NoofCells);
            app.ESR_plot_6phase_HV.YTickLabel = Topologies_Name;
            app.ESR_plot_6phase_HV.XTickLabel = NoofCells_Name;
            app.ESR_plot_6phase_HV.XLabel.String = "Number of cells per SM";
            app.ESR_plot_6phase_HV.YLabel.String = "Topologies";
            app.ESR_plot_6phase_HV.ZLabel.String = "\Delta U_{ds} [%]";
            
            %% didt plots
            % 3-phase plots
            cla(app.didt_plot_3phase)
            surf(app.didt_plot_3phase,squeeze(f_didt_calc(:,:,find(NoofPhases == 1)))/1e6)
            %             hold(app.didt_plot_3phase,'on');
            %             mesh(app.didt_plot_3phase,squeeze(dUs_ver_2l_percent_mtx(:,:,find(NoofPhases == 1))),'FaceAlpha','0.3','EdgeColor','k')
            app.didt_plot_3phase.YTick = 1:length(Topologies);
            app.didt_plot_3phase.XTick = 1:length(NoofCells);
            app.didt_plot_3phase.YTickLabel = Topologies_Name;
            app.didt_plot_3phase.XTickLabel = NoofCells_Name;
            app.didt_plot_3phase.XLabel.String = "Number of cells per SM";
            app.didt_plot_3phase.YLabel.String = "Topologies";
            app.didt_plot_3phase.ZLabel.String = "di/dt [A/\mus]";
            
            % 6-phase LV plots
            cla(app.didt_plot_6phase_LV);
            surf(app.didt_plot_6phase_LV,squeeze(f_didt_calc(:,:,find(NoofPhases == 2)))/1e6)
            %             hold(app.didt_plot_6phase_LV,'on');
            %             mesh(app.didt_plot_6phase_LV,squeeze(dUs_ver_2l_percent_mtx(:,:,find(NoofPhases == 2))),'FaceAlpha','0.3','EdgeColor','k')
            app.didt_plot_6phase_LV.YTick = 1:length(Topologies);
            app.didt_plot_6phase_LV.XTick = 1:length(NoofCells);
            app.didt_plot_6phase_LV.YTickLabel = Topologies_Name;
            app.didt_plot_6phase_LV.XTickLabel = NoofCells_Name;
            app.didt_plot_6phase_LV.XLabel.String = "Number of cells per SM";
            app.didt_plot_6phase_LV.YLabel.String = "Topologies";
            app.didt_plot_6phase_LV.ZLabel.String = "di/dt [A/\mus]";
            
            % 6-phase HV plots
            cla(app.didt_plot_6phase_HV);
            surf(app.didt_plot_6phase_HV,squeeze(f_didt_calc(:,:,find(NoofPhases == 3)))/1e6)
            %             hold(app.didt_plot_6phase_HV,'on');
            %             mesh(app.didt_plot_6phase_HV,squeeze(dUs_ver_2l_percent_mtx(:,:,find(NoofPhases == 3))),'FaceAlpha','0.3','EdgeColor','k')
            app.didt_plot_6phase_HV.YTick = 1:length(Topologies);
            app.didt_plot_6phase_HV.XTick = 1:length(NoofCells);
            app.didt_plot_6phase_HV.YTickLabel = Topologies_Name;
            app.didt_plot_6phase_HV.XTickLabel = NoofCells_Name;
            app.didt_plot_6phase_HV.XLabel.String = "Number of cells per SM";
            app.didt_plot_6phase_HV.YLabel.String = "Topologies";
            app.didt_plot_6phase_HV.ZLabel.String = "di/dt [A/\mus]";
            
            %% Zeta plots plots
            % 3-phase plots
            cla(app.Zeta_plot_3phase)
            surf(app.Zeta_plot_3phase,squeeze(Zeta_mmc_mtx(:,:,find(NoofPhases == 1))))
            hold(app.Zeta_plot_3phase,'on');
            mesh(app.Zeta_plot_3phase,squeeze(Zeta_2l_mtx(:,:,find(NoofPhases == 1))),'FaceAlpha','0.3','EdgeColor','k')
            app.Zeta_plot_3phase.YTick = 1:length(Topologies);
            app.Zeta_plot_3phase.XTick = 1:length(NoofCells);
            app.Zeta_plot_3phase.YTickLabel = Topologies_Name;
            app.Zeta_plot_3phase.XTickLabel = NoofCells_Name;
            app.Zeta_plot_3phase.XLabel.String = "Number of cells per SM";
            app.Zeta_plot_3phase.YLabel.String = "Topologies";
            app.Zeta_plot_3phase.ZLabel.String = "\zeta";
            
            % 6-phase LV plots
            cla(app.Zeta_plot_6phase_LV);
            surf(app.Zeta_plot_6phase_LV,squeeze(Zeta_mmc_mtx(:,:,find(NoofPhases == 2))))
            hold(app.Zeta_plot_6phase_LV,'on');
            mesh(app.Zeta_plot_6phase_LV,squeeze(Zeta_2l_mtx(:,:,find(NoofPhases == 2))),'FaceAlpha','0.3','EdgeColor','k')
            app.Zeta_plot_6phase_LV.YTick = 1:length(Topologies);
            app.Zeta_plot_6phase_LV.XTick = 1:length(NoofCells);
            app.Zeta_plot_6phase_LV.YTickLabel = Topologies_Name;
            app.Zeta_plot_6phase_LV.XTickLabel = NoofCells_Name;
            app.Zeta_plot_6phase_LV.XLabel.String = "Number of cells per SM";
            app.Zeta_plot_6phase_LV.YLabel.String = "Topologies";
            app.Zeta_plot_6phase_LV.ZLabel.String = "\zeta";
            
            % 6-phase HV plots
            cla(app.Zeta_plot_6phase_HV);
            surf(app.Zeta_plot_6phase_HV,squeeze(Zeta_mmc_mtx(:,:,find(NoofPhases == 3))))
            hold(app.Zeta_plot_6phase_HV,'on');
            mesh(app.Zeta_plot_6phase_HV,squeeze(Zeta_2l_mtx(:,:,find(NoofPhases == 3))),'FaceAlpha','0.3','EdgeColor','k')
            app.Zeta_plot_6phase_HV.YTick = 1:length(Topologies);
            app.Zeta_plot_6phase_HV.XTick = 1:length(NoofCells);
            app.Zeta_plot_6phase_HV.YTickLabel = Topologies_Name;
            app.Zeta_plot_6phase_HV.XTickLabel = NoofCells_Name;
            app.Zeta_plot_6phase_HV.XLabel.String = "Number of cells per SM";
            app.Zeta_plot_6phase_HV.YLabel.String = "Topologies";
            app.Zeta_plot_6phase_HV.ZLabel.String = "\zeta";
            
            
            % submodule semiconductor losses plot
            % 3-phase plots
            surf(app.Psm_plot_3phase,squeeze(Psm_max_mtx(:,:,find(NoofPhases == 1))))
            app.Psm_plot_3phase.YTick = 1:length(Topologies);
            app.Psm_plot_3phase.XTick = 1:length(NoofCells);
            app.Psm_plot_3phase.YTickLabel = Topologies_Name;
            app.Psm_plot_3phase.XTickLabel = NoofCells_Name;
            app.Psm_plot_3phase.XLabel.String = "Number of cells per SM";
            app.Psm_plot_3phase.YLabel.String = "Topologies";
            app.Psm_plot_3phase.ZLabel.String = "P_{sc(sm)} [W]";
            
            % 6-phase LV plots
            surf(app.Psm_plot_6phase_LV,squeeze(Psm_max_mtx(:,:,find(NoofPhases == 2))))
            app.Psm_plot_6phase_LV.YTick = 1:length(Topologies);
            app.Psm_plot_6phase_LV.XTick = 1:length(NoofCells);
            app.Psm_plot_6phase_LV.YTickLabel = Topologies_Name;
            app.Psm_plot_6phase_LV.XTickLabel = NoofCells_Name;
            app.Psm_plot_6phase_LV.XLabel.String = "Number of cells per SM";
            app.Psm_plot_6phase_LV.YLabel.String = "Topologies";
            app.Psm_plot_6phase_LV.ZLabel.String = "P_{sc(sm)} [W]";
            
            % 6-phase HV plots
            surf(app.Psm_plot_6phase_HV,squeeze(Psm_max_mtx(:,:,find(NoofPhases == 3))))
            app.Psm_plot_6phase_HV.YTick = 1:length(Topologies);
            app.Psm_plot_6phase_HV.XTick = 1:length(NoofCells);
            app.Psm_plot_6phase_HV.YTickLabel = Topologies_Name;
            app.Psm_plot_6phase_HV.XTickLabel = NoofCells_Name;
            app.Psm_plot_6phase_HV.XLabel.String = "Number of cells per SM";
            app.Psm_plot_6phase_HV.YLabel.String = "Topologies";
            app.Psm_plot_6phase_HV.ZLabel.String = "P_{sc(sm)} [W]";
            
            % Total submodule losses
            % 3-phase plots
            cla(app.Psc_tot_plot_3phase)
            surf(app.Psc_tot_plot_3phase,squeeze(Psc_tot_max_mtx(:,:,find(NoofPhases == 1)))/1e3)
            hold(app.Psc_tot_plot_3phase,'on')
            mesh(app.Psc_tot_plot_3phase,squeeze(P_2l_loss_max_mtx(:,:,find(NoofPhases == 1)))/1e3,'FaceAlpha','0.3','EdgeColor','k')
            app.Psc_tot_plot_3phase.YTick = 1:length(Topologies);
            app.Psc_tot_plot_3phase.XTick = 1:length(NoofCells);
            app.Psc_tot_plot_3phase.YTickLabel = Topologies_Name;
            app.Psc_tot_plot_3phase.XTickLabel = NoofCells_Name;
            app.Psc_tot_plot_3phase.XLabel.String = "Number of cells per SM";
            app.Psc_tot_plot_3phase.YLabel.String = "Topologies";
            app.Psc_tot_plot_3phase.ZLabel.String = "P_{sc(tot)} [kW]";
            
            % 6-phase LV plots
            cla(app.Psc_tot_plot_6phase_LV)
            surf(app.Psc_tot_plot_6phase_LV,squeeze(Psc_tot_max_mtx(:,:,find(NoofPhases == 2)))/1e3)
            hold(app.Psc_tot_plot_6phase_LV,'on')
            mesh(app.Psc_tot_plot_6phase_LV,squeeze(P_2l_loss_max_mtx(:,:,find(NoofPhases == 2)))/1e3,'FaceAlpha','0.3','EdgeColor','k')
            app.Psc_tot_plot_6phase_LV.YTick = 1:length(Topologies);
            app.Psc_tot_plot_6phase_LV.XTick = 1:length(NoofCells);
            app.Psc_tot_plot_6phase_LV.YTickLabel = Topologies_Name;
            app.Psc_tot_plot_6phase_LV.XTickLabel = NoofCells_Name;
            app.Psc_tot_plot_6phase_LV.XLabel.String = "Number of cells per SM";
            app.Psc_tot_plot_6phase_LV.YLabel.String = "Topologies";
            app.Psc_tot_plot_6phase_LV.ZLabel.String = "P_{sc(tot)} [kW]";
            
            % 6-phase HV plots
            cla(app.Psc_tot_plot_6phase_HV)
            surf(app.Psc_tot_plot_6phase_HV,squeeze(Psc_tot_max_mtx(:,:,find(NoofPhases == 3)))/1e3)
            hold(app.Psc_tot_plot_6phase_HV,'on')
            mesh(app.Psc_tot_plot_6phase_HV,squeeze(P_2l_loss_max_mtx(:,:,find(NoofPhases == 3)))/1e3,'FaceAlpha','0.3','EdgeColor','k')
            app.Psc_tot_plot_6phase_HV.YTick = 1:length(Topologies);
            app.Psc_tot_plot_6phase_HV.XTick = 1:length(NoofCells);
            app.Psc_tot_plot_6phase_HV.YTickLabel = Topologies_Name;
            app.Psc_tot_plot_6phase_HV.XTickLabel = NoofCells_Name;
            app.Psc_tot_plot_6phase_HV.XLabel.String = "Number of cells per SM";
            app.Psc_tot_plot_6phase_HV.YLabel.String = "Topologies";
            app.Psc_tot_plot_6phase_HV.ZLabel.String = "P_{sc(tot)} [kW]";
            
            
            % Total juction temperature plot
            % 3-phase plots
            cla(app.Tj_plot_3phase)
            surf(app.Tj_plot_3phase,squeeze(Tj_max_mtx(:,:,find(NoofPhases == 1))))
            hold(app.Tj_plot_3phase,'on');
            mesh(app.Tj_plot_3phase,squeeze(Tj_2l_mtx_max(:,:,find(NoofPhases == 1))),'FaceAlpha','0.3','EdgeColor','k')
            app.Tj_plot_3phase.YTick = 1:length(Topologies);
            app.Tj_plot_3phase.XTick = 1:length(NoofCells);
            app.Tj_plot_3phase.YTickLabel = Topologies_Name;
            app.Tj_plot_3phase.XTickLabel = NoofCells_Name;
            app.Tj_plot_3phase.XLabel.String = "Number of cells per SM";
            app.Tj_plot_3phase.YLabel.String = "Topologies";
            app.Tj_plot_3phase.ZLabel.String = "T_{j} [^oC]";
            
            % 6-phase LV plots
            cla(app.Tj_plot_6phase_LV);
            surf(app.Tj_plot_6phase_LV,squeeze(Tj_max_mtx(:,:,find(NoofPhases == 2))))
            hold(app.Tj_plot_6phase_LV,'on');
            mesh(app.Tj_plot_6phase_LV,squeeze(Tj_2l_mtx_max(:,:,find(NoofPhases == 2))),'FaceAlpha','0.3','EdgeColor','k')
            app.Tj_plot_6phase_LV.YTick = 1:length(Topologies);
            app.Tj_plot_6phase_LV.XTick = 1:length(NoofCells);
            app.Tj_plot_6phase_LV.YTickLabel = Topologies_Name;
            app.Tj_plot_6phase_LV.XTickLabel = NoofCells_Name;
            app.Tj_plot_6phase_LV.XLabel.String = "Number of cells per SM";
            app.Tj_plot_6phase_LV.YLabel.String = "Topologies";
            app.Tj_plot_6phase_LV.ZLabel.String = "T_{j} [^oC]";
            
            % 6-phase HV plots
            cla(app.Tj_plot_6phase_HV);
            surf(app.Tj_plot_6phase_HV,squeeze(Tj_max_mtx(:,:,find(NoofPhases == 3))))
            hold(app.Tj_plot_6phase_HV,'on');
            mesh(app.Tj_plot_6phase_HV,squeeze(Tj_2l_mtx_max(:,:,find(NoofPhases == 3))),'FaceAlpha','0.3','EdgeColor','k')
            app.Tj_plot_6phase_HV.YTick = 1:length(Topologies);
            app.Tj_plot_6phase_HV.XTick = 1:length(NoofCells);
            app.Tj_plot_6phase_HV.YTickLabel = Topologies_Name;
            app.Tj_plot_6phase_HV.XTickLabel = NoofCells_Name;
            app.Tj_plot_6phase_HV.XLabel.String = "Number of cells per SM";
            app.Tj_plot_6phase_HV.YLabel.String = "Topologies";
            app.Tj_plot_6phase_HV.ZLabel.String = "T_{j} [^oC]";
            
            % Total case temperature plot
            % 3-phase plots
            cla(app.Tc_plot_3phase);
            surf(app.Tc_plot_3phase,squeeze(Tc_max_mtx(:,:,find(NoofPhases == 1))))
            hold(app.Tc_plot_3phase,'on');
            mesh(app.Tc_plot_3phase,squeeze(Tc_2l_mtx_max(:,:,find(NoofPhases == 1))),'FaceAlpha','0.3','EdgeColor','k')
            app.Tc_plot_3phase.YTick = 1:length(Topologies);
            app.Tc_plot_3phase.XTick = 1:length(NoofCells);
            app.Tc_plot_3phase.YTickLabel = Topologies_Name;
            app.Tc_plot_3phase.XTickLabel = NoofCells_Name;
            app.Tc_plot_3phase.XLabel.String = "Number of cells per SM";
            app.Tc_plot_3phase.YLabel.String = "Topologies";
            app.Tc_plot_3phase.ZLabel.String = "T_{c} [^oC]";
            
            % 6-phase LV plots
            cla(app.Tc_plot_6phase_LV);
            surf(app.Tc_plot_6phase_LV,squeeze(Tc_max_mtx(:,:,find(NoofPhases == 2))))
            hold(app.Tc_plot_6phase_LV,'on');
            mesh(app.Tc_plot_6phase_LV,squeeze(Tc_2l_mtx_max(:,:,find(NoofPhases == 2))),'FaceAlpha','0.3','EdgeColor','k')
            app.Tc_plot_6phase_LV.YTick = 1:length(Topologies);
            app.Tc_plot_6phase_LV.XTick = 1:length(NoofCells);
            app.Tc_plot_6phase_LV.YTickLabel = Topologies_Name;
            app.Tc_plot_6phase_LV.XTickLabel = NoofCells_Name;
            app.Tc_plot_6phase_LV.XLabel.String = "Number of cells per SM";
            app.Tc_plot_6phase_LV.YLabel.String = "Topologies";
            app.Tc_plot_6phase_LV.ZLabel.String = "T_{c} [^oC]";
            
            % 6-phase HV plots
            cla(app.Tc_plot_6phase_HV)
            surf(app.Tc_plot_6phase_HV,squeeze(Tc_max_mtx(:,:,find(NoofPhases == 3))))
            hold(app.Tc_plot_6phase_HV,'on');
            mesh(app.Tc_plot_6phase_HV,squeeze(Tc_2l_mtx_max(:,:,find(NoofPhases == 3))),'FaceAlpha','0.3','EdgeColor','k')
            app.Tc_plot_6phase_HV.YTick = 1:length(Topologies);
            app.Tc_plot_6phase_HV.XTick = 1:length(NoofCells);
            app.Tc_plot_6phase_HV.YTickLabel = Topologies_Name;
            app.Tc_plot_6phase_HV.XTickLabel = NoofCells_Name;
            app.Tc_plot_6phase_HV.XLabel.String = "Number of cells per SM";
            app.Tc_plot_6phase_HV.YLabel.String = "Topologies";
            app.Tc_plot_6phase_HV.ZLabel.String = "T_{c} [^oC]";
            
            
            % Total losses at average power plot
            % 3-phase plots
            cla(app.Ploss_avg_plot_3phase)
            surf(app.Ploss_avg_plot_3phase,squeeze(Ploss_tot_avg_mtx(:,:,find(NoofPhases == 1))))
            hold(app.Ploss_avg_plot_3phase,'on');
            mesh(app.Ploss_avg_plot_3phase,squeeze(Ploss_tot_2l_avg(:,:,find(NoofPhases == 1))),'FaceAlpha','0.3','EdgeColor','k')
            app.Ploss_avg_plot_3phase.YTick = 1:length(Topologies);
            app.Ploss_avg_plot_3phase.XTick = 1:length(NoofCells);
            app.Ploss_avg_plot_3phase.YTickLabel = Topologies_Name;
            app.Ploss_avg_plot_3phase.XTickLabel = NoofCells_Name;
            app.Ploss_avg_plot_3phase.XLabel.String = "Number of cells per SM";
            app.Ploss_avg_plot_3phase.YLabel.String = "Topologies";
            app.Ploss_avg_plot_3phase.ZLabel.String = "P_{tot} [W]";
            
            % 6-phase LV plots
            cla(app.Ploss_avg_plot_6phase_LV)
            surf(app.Ploss_avg_plot_6phase_LV,squeeze(Ploss_tot_avg_mtx(:,:,find(NoofPhases == 2))))
            hold(app.Ploss_avg_plot_6phase_LV,'on');
            mesh(app.Ploss_avg_plot_6phase_LV,squeeze(Ploss_tot_2l_avg(:,:,find(NoofPhases == 2))),'FaceAlpha','0.3','EdgeColor','k')
            app.Ploss_avg_plot_6phase_LV.YTick = 1:length(Topologies);
            app.Ploss_avg_plot_6phase_LV.XTick = 1:length(NoofCells);
            app.Ploss_avg_plot_6phase_LV.YTickLabel = Topologies_Name;
            app.Ploss_avg_plot_6phase_LV.XTickLabel = NoofCells_Name;
            app.Ploss_avg_plot_6phase_LV.XLabel.String = "Number of cells per SM";
            app.Ploss_avg_plot_6phase_LV.YLabel.String = "Topologies";
            app.Ploss_avg_plot_6phase_LV.ZLabel.String = "P_{tot} [W]";
            
            % 6-phase HV plots
            cla(app.Ploss_avg_plot_6phase_HV)
            surf(app.Ploss_avg_plot_6phase_HV,squeeze(Ploss_tot_avg_mtx(:,:,find(NoofPhases == 3))))
            hold(app.Ploss_avg_plot_6phase_HV,'on');
            mesh(app.Ploss_avg_plot_6phase_HV,squeeze(Ploss_tot_2l_avg(:,:,find(NoofPhases == 3))),'FaceAlpha','0.3','EdgeColor','k')
            app.Ploss_avg_plot_6phase_HV.YTick = 1:length(Topologies);
            app.Ploss_avg_plot_6phase_HV.XTick = 1:length(NoofCells);
            app.Ploss_avg_plot_6phase_HV.YTickLabel = Topologies_Name;
            app.Ploss_avg_plot_6phase_HV.XTickLabel = NoofCells_Name;
            app.Ploss_avg_plot_6phase_HV.XLabel.String = "Number of cells per SM";
            app.Ploss_avg_plot_6phase_HV.YLabel.String = "Topologies";
            app.Ploss_avg_plot_6phase_HV.ZLabel.String = "P_{tot} [W]";
            
            % Total losses at maximum power plot
            % 3-phase plots
            cla(app.Ploss_max_plot_3phase)
            surf(app.Ploss_max_plot_3phase,squeeze(Ploss_tot_max_mtx(:,:,find(NoofPhases == 1)))/1e3)
            hold(app.Ploss_max_plot_3phase,'on');
            mesh(app.Ploss_max_plot_3phase,squeeze(Ploss_tot_2l_max(:,:,find(NoofPhases == 1)))/1e3,'FaceAlpha','0.3','EdgeColor','k')
            app.Ploss_max_plot_3phase.YTick = 1:length(Topologies);
            app.Ploss_max_plot_3phase.XTick = 1:length(NoofCells);
            app.Ploss_max_plot_3phase.YTickLabel = Topologies_Name;
            app.Ploss_max_plot_3phase.XTickLabel = NoofCells_Name;
            app.Ploss_max_plot_3phase.XLabel.String = "Number of cells per SM";
            app.Ploss_max_plot_3phase.YLabel.String = "Topologies";
            app.Ploss_max_plot_3phase.ZLabel.String = "P_{tot} [kW]";
            
            % 6-phase LV plots
            cla(app.Ploss_max_plot_6phase_LV)
            surf(app.Ploss_max_plot_6phase_LV,squeeze(Ploss_tot_max_mtx(:,:,find(NoofPhases == 2)))/1e3)
            hold(app.Ploss_max_plot_6phase_LV,'on');
            mesh(app.Ploss_max_plot_6phase_LV,squeeze(Ploss_tot_2l_max(:,:,find(NoofPhases == 2)))/1e3,'FaceAlpha','0.3','EdgeColor','k')
            app.Ploss_max_plot_6phase_LV.YTick = 1:length(Topologies);
            app.Ploss_max_plot_6phase_LV.XTick = 1:length(NoofCells);
            app.Ploss_max_plot_6phase_LV.YTickLabel = Topologies_Name;
            app.Ploss_max_plot_6phase_LV.XTickLabel = NoofCells_Name;
            app.Ploss_max_plot_6phase_LV.XLabel.String = "Number of cells per SM";
            app.Ploss_max_plot_6phase_LV.YLabel.String = "Topologies";
            app.Ploss_max_plot_6phase_LV.ZLabel.String = "P_{tot} [kW]";
            
            % 6-phase HV plots
            cla(app.Ploss_max_plot_6phase_HV)
            surf(app.Ploss_max_plot_6phase_HV,squeeze(Ploss_tot_max_mtx(:,:,find(NoofPhases == 3)))/1e3)
            hold(app.Ploss_max_plot_6phase_HV,'on');
            mesh(app.Ploss_max_plot_6phase_HV,squeeze(Ploss_tot_2l_max(:,:,find(NoofPhases == 3)))/1e3,'FaceAlpha','0.3','EdgeColor','k')
            app.Ploss_max_plot_6phase_HV.YTick = 1:length(Topologies);
            app.Ploss_max_plot_6phase_HV.XTick = 1:length(NoofCells);
            app.Ploss_max_plot_6phase_HV.YTickLabel = Topologies_Name;
            app.Ploss_max_plot_6phase_HV.XTickLabel = NoofCells_Name;
            app.Ploss_max_plot_6phase_HV.XLabel.String = "Number of cells per SM";
            app.Ploss_max_plot_6phase_HV.YLabel.String = "Topologies";
            app.Ploss_max_plot_6phase_HV.ZLabel.String = "P_{tot} [kW]";
            
            % Total effeciencies at average power plot
            % 3-phase plots
            cla(app.eff_avg_plot_3phase)
            surf(app.eff_avg_plot_3phase,squeeze(eff_tot_avg_mtx(:,:,find(NoofPhases == 1))))
            hold(app.eff_avg_plot_3phase,'on');
            mesh(app.eff_avg_plot_3phase,squeeze(eff_tot_2l_avg(:,:,find(NoofPhases == 1))),'FaceAlpha','0.3','EdgeColor','k')
            app.eff_avg_plot_3phase.YTick = 1:length(Topologies);
            app.eff_avg_plot_3phase.XTick = 1:length(NoofCells);
            app.eff_avg_plot_3phase.YTickLabel = Topologies_Name;
            app.eff_avg_plot_3phase.XTickLabel = NoofCells_Name;
            app.eff_avg_plot_3phase.XLabel.String = "Number of cells per SM";
            app.eff_avg_plot_3phase.YLabel.String = "Topologies";
            app.eff_avg_plot_3phase.ZLabel.String = "\eta_{tot} [-]";
            
            % 6-phase LV plots
            cla(app.eff_avg_plot_6phase_LV)
            surf(app.eff_avg_plot_6phase_LV,squeeze(eff_tot_avg_mtx(:,:,find(NoofPhases == 2))))
            hold(app.eff_avg_plot_6phase_LV,'on');
            mesh(app.eff_avg_plot_6phase_LV,squeeze(eff_tot_2l_avg(:,:,find(NoofPhases == 2))),'FaceAlpha','0.3','EdgeColor','k')
            app.eff_avg_plot_6phase_LV.YTick = 1:length(Topologies);
            app.eff_avg_plot_6phase_LV.XTick = 1:length(NoofCells);
            app.eff_avg_plot_6phase_LV.YTickLabel = Topologies_Name;
            app.eff_avg_plot_6phase_LV.XTickLabel = NoofCells_Name;
            app.eff_avg_plot_6phase_LV.XLabel.String = "Number of cells per SM";
            app.eff_avg_plot_6phase_LV.YLabel.String = "Topologies";
            app.eff_avg_plot_6phase_LV.ZLabel.String = "\eta_{tot} [-]";
            
            % 6-phase HV plots
            cla(app.eff_avg_plot_6phase_HV)
            surf(app.eff_avg_plot_6phase_HV,squeeze(eff_tot_avg_mtx(:,:,find(NoofPhases == 3))))
            hold(app.eff_avg_plot_6phase_HV,'on');
            mesh(app.eff_avg_plot_6phase_HV,squeeze(eff_tot_2l_avg(:,:,find(NoofPhases == 3))),'FaceAlpha','0.3','EdgeColor','k')
            app.eff_avg_plot_6phase_HV.YTick = 1:length(Topologies);
            app.eff_avg_plot_6phase_HV.XTick = 1:length(NoofCells);
            app.eff_avg_plot_6phase_HV.YTickLabel = Topologies_Name;
            app.eff_avg_plot_6phase_HV.XTickLabel = NoofCells_Name;
            app.eff_avg_plot_6phase_HV.XLabel.String = "Number of cells per SM";
            app.eff_avg_plot_6phase_HV.YLabel.String = "Topologies";
            app.eff_avg_plot_6phase_HV.ZLabel.String = "\eta_{tot} [-]";
            
            % Total effeciencies at maximum power plot
            % 3-phase plots
            cla(app.eff_max_plot_3phase)
            surf(app.eff_max_plot_3phase,squeeze(eff_tot_max_mtx(:,:,find(NoofPhases == 1))))
            hold(app.eff_max_plot_3phase,'on');
            mesh(app.eff_max_plot_3phase,squeeze(eff_tot_2l_max(:,:,find(NoofPhases == 1))),'FaceAlpha','0.3','EdgeColor','k')
            app.eff_max_plot_3phase.YTick = 1:length(Topologies);
            app.eff_max_plot_3phase.XTick = 1:length(NoofCells);
            app.eff_max_plot_3phase.YTickLabel = Topologies_Name;
            app.eff_max_plot_3phase.XTickLabel = NoofCells_Name;
            app.eff_max_plot_3phase.XLabel.String = "Number of cells per SM";
            app.eff_max_plot_3phase.YLabel.String = "Topologies";
            app.eff_max_plot_3phase.ZLabel.String = "\eta_{tot} [W]";
            
            % 6-phase LV plots
            cla(app.eff_max_plot_6phase_LV)
            surf(app.eff_max_plot_6phase_LV,squeeze(eff_tot_max_mtx(:,:,find(NoofPhases == 2))))
            hold(app.eff_max_plot_6phase_LV,'on');
            mesh(app.eff_max_plot_6phase_LV,squeeze(eff_tot_2l_max(:,:,find(NoofPhases == 2))),'FaceAlpha','0.3','EdgeColor','k')
            app.eff_max_plot_6phase_LV.YTick = 1:length(Topologies);
            app.eff_max_plot_6phase_LV.XTick = 1:length(NoofCells);
            app.eff_max_plot_6phase_LV.YTickLabel = Topologies_Name;
            app.eff_max_plot_6phase_LV.XTickLabel = NoofCells_Name;
            app.eff_max_plot_6phase_LV.XLabel.String = "Number of cells per SM";
            app.eff_max_plot_6phase_LV.YLabel.String = "Topologies";
            app.eff_max_plot_6phase_LV.ZLabel.String = "\eta_{tot} [W]";
            
            % 6-phase HV plots
            cla(app.eff_max_plot_6phase_HV)
            surf(app.eff_max_plot_6phase_HV,squeeze(eff_tot_max_mtx(:,:,find(NoofPhases == 3))))
            hold(app.eff_max_plot_6phase_HV,'on');
            mesh(app.eff_max_plot_6phase_HV,squeeze(eff_tot_2l_max(:,:,find(NoofPhases == 3))),'FaceAlpha','0.3','EdgeColor','k')
            app.eff_max_plot_6phase_HV.YTick = 1:length(Topologies);
            app.eff_max_plot_6phase_HV.XTick = 1:length(NoofCells);
            app.eff_max_plot_6phase_HV.YTickLabel = Topologies_Name;
            app.eff_max_plot_6phase_HV.XTickLabel = NoofCells_Name;
            app.eff_max_plot_6phase_HV.XLabel.String = "Number of cells per SM";
            app.eff_max_plot_6phase_HV.YLabel.String = "Topologies";
            app.eff_max_plot_6phase_HV.ZLabel.String = "\eta_{tot} [W]";


            %% total converter cost plots
            % 3 phase
            cla(app.lambda_plot_3phase);
            stacked_bar3(app.lambda_plot_3phase,squeeze(lambda_TotalConverterCost_mtx(:,:,find(NoofPhases == 1),:)));
            hold(app.lambda_plot_3phase,'on');
            mesh(app.lambda_plot_3phase,squeeze(lambda_conv_2l_mtx(:,:,find(NoofPhases == 1))),'FaceAlpha','0.3','EdgeColor','k')
            app.lambda_plot_3phase.YTick = 1:length(Topologies);
            app.lambda_plot_3phase.XTick = 1:length(NoofCells);
            app.lambda_plot_3phase.YTickLabel = Topologies_Name;
            app.lambda_plot_3phase.XTickLabel = NoofCells_Name;
            app.lambda_plot_3phase.XLabel.String = "Number of cells per SM";
            app.lambda_plot_3phase.YLabel.String = "Topologies";
            app.lambda_plot_3phase.ZLabel.String = "\lambda_{conv(tot)} [€]";
            
            % 6 phase LV
            cla(app.lambda_plot_6phase_LV);
            stacked_bar3(app.lambda_plot_6phase_LV,squeeze(lambda_TotalConverterCost_mtx(:,:,find(NoofPhases == 2),:)));
            hold(app.lambda_plot_6phase_LV,'on');
            mesh(app.lambda_plot_6phase_LV,squeeze(lambda_conv_2l_mtx(:,:,find(NoofPhases == 2))),'FaceAlpha','0.3','EdgeColor','k')
            app.lambda_plot_6phase_LV.YTick = 1:length(Topologies);
            app.lambda_plot_6phase_LV.XTick = 1:length(NoofCells);
            app.lambda_plot_6phase_LV.YTickLabel = Topologies_Name;
            app.lambda_plot_6phase_LV.XTickLabel = NoofCells_Name;
            app.lambda_plot_6phase_LV.XLabel.String = "Number of cells per SM";
            app.lambda_plot_6phase_LV.YLabel.String = "Topologies";
            app.lambda_plot_6phase_LV.ZLabel.String = "\lambda_{conv(tot)} [€]";
            
            % 6 phase HV
            cla(app.lambda_plot_6phase_HV);
            stacked_bar3(app.lambda_plot_6phase_HV,squeeze(lambda_TotalConverterCost_mtx(:,:,find(NoofPhases == 3),:)));
            hold(app.lambda_plot_6phase_HV,'on');
            mesh(app.lambda_plot_6phase_HV,squeeze(lambda_conv_2l_mtx(:,:,find(NoofPhases == 3))),'FaceAlpha','0.3','EdgeColor','k')
            app.lambda_plot_6phase_HV.YTick = 1:length(Topologies);
            app.lambda_plot_6phase_HV.XTick = 1:length(NoofCells);
            app.lambda_plot_6phase_HV.YTickLabel = Topologies_Name;
            app.lambda_plot_6phase_HV.XTickLabel = NoofCells_Name;
            app.lambda_plot_6phase_HV.XLabel.String = "Number of cells per SM";
            app.lambda_plot_6phase_HV.YLabel.String = "Topologies";
            app.lambda_plot_6phase_HV.ZLabel.String = "\lambda_{conv(tot)} [€]";
            
            %% total converter normalized cost plots
            % 3 phase
            cla(app.lambda_normalized_plot_3phase);
            surf(app.lambda_normalized_plot_3phase,squeeze(sum(lambda_converter_normalized(:,:,find(NoofPhases == 1),:),4)));
            app.lambda_normalized_plot_3phase.YTick = 1:length(Topologies);
            app.lambda_normalized_plot_3phase.XTick = 1:length(NoofCells);
            app.lambda_normalized_plot_3phase.YTickLabel = Topologies_Name;
            app.lambda_normalized_plot_3phase.XTickLabel = NoofCells_Name;
            app.lambda_normalized_plot_3phase.XLabel.String = "Number of cells per SM";
            app.lambda_normalized_plot_3phase.YLabel.String = "Topologies";
            app.lambda_normalized_plot_3phase.ZLabel.String = "\lambda_{conv(tot)}^N ";
            
            % 6 phase LV
            cla(app.lambda_normalized_plot_6phase_LV);
            surf(app.lambda_normalized_plot_6phase_LV,squeeze(sum(lambda_converter_normalized(:,:,find(NoofPhases == 2),:),4)));
            app.lambda_normalized_plot_6phase_LV.YTick = 1:length(Topologies);
            app.lambda_normalized_plot_6phase_LV.XTick = 1:length(NoofCells);
            app.lambda_normalized_plot_6phase_LV.YTickLabel = Topologies_Name;
            app.lambda_normalized_plot_6phase_LV.XTickLabel = NoofCells_Name;
            app.lambda_normalized_plot_6phase_LV.XLabel.String = "Number of cells per SM";
            app.lambda_normalized_plot_6phase_LV.YLabel.String = "Topologies";
            app.lambda_normalized_plot_6phase_LV.ZLabel.String = "\lambda_{conv(tot)}^N ";
            
            % 6 phase HV
            cla(app.lambda_normalized_plot_6phase_HV);
            surf(app.lambda_normalized_plot_6phase_HV,squeeze(sum(lambda_converter_normalized(:,:,find(NoofPhases == 3),:),4)));
            app.lambda_normalized_plot_6phase_HV.YTick = 1:length(Topologies);
            app.lambda_normalized_plot_6phase_HV.XTick = 1:length(NoofCells);
            app.lambda_normalized_plot_6phase_HV.YTickLabel = Topologies_Name;
            app.lambda_normalized_plot_6phase_HV.XTickLabel = NoofCells_Name;
            app.lambda_normalized_plot_6phase_HV.XLabel.String = "Number of cells per SM";
            app.lambda_normalized_plot_6phase_HV.YLabel.String = "Topologies";
            app.lambda_normalized_plot_6phase_HV.ZLabel.String = "\lambda_{conv(tot)}^N";
            
            %% Total System cost plots
            % 3 phase
            cla(app.lambda_tot_plot_3phase);
            stacked_bar3(app.lambda_tot_plot_3phase,squeeze(lambda_TotalCost_mtx(:,:,find(NoofPhases == 1),:)));
            hold(app.lambda_tot_plot_3phase,'on');
            mesh(app.lambda_tot_plot_3phase,squeeze(lambda_TotalCost_2l_mtx(:,:,find(NoofPhases == 1))),'FaceAlpha','0.3','EdgeColor','k')
            app.lambda_tot_plot_3phase.YTick = 1:length(Topologies);
            app.lambda_tot_plot_3phase.XTick = 1:length(NoofCells);
            app.lambda_tot_plot_3phase.YTickLabel = Topologies_Name;
            app.lambda_tot_plot_3phase.XTickLabel = NoofCells_Name;
            app.lambda_tot_plot_3phase.XLabel.String = "Number of cells per SM";
            app.lambda_tot_plot_3phase.YLabel.String = "Topologies";
            app.lambda_tot_plot_3phase.ZLabel.String = "\lambda_{tot} [€]";
            
            % 6 phase LV
            cla(app.lambda_tot_plot_6phase_LV);
            stacked_bar3(app.lambda_tot_plot_6phase_LV,squeeze(lambda_TotalCost_mtx(:,:,find(NoofPhases == 2),:)));
            hold(app.lambda_tot_plot_6phase_LV,'on');
            mesh(app.lambda_tot_plot_6phase_LV,squeeze(lambda_TotalCost_2l_mtx(:,:,find(NoofPhases == 2))),'FaceAlpha','0.3','EdgeColor','k')
            app.lambda_tot_plot_6phase_LV.YTick = 1:length(Topologies);
            app.lambda_tot_plot_6phase_LV.XTick = 1:length(NoofCells);
            app.lambda_tot_plot_6phase_LV.YTickLabel = Topologies_Name;
            app.lambda_tot_plot_6phase_LV.XTickLabel = NoofCells_Name;
            app.lambda_tot_plot_6phase_LV.XLabel.String = "Number of cells per SM";
            app.lambda_tot_plot_6phase_LV.YLabel.String = "Topologies";
            app.lambda_tot_plot_6phase_LV.ZLabel.String = "\lambda_{tot} [€]";
            
            % 6 phase HV
            cla(app.lambda_tot_plot_6phase_HV);
            stacked_bar3(app.lambda_tot_plot_6phase_HV,squeeze(lambda_TotalCost_mtx(:,:,find(NoofPhases == 3),:)));
            hold(app.lambda_tot_plot_6phase_HV,'on');
            mesh(app.lambda_tot_plot_6phase_HV,squeeze(lambda_TotalCost_2l_mtx(:,:,find(NoofPhases == 3))),'FaceAlpha','0.3','EdgeColor','k')
            app.lambda_tot_plot_6phase_HV.YTick = 1:length(Topologies);
            app.lambda_tot_plot_6phase_HV.XTick = 1:length(NoofCells);
            app.lambda_tot_plot_6phase_HV.YTickLabel = Topologies_Name;
            app.lambda_tot_plot_6phase_HV.XTickLabel = NoofCells_Name;
            app.lambda_tot_plot_6phase_HV.XLabel.String = "Number of cells per SM";
            app.lambda_tot_plot_6phase_HV.YLabel.String = "Topologies";
            app.lambda_tot_plot_6phase_HV.ZLabel.String = "\lambda_{tot} [€]";
            
            %% total converter normalized cost plots
            % 3 phase
            cla(app.lambda_tot_normalized_plot_3phase);
            surf(app.lambda_tot_normalized_plot_3phase,squeeze(sum(lambda_TotalCost_normalized(:,:,find(NoofPhases == 1),:),4)));
            app.lambda_tot_normalized_plot_3phase.YTick = 1:length(Topologies);
            app.lambda_tot_normalized_plot_3phase.XTick = 1:length(NoofCells);
            app.lambda_tot_normalized_plot_3phase.YTickLabel = Topologies_Name;
            app.lambda_tot_normalized_plot_3phase.XTickLabel = NoofCells_Name;
            app.lambda_tot_normalized_plot_3phase.XLabel.String = "Number of cells per SM";
            app.lambda_tot_normalized_plot_3phase.YLabel.String = "Topologies";
            app.lambda_tot_normalized_plot_3phase.ZLabel.String = "\lambda_{tot}^N ";
            
            % 6 phase LV
            cla(app.lambda_tot_normalized_plot_6phase_LV);
            surf(app.lambda_tot_normalized_plot_6phase_LV,squeeze(sum(lambda_TotalCost_normalized(:,:,find(NoofPhases == 2),:),4)));
            app.lambda_tot_normalized_plot_6phase_LV.YTick = 1:length(Topologies);
            app.lambda_tot_normalized_plot_6phase_LV.XTick = 1:length(NoofCells);
            app.lambda_tot_normalized_plot_6phase_LV.YTickLabel = Topologies_Name;
            app.lambda_tot_normalized_plot_6phase_LV.XTickLabel = NoofCells_Name;
            app.lambda_tot_normalized_plot_6phase_LV.XLabel.String = "Number of cells per SM";
            app.lambda_tot_normalized_plot_6phase_LV.YLabel.String = "Topologies";
            app.lambda_tot_normalized_plot_6phase_LV.ZLabel.String = "\lambda_{tot}^N ";
            
            % 6 phase HV
            cla(app.lambda_tot_normalized_plot_6phase_HV);
            surf(app.lambda_tot_normalized_plot_6phase_HV,squeeze(sum(lambda_TotalCost_normalized(:,:,find(NoofPhases == 3),:),4)));
            app.lambda_tot_normalized_plot_6phase_HV.YTick = 1:length(Topologies);
            app.lambda_tot_normalized_plot_6phase_HV.XTick = 1:length(NoofCells);
            app.lambda_tot_normalized_plot_6phase_HV.YTickLabel = Topologies_Name;
            app.lambda_tot_normalized_plot_6phase_HV.XTickLabel = NoofCells_Name;
            app.lambda_tot_normalized_plot_6phase_HV.XLabel.String = "Number of cells per SM";
            app.lambda_tot_normalized_plot_6phase_HV.YLabel.String = "Topologies";
            app.lambda_tot_normalized_plot_6phase_HV.ZLabel.String = "\lambda_{tot}^N";
            
            %% total converter losses during DC charging 
            % 3 phase
            cla(app.Ploss_DC_chrg_max_plot_3phase);
            surf(app.Ploss_DC_chrg_max_plot_3phase,squeeze(Psc_tot_max_DC_chrg_mtx(:,:,find(NoofPhases == 1)))/1e3)
            app.Ploss_DC_chrg_max_plot_3phase.YTick = 1:length(Topologies);
            app.Ploss_DC_chrg_max_plot_3phase.XTick = 1:length(NoofCells);
            app.Ploss_DC_chrg_max_plot_3phase.YTickLabel = Topologies_Name;
            app.Ploss_DC_chrg_max_plot_3phase.XTickLabel = NoofCells_Name;
            app.Ploss_DC_chrg_max_plot_3phase.XLabel.String = "Number of cells per SM";
            app.Ploss_DC_chrg_max_plot_3phase.YLabel.String = "Topologies";
            app.Ploss_DC_chrg_max_plot_3phase.ZLabel.String = "P_{sc(tot)} [kW]";
            
            % 6 phase LV
            cla(app.Ploss_DC_chrg_max_plot_6phase_LV);
            surf(app.Ploss_DC_chrg_max_plot_6phase_LV,squeeze(Psc_tot_max_DC_chrg_mtx(:,:,find(NoofPhases == 2)))/1e3)
            app.Ploss_DC_chrg_max_plot_6phase_LV.YTick = 1:length(Topologies);
            app.Ploss_DC_chrg_max_plot_6phase_LV.XTick = 1:length(NoofCells);
            app.Ploss_DC_chrg_max_plot_6phase_LV.YTickLabel = Topologies_Name;
            app.Ploss_DC_chrg_max_plot_6phase_LV.XTickLabel = NoofCells_Name;
            app.Ploss_DC_chrg_max_plot_6phase_LV.XLabel.String = "Number of cells per SM";
            app.Ploss_DC_chrg_max_plot_6phase_LV.YLabel.String = "Topologies";
            app.Ploss_DC_chrg_max_plot_6phase_LV.ZLabel.String = "P_{sc(tot)} [kW]";
            
            % 6 phase HV 
            cla(app.Ploss_DC_chrg_max_plot_6phase_HV);
            surf(app.Ploss_DC_chrg_max_plot_6phase_HV,squeeze(Psc_tot_max_DC_chrg_mtx(:,:,find(NoofPhases == 3)))/1e3)
            app.Ploss_DC_chrg_max_plot_6phase_HV.YTick = 1:length(Topologies);
            app.Ploss_DC_chrg_max_plot_6phase_HV.XTick = 1:length(NoofCells);
            app.Ploss_DC_chrg_max_plot_6phase_HV.YTickLabel = Topologies_Name;
            app.Ploss_DC_chrg_max_plot_6phase_HV.XTickLabel = NoofCells_Name;
            app.Ploss_DC_chrg_max_plot_6phase_HV.XLabel.String = "Number of cells per SM";
            app.Ploss_DC_chrg_max_plot_6phase_HV.YLabel.String = "Topologies";
            app.Ploss_DC_chrg_max_plot_6phase_HV.ZLabel.String = "P_{sc(tot)} [kW]";
            
            % DC charging case temperature 
            % 3 phase
            cla(app.Tc_DC_chrg_plot_3phase);
            surf(app.Tc_DC_chrg_plot_3phase,squeeze(Tc_DC_chrg_max_mtx(:,:,find(NoofPhases == 1))))
            app.Tc_DC_chrg_plot_3phase.YTick = 1:length(Topologies);
            app.Tc_DC_chrg_plot_3phase.XTick = 1:length(NoofCells);
            app.Tc_DC_chrg_plot_3phase.YTickLabel = Topologies_Name;
            app.Tc_DC_chrg_plot_3phase.XTickLabel = NoofCells_Name;
            app.Tc_DC_chrg_plot_3phase.XLabel.String = "Number of cells per SM";
            app.Tc_DC_chrg_plot_3phase.YLabel.String = "Topologies";
            app.Tc_DC_chrg_plot_3phase.ZLabel.String = "T_{c} [^oC]";
            
            % 6 phase
            cla(app.Tc_DC_chrg_plot_6phase_LV);
            surf(app.Tc_DC_chrg_plot_6phase_LV,squeeze(Tc_DC_chrg_max_mtx(:,:,find(NoofPhases == 2))))
            app.Tc_DC_chrg_plot_6phase_LV.YTick = 1:length(Topologies);
            app.Tc_DC_chrg_plot_6phase_LV.XTick = 1:length(NoofCells);
            app.Tc_DC_chrg_plot_6phase_LV.YTickLabel = Topologies_Name;
            app.Tc_DC_chrg_plot_6phase_LV.XTickLabel = NoofCells_Name;
            app.Tc_DC_chrg_plot_6phase_LV.XLabel.String = "Number of cells per SM";
            app.Tc_DC_chrg_plot_6phase_LV.YLabel.String = "Topologies";
            app.Tc_DC_chrg_plot_6phase_LV.ZLabel.String = "T_{c} [^oC]";
            
            % 6 phase
            cla(app.Tc_DC_chrg_plot_6phase_HV);
            surf(app.Tc_DC_chrg_plot_6phase_HV,squeeze(Tc_DC_chrg_max_mtx(:,:,find(NoofPhases == 3))))
            app.Tc_DC_chrg_plot_6phase_HV.YTick = 1:length(Topologies);
            app.Tc_DC_chrg_plot_6phase_HV.XTick = 1:length(NoofCells);
            app.Tc_DC_chrg_plot_6phase_HV.YTickLabel = Topologies_Name;
            app.Tc_DC_chrg_plot_6phase_HV.XTickLabel = NoofCells_Name;
            app.Tc_DC_chrg_plot_6phase_HV.XLabel.String = "Number of cells per SM";
            app.Tc_DC_chrg_plot_6phase_HV.YLabel.String = "Topologies";
            app.Tc_DC_chrg_plot_6phase_HV.ZLabel.String = "T_{c} [^oC]";
            
            %%%%...........................................................
            %% Sensitivity analysis
            %%%%...........................................................
            
            
            %% Create raw data as an spread-sheet
            Convert_data_to_table; % script to run
            
            
            %% check for error
            app.CompletedLamp.Enable = 'on'; % disable completed lamp
            app.RunningLamp.Enable = 'off'; % disable running lamp
            app.ErrorField.Value = "Done!";
            
            %% save the data
            %             save Dashboard_data.mat
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {980, 980};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {190, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TopologiesLabel = uilabel(app.LeftPanel);
            app.TopologiesLabel.Text = 'Topologies';
            app.DSHBCheckBox = uicheckbox(app.LeftPanel);
            app.DSHBCheckBox.Text = 'DSHB';
            app.DSFBCheckBox = uicheckbox(app.LeftPanel);
            app.DSFBCheckBox.Text = 'DSFB';
            app.SSHBCheckBox = uicheckbox(app.LeftPanel);
            app.SSHBCheckBox.Text = 'SSHB';
            app.SSFBCheckBox = uicheckbox(app.LeftPanel);
            app.SSFBCheckBox.Text = 'SSFB';
            app.SDFBCheckBox = uicheckbox(app.LeftPanel);
            app.SDFBCheckBox.Text = 'SDFB';
            app.NumberofcellsLabel = uilabel(app.LeftPanel);
            app.NumberofcellsLabel.Text = 'Number of cells';
            app.CheckBox_1 = uicheckbox(app.LeftPanel);
            app.CheckBox_1.Text = '1';
            app.CheckBox_5 = uicheckbox(app.LeftPanel);
            app.CheckBox_5.Text = '5';
            app.CheckBox_9 = uicheckbox(app.LeftPanel);
            app.CheckBox_9.Text = '9';
            app.CheckBox_2 = uicheckbox(app.LeftPanel);
            app.CheckBox_2.Text = '2';
            app.CheckBox_10 = uicheckbox(app.LeftPanel);
            app.CheckBox_10.Text = '10';
            app.CheckBox_6 = uicheckbox(app.LeftPanel);
            app.CheckBox_6.Text = '6';
            app.CheckBox_3 = uicheckbox(app.LeftPanel);
            app.CheckBox_3.Text = '3';
            app.CheckBox_11 = uicheckbox(app.LeftPanel);
            app.CheckBox_11.Text = '11';
            app.CheckBox_7 = uicheckbox(app.LeftPanel);
            app.CheckBox_7.Text = '7';
            app.CheckBox_4 = uicheckbox(app.LeftPanel);
            app.CheckBox_4.Text = '4';
            app.CheckBox_12 = uicheckbox(app.LeftPanel);
            app.CheckBox_12.Text = '12';
            app.CheckBox_8 = uicheckbox(app.LeftPanel);
            app.CheckBox_8.Text = '8';
            app.NumberofPhasesLabel_2 = uilabel(app.LeftPanel);
            app.NumberofPhasesLabel_2.Text = 'Number of Phases';
            app.phase3CheckBox = uicheckbox(app.LeftPanel);
            app.phase3CheckBox.Text = '3-phase';
            app.phase3CheckBox.Value = true;
            app.phase6LVCheckBox = uicheckbox(app.LeftPanel);
            app.phase6LVCheckBox.Text = '6-phase LV';
            app.phase6LVCheckBox.Value = true;
            app.phase6HVCheckBox = uicheckbox(app.LeftPanel);
            app.phase6HVCheckBox.Text = '6-phase HV';
            app.phase6HVCheckBox.Value = true;
            app.InformationLabel = uilabel(app.LeftPanel);
            app.InformationLabel.Text = 'Information';
            app.Tab0ParamteresTab1Label = uilabel(app.LeftPanel);
            app.Tab0ParamteresTab1Label.Text = {'SM - Submodule.'; 'SC - Semiconductor.'; ''; 'NOTE:'; 'Before you run the application, kindly close ''Dasboard_raw_data.xlsx'' file. To avoid program CRASH!'; ''};
            app.ErrorLampLabel = uilabel(app.LeftPanel);
            app.ErrorLampLabel.Text = 'Error';
            app.ErrorLamp = uilamp(app.LeftPanel);
            app.ErrorField = uieditfield(app.LeftPanel, 'text');
            app.RunningLampLabel = uilabel(app.LeftPanel);
            app.RunningLampLabel.Text = 'Running';
            app.RunningLamp = uilamp(app.LeftPanel);
            app.CompletedLampLabel = uilabel(app.LeftPanel);
            app.CompletedLampLabel.Text = 'Completed';
            app.CompletedLamp = uilamp(app.LeftPanel);
            app.RunButton = uibutton(app.LeftPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.ConverterParametersLabel = uilabel(app.ParametersTab);
            app.ConverterParametersLabel.Text = 'Converter Parameters';
            app.BatteryParametersLabel = uilabel(app.ParametersTab);
            app.BatteryParametersLabel.Text = 'Battery Parameters';
            app.ThermalParametersLabel = uilabel(app.ParametersTab);
            app.ThermalParametersLabel.Text = 'Thermal Parameters';
            app.CostConstantsLabel = uilabel(app.ParametersTab);
            app.CostConstantsLabel.Text = 'Cost Constants';
            app.MOSFETswitchingparametersLabel = uilabel(app.ParametersTab);
            app.MOSFETswitchingparametersLabel.Text = 'MOSFET switching parameters';
            app.AverageoutputpowerWEditFieldLabel = uilabel(app.ParametersTab);
            app.AverageoutputpowerWEditFieldLabel.Text = 'Average output power [W]';
            app.AverageoutputpowerWEditField = uieditfield(app.ParametersTab, 'numeric');
            app.AverageoutputpowerWEditField.Value = 100000;
            app.MinimumbatterycellvoltageVEditFieldLabel = uilabel(app.ParametersTab);
            app.MinimumbatterycellvoltageVEditFieldLabel.Text = 'Minimum battery cell voltage [V]';
            app.MinimumbatterycellvoltageVEditField = uieditfield(app.ParametersTab, 'numeric');
            app.MinimumbatterycellvoltageVEditField.Value = 3.45;
            app.MaximumAmbienttemperaturedegCEditFieldLabel = uilabel(app.ParametersTab);
            app.MaximumAmbienttemperaturedegCEditFieldLabel.Text = 'Maximum Ambient temperature  [degC]';
            app.MaximumAmbienttemperaturedegCEditField = uieditfield(app.ParametersTab, 'numeric');
            app.MaximumAmbienttemperaturedegCEditField.Value = 40;
            app.CostpersubmoduleSMEditFieldLabel = uilabel(app.ParametersTab);
            app.CostpersubmoduleSMEditFieldLabel.Text = 'Cost per submodule [€/SM]';
            app.RisetimedvdtVusEditFieldLabel = uilabel(app.ParametersTab);
            app.RisetimedvdtVusEditFieldLabel.Text = 'Rise-time dvdt [V/us]';
            app.CapacitorcostperunitEditFieldLabel = uilabel(app.ParametersTab);
            app.CapacitorcostperunitEditFieldLabel.Text = 'Capacitor cost per unit[€]';
            app.CostpersubmoduleSMEditField = uieditfield(app.ParametersTab, 'numeric');
            app.CostpersubmoduleSMEditField.Value = 50;
            app.CapacitorcostperunitEditField = uieditfield(app.ParametersTab, 'numeric');
            app.CapacitorcostperunitEditField.Value = 1;
            app.RisetimedvdtVusEditField = uieditfield(app.ParametersTab, 'numeric');
            app.RisetimedvdtVusEditField.Value = 500;
            app.RatedoutputvoltageVEditFieldLabel = uilabel(app.ParametersTab);
            app.RatedoutputvoltageVEditFieldLabel.Text = 'Rated output voltage [V]';
            app.RatedoutputvoltageVEditField = uieditfield(app.ParametersTab, 'numeric');
            app.RatedoutputvoltageVEditField.Value = 440;
            app.NominalcellvoltageVEditFieldLabel = uilabel(app.ParametersTab);
            app.NominalcellvoltageVEditFieldLabel.Text = 'Nominal cell voltage [V]';
            app.NominalcellvoltageVEditField = uieditfield(app.ParametersTab, 'numeric');
            app.NominalcellvoltageVEditField.Value = 3.7;
            app.MaximumCasetemperaturedegCEditFieldLabel = uilabel(app.ParametersTab);
            app.MaximumCasetemperaturedegCEditFieldLabel.Text = 'Maximum Case temperature  [degC]';
            app.MaximumCasetemperaturedegCEditField = uieditfield(app.ParametersTab, 'numeric');
            app.MaximumCasetemperaturedegCEditField.Value = 80;
            app.BatterycostperkWhkWhEditFieldLabel = uilabel(app.ParametersTab);
            app.BatterycostperkWhkWhEditFieldLabel.Text = 'Battery cost per kWh [€/kWh]';
            app.RisetimedidtAusEditFieldLabel = uilabel(app.ParametersTab);
            app.RisetimedidtAusEditFieldLabel.Text = 'Rise-time didt [A/us]';
            app.BatterycostperkWhkWhEditField = uieditfield(app.ParametersTab, 'numeric');
            app.BatterycostperkWhkWhEditField.Value = 120;
            app.RisetimedidtAusEditField = uieditfield(app.ParametersTab, 'numeric');
            app.RisetimedidtAusEditField.Value = 2000;
            app.maximummodulationindexEditFieldLabel = uilabel(app.ParametersTab);
            app.maximummodulationindexEditFieldLabel.Text = 'maximum modulation index';
            app.maximummodulationindexEditField = uieditfield(app.ParametersTab, 'numeric');
            app.maximummodulationindexEditField.Value = 0.85;
            app.MOSFETparametersvectorLabel = uilabel(app.ParametersTab);
            app.MOSFETparametersvectorLabel.Text = 'MOSFET parameters vector';
            app.loadpowerfactorEditFieldLabel = uilabel(app.ParametersTab);
            app.loadpowerfactorEditFieldLabel.Text = 'load power factor';
            app.loadpowerfactorEditField = uieditfield(app.ParametersTab, 'numeric');
            app.loadpowerfactorEditField.Value = 0.9;
            app.MaximumcellvoltageVEditFieldLabel = uilabel(app.ParametersTab);
            app.MaximumcellvoltageVEditFieldLabel.Text = 'Maximum cell voltage [V]';
            app.MaximumcellvoltageVEditField = uieditfield(app.ParametersTab, 'numeric');
            app.MaximumcellvoltageVEditField.Value = 4;
            app.CasetoambientheattransfercoefficienctWm2KEditFieldLabel = uilabel(app.ParametersTab);
            app.CasetoambientheattransfercoefficienctWm2KEditFieldLabel.Text = 'Case to ambient heat transfer coefficienct [W/m^2K]';
            app.CasetoambientheattransfercoefficienctWm2KEditField = uieditfield(app.ParametersTab, 'numeric');
            app.CasetoambientheattransfercoefficienctWm2KEditField.Value = 400;
            app.OnstateresistanceOhmEditFieldLabel = uilabel(app.ParametersTab);
            app.OnstateresistanceOhmEditFieldLabel.Text = 'On-state resistance [Ohm]';
            app.FalltimedvdtVusEditFieldLabel = uilabel(app.ParametersTab);
            app.FalltimedvdtVusEditFieldLabel.Text = 'Fall-time dvdt [V/us]';
            app.OnstateresistanceOhmEditField = uieditfield(app.ParametersTab, 'text');
            app.OnstateresistanceOhmEditField.Value = '2.5e-4 4e-4 4e-4 4e-4 4e-4 4e-4 6.8e-4 6.8e-4 6.8e-4 1.05e-3 1.05e-3 1.05e-3';
            app.NotinUseLabel = uilabel(app.ParametersTab);
            app.NotinUseLabel.Text = 'Not in Use';
            app.FalltimedvdtVusEditField = uieditfield(app.ParametersTab, 'numeric');
            app.FalltimedvdtVusEditField.Value = 500;
            app.overcurrentfactorEditFieldLabel = uilabel(app.ParametersTab);
            app.overcurrentfactorEditFieldLabel.Text = 'over current factor';
            app.overcurrentfactorEditField = uieditfield(app.ParametersTab, 'numeric');
            app.overcurrentfactorEditField.Value = 4;
            app.EnergyofbatterypackWhEditFieldLabel = uilabel(app.ParametersTab);
            app.EnergyofbatterypackWhEditFieldLabel.Text = 'Energy of battery pack [Wh]';
            app.EnergyofbatterypackWhEditField = uieditfield(app.ParametersTab, 'numeric');
            app.EnergyofbatterypackWhEditField.Value = 1000000;
            app.MOSFETaream2EditFieldLabel = uilabel(app.ParametersTab);
            app.MOSFETaream2EditFieldLabel.Text = 'MOSFET area [m^2]';
            app.MOSFETaream2EditField = uieditfield(app.ParametersTab, 'numeric');
            app.MOSFETaream2EditField.Value = 0.0004;
            app.ThermalresistancejunctiontocaseKWEditFieldLabel = uilabel(app.ParametersTab);
            app.ThermalresistancejunctiontocaseKWEditFieldLabel.Text = 'Thermal resistance (junction to case) [K/W]';
            app.FalltimedidtAusEditFieldLabel = uilabel(app.ParametersTab);
            app.FalltimedidtAusEditFieldLabel.Text = 'Fall-time didt [A/us]';
            app.NotinUseLabel_2 = uilabel(app.ParametersTab);
            app.NotinUseLabel_2.Text = 'Not in Use';
            app.ThermalresistancejunctiontocaseKWEditField = uieditfield(app.ParametersTab, 'text');
            app.ThermalresistancejunctiontocaseKWEditField.Value = '0.61 1.2 1.2 0.61 0.61 0.61 0.5 0.5 0.5 0.48 0.48 0.48';
            app.FalltimedidtAusEditField = uieditfield(app.ParametersTab, 'numeric');
            app.FalltimedidtAusEditField.Value = 2000;
            app.SubmodulepulsenumberEditFieldLabel = uilabel(app.ParametersTab);
            app.SubmodulepulsenumberEditFieldLabel.Text = 'Submodule pulse number';
            app.SubmodulepulsenumberEditField = uieditfield(app.ParametersTab, 'numeric');
            app.SubmodulepulsenumberEditField.Value = 6;
            app.DepthofdischargeEditFieldLabel = uilabel(app.ParametersTab);
            app.DepthofdischargeEditFieldLabel.Text = 'Depth of discharge';
            app.DepthofdischargeEditField = uieditfield(app.ParametersTab, 'numeric');
            app.DepthofdischargeEditField.Value = 0.65;
            app.Heattransferaream2EditFieldLabel = uilabel(app.ParametersTab);
            app.Heattransferaream2EditFieldLabel.Text = 'Heat transfer area [m^2]';
            app.Heattransferaream2EditField = uieditfield(app.ParametersTab, 'numeric');
            app.Heattransferaream2EditField.Value = 0.0008;
            app.MOSFETcostEditFieldLabel = uilabel(app.ParametersTab);
            app.MOSFETcostEditFieldLabel.Text = 'MOSFET cost [€]';
            app.SubmoduleDCvoltagetolerenceLabel = uilabel(app.ParametersTab);
            app.SubmoduleDCvoltagetolerenceLabel.Text = 'Submodule DC voltage tolerence [%]';
            app.MOSFETcostEditField = uieditfield(app.ParametersTab, 'text');
            app.MOSFETcostEditField.Value = '3 3 3 3 3 3 3 3 3 3 3 3';
            app.NotinUseLabel_3 = uilabel(app.ParametersTab);
            app.NotinUseLabel_3.Text = 'Not in Use';
            app.SubmoduleDCvoltagetolerenceEditField = uieditfield(app.ParametersTab, 'numeric');
            app.SubmoduleDCvoltagetolerenceEditField.Value = 50;
            app.MotoranddrivecycleparametersLabel_2 = uilabel(app.ParametersTab);
            app.MotoranddrivecycleparametersLabel_2.Text = 'Motor and drive-cycle parameters';
            app.NominalcellcapacityAhEditFieldLabel = uilabel(app.ParametersTab);
            app.NominalcellcapacityAhEditFieldLabel.Text = 'Nominal cell capacity [Ah]';
            app.NominalcellcapacityAhEditField = uieditfield(app.ParametersTab, 'numeric');
            app.NominalcellcapacityAhEditField.Value = 28;
            app.DCchargingLabel = uilabel(app.ParametersTab);
            app.DCchargingLabel.Text = 'DC charging';
            app.DCchargingenableCheckBox = uicheckbox(app.ParametersTab);
            app.DCchargingenableCheckBox.Text = 'DC charging enable';
            app.DCchargingenableCheckBox.Value = true;
            app.LossclaculationlimitsPanel = uipanel(app.ParametersTab);
            app.LossclaculationlimitsPanel.Title = 'Loss claculation limits';
            app.LosslimitEnableCheckBox = uicheckbox(app.LossclaculationlimitsPanel);
            app.LosslimitEnableCheckBox.Text = 'Loss limit Enable';
            app.PCBparaciticinductanceHEditFieldLabel = uilabel(app.ParametersTab);
            app.PCBparaciticinductanceHEditFieldLabel.Text = 'PCB paracitic inductance [H]';
            app.PCBparaciticinductanceHEditField = uieditfield(app.ParametersTab, 'numeric');
            app.PCBparaciticinductanceHEditField.Value = 2e-09;
            app.NumberofpolesofmotorEditFieldLabel = uilabel(app.ParametersTab);
            app.NumberofpolesofmotorEditFieldLabel.Text = 'Number of poles of motor';
            app.NumberofpolesofmotorEditField = uieditfield(app.ParametersTab, 'numeric');
            app.NumberofpolesofmotorEditField.Value = 40;
            app.DCchargingvoltageVEditFieldLabel = uilabel(app.ParametersTab);
            app.DCchargingvoltageVEditFieldLabel.Text = 'DC charging voltage [V]';
            app.DCchargingvoltageVEditField = uieditfield(app.ParametersTab, 'numeric');
            app.DCchargingvoltageVEditField.Value = 800;
            app.DampingratioEditFieldLabel = uilabel(app.ParametersTab);
            app.DampingratioEditFieldLabel.Text = 'Damping ratio';
            app.DampingratioEditField = uieditfield(app.ParametersTab, 'numeric');
            app.DampingratioEditField.Value = 1.1;
            app.MotorNominalspeedrpmEditFieldLabel = uilabel(app.ParametersTab);
            app.MotorNominalspeedrpmEditFieldLabel.Text = 'Motor Nominal speed [rpm]';
            app.MotorNominalspeedrpmEditField = uieditfield(app.ParametersTab, 'numeric');
            app.MotorNominalspeedrpmEditField.Value = 1000;
            app.DCchargingpowerWEditFieldLabel = uilabel(app.ParametersTab);
            app.DCchargingpowerWEditFieldLabel.Text = 'DC charging power [W]';
            app.DCchargingpowerWEditField = uieditfield(app.ParametersTab, 'numeric');
            app.DCchargingpowerWEditField.Value = 1000000;
            app.MinimumnumberofparallelMOSFETsEditFieldLabel = uilabel(app.ParametersTab);
            app.MinimumnumberofparallelMOSFETsEditFieldLabel.Text = 'Minimum number of parallel MOSFETs';
            app.MinimumnumberofparallelMOSFETsEditField = uieditfield(app.ParametersTab, 'numeric');
            app.MinimumnumberofparallelMOSFETsEditField.Value = 2;
            app.Image = uiimage(app.ParametersTab);
            app.Image.ImageSource = 'AllTops.png';
            app.BatterymodelingLabel = uilabel(app.ParametersTab);
            app.BatterymodelingLabel.Text = 'Battery modeling';
            app.MaximumnumberofparallelMOSFETsEditFieldLabel = uilabel(app.ParametersTab);
            app.MaximumnumberofparallelMOSFETsEditFieldLabel.Text = 'Maximum number of parallel MOSFETs';
            app.MaximumnumberofparallelMOSFETsEditField = uieditfield(app.ParametersTab, 'numeric');
            app.MaximumnumberofparallelMOSFETsEditField.Value = 20;
            app.BatteryLeadsparaciticinductanceHEditFieldLabel = uilabel(app.ParametersTab);
            app.BatteryLeadsparaciticinductanceHEditFieldLabel.Text = 'Battery Leads paracitic inductance [H]';
            app.AlelionCellEISdataCheckBox = uicheckbox(app.ParametersTab);
            app.AlelionCellEISdataCheckBox.Text = 'Alelion Cell EIS data';
            app.AlelionCellEISdataCheckBox.Value = true;
            app.BatteryLeadsparaciticinductanceHEditField = uieditfield(app.ParametersTab, 'numeric');
            app.BatteryLeadsparaciticinductanceHEditField.Value = 2e-07;
            app.MinimumallowabledidtAusEditFieldLabel = uilabel(app.ParametersTab);
            app.MinimumallowabledidtAusEditFieldLabel.Text = 'Minimum allowable di/dt [A/us]';
            app.DCimpedancefrequencyHzEditFieldLabel = uilabel(app.ParametersTab);
            app.DCimpedancefrequencyHzEditFieldLabel.Text = 'DC-impedance frequency [Hz]';
            app.DCimpedancefrequencyHzEditField = uieditfield(app.ParametersTab, 'numeric');
            app.DCimpedancefrequencyHzEditField.Value = 1;
            app.MinimumallowabledidtAusEditField = uieditfield(app.ParametersTab, 'numeric');
            app.MinimumallowabledidtAusEditField.Value = 200;
            app.levelinverterLabel = uilabel(app.ParametersTab);
            app.levelinverterLabel.Text = '2-level inverter ';
            app.CapacitormodelingLabel = uilabel(app.ParametersTab);
            app.CapacitormodelingLabel.Text = 'Capacitor modeling';
            app.PeakRMScurrentfor1seriescellspersubmoduleAEditFieldLabel = uilabel(app.ParametersTab);
            app.PeakRMScurrentfor1seriescellspersubmoduleAEditFieldLabel.Text = 'Peak RMS current for 1 series cells per submodule [A]';
            app.DClinkvoltageVEditFieldLabel = uilabel(app.ParametersTab);
            app.DClinkvoltageVEditFieldLabel.Text = 'DC-link voltage [V]';
            app.DClinkvoltageVEditField = uieditfield(app.ParametersTab, 'numeric');
            app.DClinkvoltageVEditField.Value = 800;
            app.PeakRMScurrentfor1seriescellspersubmoduleAEditField = uieditfield(app.ParametersTab, 'numeric');
            app.PeakRMScurrentfor1seriescellspersubmoduleAEditField.Value = 5;
            app.Maximumenergyfor1seriescellspersubmoduleJEditFieldLabel = uilabel(app.ParametersTab);
            app.Maximumenergyfor1seriescellspersubmoduleJEditFieldLabel.Text = 'Maximum energy for 1 series cells per submodule [J]';
            app.levelinverterpulsenumberEditFieldLabel = uilabel(app.ParametersTab);
            app.levelinverterpulsenumberEditFieldLabel.Text = '2-level inverter pulse number';
            app.levelinverterpulsenumberEditField = uieditfield(app.ParametersTab, 'numeric');
            app.levelinverterpulsenumberEditField.Value = 21;
            app.Maximumenergyfor1seriescellspersubmoduleJEditField = uieditfield(app.ParametersTab, 'numeric');
            app.Maximumenergyfor1seriescellspersubmoduleJEditField.Value = 0.008;
            app.MaximumenergyforasubmodulecapacitorJEditFieldLabel = uilabel(app.ParametersTab);
            app.MaximumenergyforasubmodulecapacitorJEditFieldLabel.Text = 'Maximum energy for a submodule capacitor [J]';
            app.DClinkcapacitorenergy2levelJEditFieldLabel = uilabel(app.ParametersTab);
            app.DClinkcapacitorenergy2levelJEditFieldLabel.Text = 'DC-link capacitor energy 2-level [J]';
            app.DClinkcapacitorenergy2levelJEditField = uieditfield(app.ParametersTab, 'numeric');
            app.DClinkcapacitorenergy2levelJEditField.Value = 992;
            app.MaximumenergyforasubmodulecapacitorJEditField = uieditfield(app.ParametersTab, 'numeric');
            app.MaximumenergyforasubmodulecapacitorJEditField.Value = 0.03;
            app.MaximumpowerdessipationforasubmodulecapacitorWEditFieldLabel = uilabel(app.ParametersTab);
            app.MaximumpowerdessipationforasubmodulecapacitorWEditFieldLabel.Text = 'Maximum power dessipation for a submodule capacitor [W]';
            app.DClinkcapacitorDF2levelEditFieldLabel = uilabel(app.ParametersTab);
            app.DClinkcapacitorDF2levelEditFieldLabel.Text = 'DC-link capacitor DF 2-level [%]';
            app.DClinkcapacitorDF2levelEditField = uieditfield(app.ParametersTab, 'numeric');
            app.DClinkcapacitorDF2levelEditField.Value = 1.95;
            app.MaximumpowerdessipationforasubmodulecapacitorWEditField = uieditfield(app.ParametersTab, 'numeric');
            app.MaximumpowerdessipationforasubmodulecapacitorWEditField.Value = 0.25;
            app.DClinkcapacitorESLHEditFieldLabel = uilabel(app.ParametersTab);
            app.DClinkcapacitorESLHEditFieldLabel.Text = 'DC-link capacitor ESL [H]';
            app.SMDClinkcapacitorDFEditFieldLabel = uilabel(app.ParametersTab);
            app.SMDClinkcapacitorDFEditFieldLabel.Text = 'SM DC-link capacitor DF [%]';
            app.DClinkcapacitorESLHEditField = uieditfield(app.ParametersTab, 'numeric');
            app.DClinkcapacitorESLHEditField.Value = 6e-08;
            app.SMDClinkcapacitorDFEditField = uieditfield(app.ParametersTab, 'numeric');
            app.SMDClinkcapacitorDFEditField.Value = 5;
            app.TotalenergystoredinSMdclinkcapacitorsJEditFieldLabel = uilabel(app.ParametersTab);
            app.TotalenergystoredinSMdclinkcapacitorsJEditFieldLabel.Text = 'Total energy stored in SM dc-link capacitors [J]';
            app.ThermalresistancecasetoambientKWEditFieldLabel = uilabel(app.ParametersTab);
            app.ThermalresistancecasetoambientKWEditFieldLabel.Text = 'Thermal resistance case to ambient [K/W]';
            app.ThermalresistancecasetoambientKWEditField = uieditfield(app.ParametersTab, 'numeric');
            app.ThermalresistancecasetoambientKWEditField.Value = 0.01;
            app.TotalenergystoredinSMdclinkcapacitorsJEditField = uieditfield(app.ParametersTab, 'numeric');
            app.TotalenergystoredinSMdclinkcapacitorsJEditField.Value = 350;
            app.BatteryLeadsinductance2levelHEditFieldLabel = uilabel(app.ParametersTab);
            app.BatteryLeadsinductance2levelHEditFieldLabel.Text = 'Battery Leads inductance 2-level [H]';
            app.LoadcapacitorEnergymatrixCheckBox = uicheckbox(app.ParametersTab);
            app.LoadcapacitorEnergymatrixCheckBox.Text = 'Load capacitor Energy matrix';
            app.BatteryLeadsinductance2levelHEditField = uieditfield(app.ParametersTab, 'numeric');
            app.BatteryLeadsinductance2levelHEditField.Value = 1e-07;
            app.OptimizingminimumnumberofparallelMOSFETsCheckBox = uicheckbox(app.ParametersTab);
            app.OptimizingminimumnumberofparallelMOSFETsCheckBox.Text = 'Optimizing minimum number of parallel MOSFETs';
            app.Tab0ParamteresTab1Label_2 = uilabel(app.ParametersTab);
            app.Tab0ParamteresTab1Label_2.Text = {'(a) Double-Star Half-Bridge (DS-HB)'; '(b) Double-Star Full-Bridge (DS-FB)'; '(c) Single-Star Half-Bridge (SS-HB)'; '(d) Single-Star Full-Bridge (SS-FB)'; '(e) Single-Delta Full-Bridge (SD-FB)'};
            app.ModuleSelectionButtonGroup = uibuttongroup(app.ParametersTab);
            app.ModuleSelectionButtonGroup.Title = 'Module Selection';
            app.MOSFETWolfspeedCAB450M12XM3SiCButton = uiradiobutton(app.ModuleSelectionButtonGroup);
            app.MOSFETWolfspeedCAB450M12XM3SiCButton.Text = 'MOSFET - Wolfspeed CAB450M12XM3 - SiC ';
            app.MOSFETWolfspeedCAB450M12XM3SiCButton.Value = true;
            app.MOSFETSEMITRANSSKM260MB170SCH17SiCButton = uiradiobutton(app.ModuleSelectionButtonGroup);
            app.MOSFETSEMITRANSSKM260MB170SCH17SiCButton.Text = 'MOSFET - SEMITRANS SKM260MB170SCH17 - SiC';
            app.IGBTIXYSMIXA300PF1200TSFSiBuggedButton = uiradiobutton(app.ModuleSelectionButtonGroup);
            app.IGBTIXYSMIXA300PF1200TSFSiBuggedButton.Text = 'IGBT - IXYS MIXA300PF1200TSF - Si (Bugged)';
            app.RelativelossreductionfactorEditFieldLabel = uilabel(app.ParametersTab);
            app.RelativelossreductionfactorEditFieldLabel.Text = ' Relative loss reduction factor [%]';
            app.RelativelossreductionfactorEditField = uieditfield(app.ParametersTab, 'numeric');
            app.RelativelossreductionfactorEditField.Value = 5;
            app.OptimizedpulsenumberandcapacitorenergyCheckBox = uicheckbox(app.ParametersTab);
            app.OptimizedpulsenumberandcapacitorenergyCheckBox.Text = 'Optimized pulse number and capacitor energy ';
            app.OptimizedpulsenumberandcapacitorenergyCheckBox.Value = true;
            app.ReoptimizeCheckBox = uicheckbox(app.ParametersTab);
            app.ReoptimizeCheckBox.Text = 'Re-optimize';
            app.CapacitorunderratingfactorEditFieldLabel = uilabel(app.ParametersTab);
            app.CapacitorunderratingfactorEditFieldLabel.Text = 'Capacitor under-rating factor [%]';
            app.CapacitorunderratingfactorEditField = uieditfield(app.ParametersTab, 'numeric');
            app.CapacitorunderratingfactorEditField.Value = 10;
            app.LosscalculationlimitingfrequencyHzEditFieldLabel = uilabel(app.ParametersTab);
            app.LosscalculationlimitingfrequencyHzEditFieldLabel.Text = 'Loss calculation limiting frequency [Hz]';
            app.LosscalculationlimitingfrequencyHzEditField = uieditfield(app.ParametersTab, 'numeric');
            app.LosscalculationlimitingfrequencyHzEditField.Value = 20000;
            app.SMsandCapacityTab = uitab(app.TabGroup);
            app.SMsandCapacityTab.Title = 'SMs and Capacity';
            app.GridLayout2 = uigridlayout(app.SMsandCapacityTab);
            app.Nsm_plot_6phase_HV = uiaxes(app.GridLayout2);
            title(app.Nsm_plot_6phase_HV, '6-phase HV system')
            xlabel(app.Nsm_plot_6phase_HV, 'X')
            ylabel(app.Nsm_plot_6phase_HV, 'Y')
            zlabel(app.Nsm_plot_6phase_HV, 'Z')
            app.Nsm_plot_6phase_LV = uiaxes(app.GridLayout2);
            title(app.Nsm_plot_6phase_LV, '6-phase LV system')
            xlabel(app.Nsm_plot_6phase_LV, 'X')
            ylabel(app.Nsm_plot_6phase_LV, 'Y')
            zlabel(app.Nsm_plot_6phase_LV, 'Z')
            app.Bcap_sm_plot_6phase_HV = uiaxes(app.GridLayout2);
            title(app.Bcap_sm_plot_6phase_HV, '6-phase HV system')
            xlabel(app.Bcap_sm_plot_6phase_HV, 'X')
            ylabel(app.Bcap_sm_plot_6phase_HV, 'Y')
            zlabel(app.Bcap_sm_plot_6phase_HV, 'Z')
            app.Nsm_plot_3phase = uiaxes(app.GridLayout2);
            title(app.Nsm_plot_3phase, '3-phase system')
            xlabel(app.Nsm_plot_3phase, 'X')
            ylabel(app.Nsm_plot_3phase, 'Y')
            zlabel(app.Nsm_plot_3phase, 'Z')
            app.Bcap_sm_plot_6phase_LV = uiaxes(app.GridLayout2);
            title(app.Bcap_sm_plot_6phase_LV, '6-phase LV system')
            xlabel(app.Bcap_sm_plot_6phase_LV, 'X')
            ylabel(app.Bcap_sm_plot_6phase_LV, 'Y')
            zlabel(app.Bcap_sm_plot_6phase_LV, 'Z')
            app.Bcap_sm_plot_3phase = uiaxes(app.GridLayout2);
            title(app.Bcap_sm_plot_3phase, '3-phase system')
            xlabel(app.Bcap_sm_plot_3phase, 'X')
            ylabel(app.Bcap_sm_plot_3phase, 'Y')
            zlabel(app.Bcap_sm_plot_3phase, 'Z')
            app.TotalNumberofSubmodulesLabel = uilabel(app.GridLayout2);
            app.TotalNumberofSubmodulesLabel.Text = 'Total Number of Submodules ';
            app.TotalNumberofSubmodulesLabel_2 = uilabel(app.GridLayout2);
            app.TotalNumberofSubmodulesLabel_2.Text = 'Total Number of Submodules ';
            app.BatteryCapacityperSubmoduleLabel = uilabel(app.GridLayout2);
            app.BatteryCapacityperSubmoduleLabel.Text = 'Battery Capacity per Submodule';
            app.ParallelCellsSCsTab = uitab(app.TabGroup);
            app.ParallelCellsSCsTab.Title = 'Parallel Cells & SCs';
            app.Npmos_sm_plot_3phase = uiaxes(app.ParallelCellsSCsTab);
            title(app.Npmos_sm_plot_3phase, '3-phase system')
            xlabel(app.Npmos_sm_plot_3phase, 'X')
            ylabel(app.Npmos_sm_plot_3phase, 'Y')
            zlabel(app.Npmos_sm_plot_3phase, 'Z')
            app.Npmos_sm_plot_6phase_LV = uiaxes(app.ParallelCellsSCsTab);
            title(app.Npmos_sm_plot_6phase_LV, '6-phase LV system')
            xlabel(app.Npmos_sm_plot_6phase_LV, 'X')
            ylabel(app.Npmos_sm_plot_6phase_LV, 'Y')
            zlabel(app.Npmos_sm_plot_6phase_LV, 'Z')
            app.Npcells_plot_3phase = uiaxes(app.ParallelCellsSCsTab);
            title(app.Npcells_plot_3phase, '3-phase system')
            xlabel(app.Npcells_plot_3phase, 'X')
            ylabel(app.Npcells_plot_3phase, 'Y')
            zlabel(app.Npcells_plot_3phase, 'Z')
            app.Npcells_plot_6phase_HV = uiaxes(app.ParallelCellsSCsTab);
            title(app.Npcells_plot_6phase_HV, '6-phase HV system')
            xlabel(app.Npcells_plot_6phase_HV, 'X')
            ylabel(app.Npcells_plot_6phase_HV, 'Y')
            zlabel(app.Npcells_plot_6phase_HV, 'Z')
            app.Npcells_plot_6phase_LV = uiaxes(app.ParallelCellsSCsTab);
            title(app.Npcells_plot_6phase_LV, '6-phase LV system')
            xlabel(app.Npcells_plot_6phase_LV, 'X')
            ylabel(app.Npcells_plot_6phase_LV, 'Y')
            zlabel(app.Npcells_plot_6phase_LV, 'Z')
            app.Npmos_plot_6phase_HV = uiaxes(app.ParallelCellsSCsTab);
            title(app.Npmos_plot_6phase_HV, '6-phase HV system')
            xlabel(app.Npmos_plot_6phase_HV, 'X')
            ylabel(app.Npmos_plot_6phase_HV, 'Y')
            zlabel(app.Npmos_plot_6phase_HV, 'Z')
            app.NumberofparallelcellspersubmoduleLabel = uilabel(app.ParallelCellsSCsTab);
            app.NumberofparallelcellspersubmoduleLabel.Text = 'Number of parallel cells per submodule';
            app.NumberofparallelMOSFETsperswitchLabel = uilabel(app.ParallelCellsSCsTab);
            app.NumberofparallelMOSFETsperswitchLabel.Text = 'Number of parallel MOSFETs per switch';
            app.SMCurrentsTab = uitab(app.TabGroup);
            app.SMCurrentsTab.Title = 'SM Currents';
            app.GridLayout3 = uigridlayout(app.SMCurrentsTab);
            app.Rthata_ca_3phase = uiaxes(app.GridLayout3);
            title(app.Rthata_ca_3phase, '3-phase system')
            xlabel(app.Rthata_ca_3phase, 'X')
            ylabel(app.Rthata_ca_3phase, 'Y')
            app.Rthata_ca_6phase_LV = uiaxes(app.GridLayout3);
            title(app.Rthata_ca_6phase_LV, '6-phase LV system')
            xlabel(app.Rthata_ca_6phase_LV, 'X')
            ylabel(app.Rthata_ca_6phase_LV, 'Y')
            app.Ibatt_plot_6phase_LV = uiaxes(app.GridLayout3);
            title(app.Ibatt_plot_6phase_LV, '6-phase LV system')
            xlabel(app.Ibatt_plot_6phase_LV, 'X')
            ylabel(app.Ibatt_plot_6phase_LV, 'Y')
            app.Ibatt_plot_3phase = uiaxes(app.GridLayout3);
            title(app.Ibatt_plot_3phase, '3-phase system')
            xlabel(app.Ibatt_plot_3phase, 'X')
            ylabel(app.Ibatt_plot_3phase, 'Y')
            app.Rthata_ca_6phase_HV = uiaxes(app.GridLayout3);
            title(app.Rthata_ca_6phase_HV, '6-phase HV system')
            xlabel(app.Rthata_ca_6phase_HV, 'X')
            ylabel(app.Rthata_ca_6phase_HV, 'Y')
            app.Ibatt_plot_6phase_HV = uiaxes(app.GridLayout3);
            title(app.Ibatt_plot_6phase_HV, '6-phase HV system')
            xlabel(app.Ibatt_plot_6phase_HV, 'X')
            ylabel(app.Ibatt_plot_6phase_HV, 'Y')
            app.SubmoduleDCsidecurrentsLabel_21 = uilabel(app.GridLayout3);
            app.SubmoduleDCsidecurrentsLabel_21.Text = 'Pulse number';
            app.SubmoduleDCsidecurrentsLabel_22 = uilabel(app.GridLayout3);
            app.SubmoduleDCsidecurrentsLabel_22.Text = 'Capacitance per submodule';
            app.SMCapacitorTab = uitab(app.TabGroup);
            app.SMCapacitorTab.Title = 'SM Capacitor';
            app.GridLayout4 = uigridlayout(app.SMCapacitorTab);
            app.ESR_plot_6phase_LV = uiaxes(app.GridLayout4);
            title(app.ESR_plot_6phase_LV, '6-phase LV system')
            xlabel(app.ESR_plot_6phase_LV, 'X')
            ylabel(app.ESR_plot_6phase_LV, 'Y')
            app.ESR_plot_6phase_HV = uiaxes(app.GridLayout4);
            title(app.ESR_plot_6phase_HV, '6-phase HV system')
            xlabel(app.ESR_plot_6phase_HV, 'X')
            ylabel(app.ESR_plot_6phase_HV, 'Y')
            app.Cap_plot_6phase_LV = uiaxes(app.GridLayout4);
            title(app.Cap_plot_6phase_LV, '6-phase LV system')
            xlabel(app.Cap_plot_6phase_LV, 'X')
            ylabel(app.Cap_plot_6phase_LV, 'Y')
            app.ESR_plot_3phase = uiaxes(app.GridLayout4);
            title(app.ESR_plot_3phase, '3-phase system')
            xlabel(app.ESR_plot_3phase, 'X')
            ylabel(app.ESR_plot_3phase, 'Y')
            app.Cap_plot_3phase = uiaxes(app.GridLayout4);
            title(app.Cap_plot_3phase, '3-phase system')
            xlabel(app.Cap_plot_3phase, 'X')
            ylabel(app.Cap_plot_3phase, 'Y')
            app.Cap_plot_6phase_HV = uiaxes(app.GridLayout4);
            title(app.Cap_plot_6phase_HV, '6-phase HV system')
            xlabel(app.Cap_plot_6phase_HV, 'X')
            ylabel(app.Cap_plot_6phase_HV, 'Y')
            app.SubmoduleDCsidecurrentsLabel_3 = uilabel(app.GridLayout4);
            app.SubmoduleDCsidecurrentsLabel_3.Text = 'Energy stored in DC-link capacitor per submodule';
            app.SubmoduleDCsidecurrentsLabel_4 = uilabel(app.GridLayout4);
            app.SubmoduleDCsidecurrentsLabel_4.Text = 'Energy stored in DC-link capacitor';
            app.ZetaanddidtTab = uitab(app.TabGroup);
            app.ZetaanddidtTab.Title = 'Zeta and didt';
            app.GridLayout3_2 = uigridlayout(app.ZetaanddidtTab);
            app.Zeta_plot_6phase_LV = uiaxes(app.GridLayout3_2);
            title(app.Zeta_plot_6phase_LV, '6-phase LV system')
            xlabel(app.Zeta_plot_6phase_LV, 'X')
            ylabel(app.Zeta_plot_6phase_LV, 'Y')
            app.Zeta_plot_6phase_HV = uiaxes(app.GridLayout3_2);
            title(app.Zeta_plot_6phase_HV, '6-phase HV system')
            xlabel(app.Zeta_plot_6phase_HV, 'X')
            ylabel(app.Zeta_plot_6phase_HV, 'Y')
            app.Zeta_plot_3phase = uiaxes(app.GridLayout3_2);
            title(app.Zeta_plot_3phase, '3-phase system')
            xlabel(app.Zeta_plot_3phase, 'X')
            ylabel(app.Zeta_plot_3phase, 'Y')
            app.didt_plot_3phase = uiaxes(app.GridLayout3_2);
            title(app.didt_plot_3phase, '3-phase system')
            xlabel(app.didt_plot_3phase, 'X')
            ylabel(app.didt_plot_3phase, 'Y')
            app.didt_plot_6phase_HV = uiaxes(app.GridLayout3_2);
            title(app.didt_plot_6phase_HV, '6-phase HV system')
            xlabel(app.didt_plot_6phase_HV, 'X')
            ylabel(app.didt_plot_6phase_HV, 'Y')
            app.didt_plot_6phase_LV = uiaxes(app.GridLayout3_2);
            title(app.didt_plot_6phase_LV, '6-phase LV system')
            xlabel(app.didt_plot_6phase_LV, 'X')
            ylabel(app.didt_plot_6phase_LV, 'Y')
            app.SubmoduleDCsidecurrentsLabel_23 = uilabel(app.GridLayout3_2);
            app.SubmoduleDCsidecurrentsLabel_23.Text = 'didt of MSOFETs';
            app.SubmoduleDCsidecurrentsLabel_24 = uilabel(app.GridLayout3_2);
            app.SubmoduleDCsidecurrentsLabel_24.Text = 'Zeta (damping ratio)';
            app.BatterylossTab = uitab(app.TabGroup);
            app.BatterylossTab.Title = 'Battery loss';
            app.GridLayout5 = uigridlayout(app.BatterylossTab);
            app.Pbatt_tot_plot_3phase = uiaxes(app.GridLayout5);
            title(app.Pbatt_tot_plot_3phase, '3-phase system')
            xlabel(app.Pbatt_tot_plot_3phase, 'X')
            ylabel(app.Pbatt_tot_plot_3phase, 'Y')
            app.Pbatt_tot_plot_6phase_LV = uiaxes(app.GridLayout5);
            title(app.Pbatt_tot_plot_6phase_LV, '3-phase system')
            xlabel(app.Pbatt_tot_plot_6phase_LV, 'X')
            ylabel(app.Pbatt_tot_plot_6phase_LV, 'Y')
            app.Pbatt_plot_6phase_HV = uiaxes(app.GridLayout5);
            title(app.Pbatt_plot_6phase_HV, '6-phase HV system')
            xlabel(app.Pbatt_plot_6phase_HV, 'X')
            ylabel(app.Pbatt_plot_6phase_HV, 'Y')
            app.Pbatt_tot_plot_6phase_HV = uiaxes(app.GridLayout5);
            title(app.Pbatt_tot_plot_6phase_HV, '6-phase HV system')
            xlabel(app.Pbatt_tot_plot_6phase_HV, 'X')
            ylabel(app.Pbatt_tot_plot_6phase_HV, 'Y')
            app.Pbatt_plot_6phase_LV = uiaxes(app.GridLayout5);
            title(app.Pbatt_plot_6phase_LV, '6-phase LV system')
            xlabel(app.Pbatt_plot_6phase_LV, 'X')
            ylabel(app.Pbatt_plot_6phase_LV, 'Y')
            app.Pbatt_plot_3phase = uiaxes(app.GridLayout5);
            title(app.Pbatt_plot_3phase, '3-phase system')
            xlabel(app.Pbatt_plot_3phase, 'X')
            ylabel(app.Pbatt_plot_3phase, 'Y')
            app.SubmoduleDCsidecurrentsLabel_5 = uilabel(app.GridLayout5);
            app.SubmoduleDCsidecurrentsLabel_5.Text = 'Battery losses per submodule';
            app.SubmoduleDCsidecurrentsLabel_6 = uilabel(app.GridLayout5);
            app.SubmoduleDCsidecurrentsLabel_6.Text = 'Total Battery losses';
            app.CapacitorlossTab = uitab(app.TabGroup);
            app.CapacitorlossTab.Title = 'Capacitor loss';
            app.GridLayout4_2 = uigridlayout(app.CapacitorlossTab);
            app.Pcap_tot_plot_3phase = uiaxes(app.GridLayout4_2);
            title(app.Pcap_tot_plot_3phase, '3-phase system')
            xlabel(app.Pcap_tot_plot_3phase, 'X')
            ylabel(app.Pcap_tot_plot_3phase, 'Y')
            app.Pcap_plot_3phase = uiaxes(app.GridLayout4_2);
            title(app.Pcap_plot_3phase, '3-phase system')
            xlabel(app.Pcap_plot_3phase, 'X')
            ylabel(app.Pcap_plot_3phase, 'Y')
            app.Pcap_plot_6phase_LV = uiaxes(app.GridLayout4_2);
            title(app.Pcap_plot_6phase_LV, '6-phase LV system')
            xlabel(app.Pcap_plot_6phase_LV, 'X')
            ylabel(app.Pcap_plot_6phase_LV, 'Y')
            app.Pcap_tot_plot_6phase_HV = uiaxes(app.GridLayout4_2);
            title(app.Pcap_tot_plot_6phase_HV, '6-phase HV system')
            xlabel(app.Pcap_tot_plot_6phase_HV, 'X')
            ylabel(app.Pcap_tot_plot_6phase_HV, 'Y')
            app.Pcap_tot_plot_6phase_LV = uiaxes(app.GridLayout4_2);
            title(app.Pcap_tot_plot_6phase_LV, '6-phase LV system')
            xlabel(app.Pcap_tot_plot_6phase_LV, 'X')
            ylabel(app.Pcap_tot_plot_6phase_LV, 'Y')
            app.Pcap_plot_6phase_HV = uiaxes(app.GridLayout4_2);
            title(app.Pcap_plot_6phase_HV, '6-phase HV system')
            xlabel(app.Pcap_plot_6phase_HV, 'X')
            ylabel(app.Pcap_plot_6phase_HV, 'Y')
            app.SubmoduleDCsidecurrentsLabel_13 = uilabel(app.GridLayout4_2);
            app.SubmoduleDCsidecurrentsLabel_13.Text = 'Capacitor losses per submodule';
            app.SubmoduleDCsidecurrentsLabel_14 = uilabel(app.GridLayout4_2);
            app.SubmoduleDCsidecurrentsLabel_14.Text = 'Total Capacitor losses';
            app.TemperatureTab = uitab(app.TabGroup);
            app.TemperatureTab.Title = 'Temperature';
            app.GridLayout4_3 = uigridlayout(app.TemperatureTab);
            app.Tj_plot_6phase_LV = uiaxes(app.GridLayout4_3);
            title(app.Tj_plot_6phase_LV, '6-phase LV system')
            xlabel(app.Tj_plot_6phase_LV, 'X')
            ylabel(app.Tj_plot_6phase_LV, 'Y')
            app.Tj_plot_3phase = uiaxes(app.GridLayout4_3);
            title(app.Tj_plot_3phase, '3-phase system')
            xlabel(app.Tj_plot_3phase, 'X')
            ylabel(app.Tj_plot_3phase, 'Y')
            app.Tc_plot_6phase_LV = uiaxes(app.GridLayout4_3);
            title(app.Tc_plot_6phase_LV, '6-phase LV system')
            xlabel(app.Tc_plot_6phase_LV, 'X')
            ylabel(app.Tc_plot_6phase_LV, 'Y')
            app.Tj_plot_6phase_HV = uiaxes(app.GridLayout4_3);
            title(app.Tj_plot_6phase_HV, '6-phase HV system')
            xlabel(app.Tj_plot_6phase_HV, 'X')
            ylabel(app.Tj_plot_6phase_HV, 'Y')
            app.Tc_plot_6phase_HV = uiaxes(app.GridLayout4_3);
            title(app.Tc_plot_6phase_HV, '6-phase HV system')
            xlabel(app.Tc_plot_6phase_HV, 'X')
            ylabel(app.Tc_plot_6phase_HV, 'Y')
            app.Tc_plot_3phase = uiaxes(app.GridLayout4_3);
            title(app.Tc_plot_3phase, '3-phase system')
            xlabel(app.Tc_plot_3phase, 'X')
            ylabel(app.Tc_plot_3phase, 'Y')
            app.SubmoduleDCsidecurrentsLabel_15 = uilabel(app.GridLayout4_3);
            app.SubmoduleDCsidecurrentsLabel_15.Text = 'MOSFET junction temperature';
            app.SubmoduleDCsidecurrentsLabel_16 = uilabel(app.GridLayout4_3);
            app.SubmoduleDCsidecurrentsLabel_16.Text = 'Case temperature Maximum';
            app.SClossTab = uitab(app.TabGroup);
            app.SClossTab.Title = 'SC loss';
            app.GridLayout6 = uigridlayout(app.SClossTab);
            app.Psm_plot_6phase_LV = uiaxes(app.GridLayout6);
            title(app.Psm_plot_6phase_LV, '6-phase LV system')
            xlabel(app.Psm_plot_6phase_LV, 'X')
            ylabel(app.Psm_plot_6phase_LV, 'Y')
            app.Psm_plot_6phase_HV = uiaxes(app.GridLayout6);
            title(app.Psm_plot_6phase_HV, '6-phase HV system')
            xlabel(app.Psm_plot_6phase_HV, 'X')
            ylabel(app.Psm_plot_6phase_HV, 'Y')
            app.Psc_tot_plot_3phase = uiaxes(app.GridLayout6);
            title(app.Psc_tot_plot_3phase, '3-phase system')
            xlabel(app.Psc_tot_plot_3phase, 'X')
            ylabel(app.Psc_tot_plot_3phase, 'Y')
            app.Psc_tot_plot_6phase_HV = uiaxes(app.GridLayout6);
            title(app.Psc_tot_plot_6phase_HV, '6-phase HV system')
            xlabel(app.Psc_tot_plot_6phase_HV, 'X')
            ylabel(app.Psc_tot_plot_6phase_HV, 'Y')
            app.Psc_tot_plot_6phase_LV = uiaxes(app.GridLayout6);
            title(app.Psc_tot_plot_6phase_LV, '6-phase LV system')
            xlabel(app.Psc_tot_plot_6phase_LV, 'X')
            ylabel(app.Psc_tot_plot_6phase_LV, 'Y')
            app.Psm_plot_3phase = uiaxes(app.GridLayout6);
            title(app.Psm_plot_3phase, '3-phase system')
            xlabel(app.Psm_plot_3phase, 'X')
            ylabel(app.Psm_plot_3phase, 'Y')
            app.SubmoduleDCsidecurrentsLabel_7 = uilabel(app.GridLayout6);
            app.SubmoduleDCsidecurrentsLabel_7.Text = 'Semiconductor losses per submodule';
            app.SubmoduleDCsidecurrentsLabel_8 = uilabel(app.SClossTab);
            app.SubmoduleDCsidecurrentsLabel_8.Text = 'Total semiconductor losses';
            app.TotallossTab = uitab(app.TabGroup);
            app.TotallossTab.Title = 'Total loss';
            app.GridLayout7 = uigridlayout(app.TotallossTab);
            app.Ploss_avg_plot_6phase_HV = uiaxes(app.GridLayout7);
            title(app.Ploss_avg_plot_6phase_HV, '6-phase HV system')
            xlabel(app.Ploss_avg_plot_6phase_HV, 'X')
            ylabel(app.Ploss_avg_plot_6phase_HV, 'Y')
            app.Ploss_max_plot_6phase_HV = uiaxes(app.GridLayout7);
            title(app.Ploss_max_plot_6phase_HV, '6-phase HV system')
            xlabel(app.Ploss_max_plot_6phase_HV, 'X')
            ylabel(app.Ploss_max_plot_6phase_HV, 'Y')
            app.Ploss_max_plot_6phase_LV = uiaxes(app.GridLayout7);
            title(app.Ploss_max_plot_6phase_LV, '6-phase LV system')
            xlabel(app.Ploss_max_plot_6phase_LV, 'X')
            ylabel(app.Ploss_max_plot_6phase_LV, 'Y')
            app.Ploss_max_plot_3phase = uiaxes(app.GridLayout7);
            title(app.Ploss_max_plot_3phase, '3-phase system')
            xlabel(app.Ploss_max_plot_3phase, 'X')
            ylabel(app.Ploss_max_plot_3phase, 'Y')
            app.Ploss_avg_plot_6phase_LV = uiaxes(app.GridLayout7);
            title(app.Ploss_avg_plot_6phase_LV, '6-phase LV system')
            xlabel(app.Ploss_avg_plot_6phase_LV, 'X')
            ylabel(app.Ploss_avg_plot_6phase_LV, 'Y')
            app.Ploss_avg_plot_3phase = uiaxes(app.GridLayout7);
            title(app.Ploss_avg_plot_3phase, '3-phase system')
            xlabel(app.Ploss_avg_plot_3phase, 'X')
            ylabel(app.Ploss_avg_plot_3phase, 'Y')
            app.SubmoduleDCsidecurrentsLabel_9 = uilabel(app.GridLayout7);
            app.SubmoduleDCsidecurrentsLabel_9.Text = 'Total average losses';
            app.SubmoduleDCsidecurrentsLabel_10 = uilabel(app.GridLayout7);
            app.SubmoduleDCsidecurrentsLabel_10.Text = 'Total maximum losses';
            app.EfficiencyTab = uitab(app.TabGroup);
            app.EfficiencyTab.Title = 'Efficiency';
            app.GridLayout6_2 = uigridlayout(app.EfficiencyTab);
            app.eff_max_plot_6phase_LV = uiaxes(app.GridLayout6_2);
            title(app.eff_max_plot_6phase_LV, '6-phase LV system')
            xlabel(app.eff_max_plot_6phase_LV, 'X')
            ylabel(app.eff_max_plot_6phase_LV, 'Y')
            app.eff_avg_plot_3phase = uiaxes(app.GridLayout6_2);
            title(app.eff_avg_plot_3phase, '3-phase system')
            xlabel(app.eff_avg_plot_3phase, 'X')
            ylabel(app.eff_avg_plot_3phase, 'Y')
            app.eff_avg_plot_6phase_LV = uiaxes(app.GridLayout6_2);
            title(app.eff_avg_plot_6phase_LV, '6-phase LV system')
            xlabel(app.eff_avg_plot_6phase_LV, 'X')
            ylabel(app.eff_avg_plot_6phase_LV, 'Y')
            app.eff_max_plot_6phase_HV = uiaxes(app.GridLayout6_2);
            title(app.eff_max_plot_6phase_HV, '6-phase HV system')
            xlabel(app.eff_max_plot_6phase_HV, 'X')
            ylabel(app.eff_max_plot_6phase_HV, 'Y')
            app.eff_max_plot_3phase = uiaxes(app.GridLayout6_2);
            title(app.eff_max_plot_3phase, '3-phase system')
            xlabel(app.eff_max_plot_3phase, 'X')
            ylabel(app.eff_max_plot_3phase, 'Y')
            app.eff_avg_plot_6phase_HV = uiaxes(app.GridLayout6_2);
            title(app.eff_avg_plot_6phase_HV, '6-phase HV system')
            xlabel(app.eff_avg_plot_6phase_HV, 'X')
            ylabel(app.eff_avg_plot_6phase_HV, 'Y')
            app.SubmoduleDCsidecurrentsLabel_11 = uilabel(app.GridLayout6_2);
            app.SubmoduleDCsidecurrentsLabel_11.Text = 'Total effeciency at average power';
            app.SubmoduleDCsidecurrentsLabel_12 = uilabel(app.GridLayout6_2);
            app.SubmoduleDCsidecurrentsLabel_12.Text = 'Total effeciency at maximum power';
            app.ConverterCostsTab = uitab(app.TabGroup);
            app.ConverterCostsTab.Title = 'Converter Costs';
            app.GridLayout6_3 = uigridlayout(app.ConverterCostsTab);
            app.lambda_plot_6phase_LV = uiaxes(app.GridLayout6_3);
            title(app.lambda_plot_6phase_LV, '6-phase LV system')
            xlabel(app.lambda_plot_6phase_LV, 'X')
            ylabel(app.lambda_plot_6phase_LV, 'Y')
            app.lambda_plot_6phase_HV = uiaxes(app.GridLayout6_3);
            title(app.lambda_plot_6phase_HV, '6-phase HV system')
            xlabel(app.lambda_plot_6phase_HV, 'X')
            ylabel(app.lambda_plot_6phase_HV, 'Y')
            app.lambda_normalized_plot_3phase = uiaxes(app.GridLayout6_3);
            title(app.lambda_normalized_plot_3phase, '3-phase system')
            xlabel(app.lambda_normalized_plot_3phase, 'X')
            ylabel(app.lambda_normalized_plot_3phase, 'Y')
            app.lambda_normalized_plot_6phase_HV = uiaxes(app.GridLayout6_3);
            title(app.lambda_normalized_plot_6phase_HV, '6-phase HV system')
            xlabel(app.lambda_normalized_plot_6phase_HV, 'X')
            ylabel(app.lambda_normalized_plot_6phase_HV, 'Y')
            app.lambda_plot_3phase = uiaxes(app.GridLayout6_3);
            title(app.lambda_plot_3phase, '3-phase system')
            xlabel(app.lambda_plot_3phase, 'X')
            ylabel(app.lambda_plot_3phase, 'Y')
            app.lambda_normalized_plot_6phase_LV = uiaxes(app.GridLayout6_3);
            title(app.lambda_normalized_plot_6phase_LV, '6-phase LV system')
            xlabel(app.lambda_normalized_plot_6phase_LV, 'X')
            ylabel(app.lambda_normalized_plot_6phase_LV, 'Y')
            app.SubmoduleDCsidecurrentsLabel_17 = uilabel(app.GridLayout6_3);
            app.SubmoduleDCsidecurrentsLabel_17.Text = 'Total converter costs (including capacitor costs)';
            app.SubmoduleDCsidecurrentsLabel_18 = uilabel(app.GridLayout6_3);
            app.SubmoduleDCsidecurrentsLabel_18.Text = 'Normalized converter costs';
            app.TotalCostsTab = uitab(app.TabGroup);
            app.TotalCostsTab.Title = 'Total Costs';
            app.GridLayout6_4 = uigridlayout(app.TotalCostsTab);
            app.lambda_tot_plot_6phase_LV = uiaxes(app.GridLayout6_4);
            title(app.lambda_tot_plot_6phase_LV, '6-phase LV system')
            xlabel(app.lambda_tot_plot_6phase_LV, 'X')
            ylabel(app.lambda_tot_plot_6phase_LV, 'Y')
            app.lambda_tot_plot_6phase_HV = uiaxes(app.GridLayout6_4);
            title(app.lambda_tot_plot_6phase_HV, '6-phase HV system')
            xlabel(app.lambda_tot_plot_6phase_HV, 'X')
            ylabel(app.lambda_tot_plot_6phase_HV, 'Y')
            app.lambda_tot_normalized_plot_3phase = uiaxes(app.GridLayout6_4);
            title(app.lambda_tot_normalized_plot_3phase, '3-phase system')
            xlabel(app.lambda_tot_normalized_plot_3phase, 'X')
            ylabel(app.lambda_tot_normalized_plot_3phase, 'Y')
            app.lambda_tot_normalized_plot_6phase_HV = uiaxes(app.GridLayout6_4);
            title(app.lambda_tot_normalized_plot_6phase_HV, '6-phase HV system')
            xlabel(app.lambda_tot_normalized_plot_6phase_HV, 'X')
            ylabel(app.lambda_tot_normalized_plot_6phase_HV, 'Y')
            app.lambda_tot_plot_3phase = uiaxes(app.GridLayout6_4);
            title(app.lambda_tot_plot_3phase, '3-phase system')
            xlabel(app.lambda_tot_plot_3phase, 'X')
            ylabel(app.lambda_tot_plot_3phase, 'Y')
            app.lambda_tot_normalized_plot_6phase_LV = uiaxes(app.GridLayout6_4);
            title(app.lambda_tot_normalized_plot_6phase_LV, '6-phase LV system')
            xlabel(app.lambda_tot_normalized_plot_6phase_LV, 'X')
            ylabel(app.lambda_tot_normalized_plot_6phase_LV, 'Y')
            app.SubmoduleDCsidecurrentsLabel_19 = uilabel(app.GridLayout6_4);
            app.SubmoduleDCsidecurrentsLabel_19.Text = 'Total system cost ';
            app.SubmoduleDCsidecurrentsLabel_20 = uilabel(app.GridLayout6_4);
            app.SubmoduleDCsidecurrentsLabel_20.Text = 'Total costs nomalized';
            app.TotalcharinglossesTab = uitab(app.TabGroup);
            app.TotalcharinglossesTab.Title = 'Total charing losses';
            app.Ploss_DC_chrg_max_plot_6phase_HV = uiaxes(app.TotalcharinglossesTab);
            title(app.Ploss_DC_chrg_max_plot_6phase_HV, '6-phase HV system')
            xlabel(app.Ploss_DC_chrg_max_plot_6phase_HV, 'X')
            ylabel(app.Ploss_DC_chrg_max_plot_6phase_HV, 'Y')
            app.Ploss_DC_chrg_max_plot_6phase_LV = uiaxes(app.TotalcharinglossesTab);
            title(app.Ploss_DC_chrg_max_plot_6phase_LV, '6-phase LV system')
            xlabel(app.Ploss_DC_chrg_max_plot_6phase_LV, 'X')
            ylabel(app.Ploss_DC_chrg_max_plot_6phase_LV, 'Y')
            app.Ploss_DC_chrg_max_plot_3phase = uiaxes(app.TotalcharinglossesTab);
            title(app.Ploss_DC_chrg_max_plot_3phase, '3-phase system')
            xlabel(app.Ploss_DC_chrg_max_plot_3phase, 'X')
            ylabel(app.Ploss_DC_chrg_max_plot_3phase, 'Y')
            app.Tc_DC_chrg_plot_6phase_LV = uiaxes(app.TotalcharinglossesTab);
            title(app.Tc_DC_chrg_plot_6phase_LV, '6-phase LV system')
            xlabel(app.Tc_DC_chrg_plot_6phase_LV, 'X')
            ylabel(app.Tc_DC_chrg_plot_6phase_LV, 'Y')
            app.Tc_DC_chrg_plot_3phase = uiaxes(app.TotalcharinglossesTab);
            title(app.Tc_DC_chrg_plot_3phase, '3-phase system')
            xlabel(app.Tc_DC_chrg_plot_3phase, 'X')
            ylabel(app.Tc_DC_chrg_plot_3phase, 'Y')
            app.Tc_DC_chrg_plot_6phase_HV = uiaxes(app.TotalcharinglossesTab);
            title(app.Tc_DC_chrg_plot_6phase_HV, '6-phase HV system')
            xlabel(app.Tc_DC_chrg_plot_6phase_HV, 'X')
            ylabel(app.Tc_DC_chrg_plot_6phase_HV, 'Y')
            app.SubmoduleDCsidecurrentsLabel_25 = uilabel(app.TotalcharinglossesTab);
            app.SubmoduleDCsidecurrentsLabel_25.Text = 'Total maximum losses during DC side charging';
            app.SubmoduleDCsidecurrentsLabel_26 = uilabel(app.TotalcharinglossesTab);
            app.SubmoduleDCsidecurrentsLabel_26.Text = 'Maximum case temperature during DC charging';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DashBoard_converted

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end